classdef BatchCreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BatchEditorUIFigure          matlab.ui.Figure
        ExecutionoptionsPanel        matlab.ui.container.Panel
        DialogmessageEditFieldLabel  matlab.ui.control.Label
        CloseafterrunCheckBox        matlab.ui.control.CheckBox
        RepeatoptionCheckBox         matlab.ui.control.CheckBox
        DialogmessageEditField       matlab.ui.control.EditField
        PauseafterrunCheckBox        matlab.ui.control.CheckBox
        SaveafterrunCheckBox         matlab.ui.control.CheckBox
        RunonlyonceCheckBox          matlab.ui.control.CheckBox
        Tree                         matlab.ui.container.Tree
        VariablesNode                matlab.ui.container.TreeNode
        iNode                        matlab.ui.container.TreeNode
        RNode                        matlab.ui.container.TreeNode
        var1Node                     matlab.ui.container.TreeNode
        var2Node                     matlab.ui.container.TreeNode
        AddnewvariableNode           matlab.ui.container.TreeNode
        DeletevariableNode           matlab.ui.container.TreeNode
        FilenameEditField            matlab.ui.control.EditField
        FilenameEditFieldLabel       matlab.ui.control.Label
        NumberoffilesSpinner         matlab.ui.control.Spinner
        NumberoffilesSpinnerLabel    matlab.ui.control.Label
        REditField                   matlab.ui.control.EditField
        REditFieldLabel              matlab.ui.control.Label
        CommandWindowTextArea        matlab.ui.control.TextArea
        CommandWindowTextAreaLabel   matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        AddfilesLabel                matlab.ui.control.Label
        AddfilesButton               matlab.ui.control.Button
        SaveintofolderLabel          matlab.ui.control.Label
        SaveintofolderButton         matlab.ui.control.Button
        RunnowButton                 matlab.ui.control.Button
        CodeTextArea                 matlab.ui.control.TextArea
        CodeTextAreaLabel            matlab.ui.control.Label
        CommentsTextArea             matlab.ui.control.TextArea
        CommentsTextAreaLabel        matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        NameEditFieldLabel           matlab.ui.control.Label
        CreatebatchButton            matlab.ui.control.Button
        ImportfromdatasetButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        savePath % Path to save folder
        lastCommandWindow % Text in command window in last step
        data
        addfilesPath % Path to required files
    end
    
    methods (Access = private)
        
        function err=evalCommand(app,str)
            [str,tokens] = app.addVariables(str,0);
            Var={};
            VarNames={};

            CD=cd;
            cd(app.addfilesPath);
            try
                y=evalin('base',str);
                err=[];
                if ~isempty(tokens)
                    for k=1:numel(tokens)
                        if evalin('base',sprintf('exist(''%s'')',tokens{k}{1}))
                            Var{end+1}=evalin('base',tokens{k}{1});
                            VarNames{end+1}=tokens{k}{1};
                            y=sprintf('%s\n%s = %s',y,tokens{k}{1},char(formattedDisplayText(Var{end})));
                        end
                    end
                end
            catch ME
                try
                    evalin('base',str);
                    err=[];
                    y=[];
                    if ~isempty(tokens)
                        for k=1:numel(tokens)
                            if evalin('base',sprintf('exist(''%s'')',tokens{k}{1}))
                                Var{end+1}=evalin('base',tokens{k}{1});
                                VarNames{end+1}=tokens{k}{1};
                                y=sprintf('%s\n%s = %s',y,tokens{k}{1},char(formattedDisplayText(Var{end})));
                            end
                        end
                    end
                catch ME2

                    y=ME2.message;
                    err=ME2;
                end
            end
            cd(CD);

            if ~isempty(VarNames)
                varNames=app.UITable.Data.Properties.VariableNames;
                for k=numel(VarNames):-1:1
                    if isempty(find(strcmp(varNames, VarNames{k}),1))
                        y=sprintf('%s\nWarning: Variable %s not found.',y,VarNames{k});
                        VarNames(k)=[];
                        Var(k)=[];
                    end
                end
                for k=1:numel(VarNames)
                    try
                        Val=string(Var{k});
                    catch ME
                        y=sprintf('%s\nWarning: unexpected value for %s.\n%s',y,VarNames{k},ME.message);
                        continue;
                    end
                    N=numel(Val);

                    value=string((1:N)');
                    value(:)=Val(:);

                    if ~isempty(value)
                        if strcmp(VarNames{k},'i')
                            value=[];
                            y=sprintf('%s\nWarning: Variable i cannot be modified.',y);
                        else
                            value={value,VarNames{k}};
                        end
                        if N~=app.NumberoffilesSpinner.Value
                            app.NumberoffilesSpinner.Value=N;
                        end
                        app.NumberoffilesSpinnerValueChanged(value);
                    end

                end
            end

            Y=char(formattedDisplayText(y));
            ind=[0,strfind(Y,newline),numel(Y)+1];
            y={};
            for k=2:numel(ind)
                y{end+1}=Y(ind(k-1)+1:ind(k)-1);
            end
            app.CommandWindowTextArea.Value=[app.CommandWindowTextArea.Value;y'];
        end
        
        function str = code2str(app,k)
            str='';
            if ~exist('k','var')
                k=1;
            end

            for q=1:numel(app.CodeTextArea.Value)
                str=sprintf('%s%s',str,app.addVariables(app.CodeTextArea.Value{q},k));
                if ~isempty(app.CodeTextArea.Value{q})&&~strcmp(app.CodeTextArea.Value{q}(end),';')
                    str=sprintf('%s;',str);
                end
            end
        end
        
        function [newStr,tokens] = addVariables(app,str,k)
                expression = '\$(\w+)*\$';
                [tokens,starts,ends] = regexp(str,expression,'tokens','start','end');
                ends=[0,ends];
                newStr='';
                for q=1:numel(starts)
                    if k==0
                        newStr=sprintf('%s%s%s',newStr,str(ends(q)+1:starts(q)-1),tokens{q}{1});
                    else
                        newStr=sprintf('%s%s%s',newStr,str(ends(q)+1:starts(q)-1),app.UITable.Data.(tokens{q}{1})(k));
                    end
                end
                newStr=sprintf('%s%s',newStr,str(ends(end)+1:end));

        end
        
        function results = getVariables(app)

            labels={'data',{'data','signal','signaldata','tabledata','plotdata','ans','table','plot'},[0,0];
                'R',{'r','res','resistance'},1e7;
                'Xname',{'xname','xlabel'},'t';
                'Yname',{'yname','ylabel'},'V';
                'Xunits',{'xunits'},'s';
                'Yunits',{'yunits','units'},'V';
                'dataStimulus',{'stimulus','stimulusdata','datastimulus','dataforce','force','forcedata','temperature','temperaturedata','datatemperature'},{[],[0,0],[0,0,0,0]};
                'labelStimulus',{'labelstimulus','namestimulus','unitstimulus','stimuluslabel','stimulusname','stimulusunits',...
                    'labelforce','nameforce','unitsforce','forcelabel','forcename','forceunits',...
                    'labeltemperature','nametemperature','unitstemperature','temperaturelabel','temperaturename','temperatureunits'},'F(N)';
                'channelOfStimulus',{'channelofstimulus','stimuluschannel','stimulusfrom','channelofforce','forcechannel','forcefrom',...
                    'channeloftemperature','temperaturechannel','temperaturefrom','channel'},{'CH 1','CH 2'};
                'trigger',{'trigger','sync'},false;
                'rescale',{'rescale','rescaling','functionbeforeoperation'},'x';
                'operationStimulus',{'operationstimulus','operationforce','operationtemperature','operation'},{'none','Derivation','Integration'};
                'additionalOperations',{'additionaloperations','additionalfunction','functionafteroperation','function'},'x';
                'processingMode',{'processingmode','mode'},{'V - t signal', 'I - t signal', 'V - t with R', 'I - t with R'}};

            varnames=evalin('base','who');

            for k=1:size(labels,1)
                if isa(labels{k,3},'cell')
                    bool=true;
                else
                    bool=false;
                end
                results.(labels{k,1})=[];
                for q=1:numel(labels{k,2})
                    ind = find(strcmpi(labels{k,2}{q},varnames),1);
                    if isempty(ind)
                        continue;
                    end
                    varData=evalin('base',varnames{ind});
                    BOOL=0;
                    if bool
                        if isa(labels{k,3}{1},'char')&&isa(varData,'char')
                            aux=strcmpi(varData,labels{k,3});
                            BOOL=sum(aux,"all");
                        elseif isnumeric(labels{k,3}{1})
                            if ~isnumeric(varData)
                                try
                                    varData=str2num(varData);
                                catch
                                    continue;
                                end
                            end
                            sizes=zeros(size(labels{k,3}));
                            for j=1:numel(sizes)
                                sizes(j)=size(labels{k,3}{j},2);
                            end
                            aux=(size(varData,2)==sizes);
                            BOOL=sum(aux,"all");
                            if ~BOOL
                                varData=varData.';
                                aux=(size(varData,2)==sizes);
                                BOOL=sum(aux,"all");
                            end
                        end
                    else
                        if isnumeric(labels{k,3})
                            if ~isnumeric(varData)
                                try
                                    varData=str2num(varData);
                                catch
                                    continue;
                                end
                            end
                            BOOL=(size(varData,2)==size(labels{k,3},2));
                            if ~BOOL
                                varData=varData.';
                                BOOL=(size(varData,2)==size(labels{k,3},2));
                            end
                        elseif isa(varData,class(labels{k,3}))
                            BOOL=1;
                        elseif isa(varData,'string')&&isa(labels{k,3},'char')
                            BOOL=1;
                            varData=char(varData);
                        end
                    end
                    if BOOL
                        results.(labels{k,1})=varData;
                        break;
                    end
                end
            end
                        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            expand(app.Tree);
            
            app.CommandWindowTextArea.Value=[{'Variables in the table can be called using the expression $varName$. Their values can be modified using the command window by asigning them 1D numerical, cell or string arrays. Number of files will be changed accordingly to the number of elements. Example: $R$=1000:1000:5000;'};{' >> '}];
            i = "1";
            R = "10M";
            var={i;R};
            for q=3:numel(app.UITable.ColumnName)
                var{q}="";
            end
            t=table(var{:});
            app.UITable.Data = renamevars(t,t.Properties.VariableNames,app.UITable.ColumnName);
            
            app.savePath=[cd,'\'];
            app.SaveintofolderLabel.Text=app.savePath;
            app.addfilesPath=app.savePath;

            X = zeros(10,1);
            Y = zeros(10,1);
            
            app.data = struct('Table',table(X,Y),...
                    'plotX',0,...
                    'plotY',0,...
                    'dataForce',[],...
                    'R',10e6,...
                    'plotForceParams',{{'none',true,true,'x',0,'F (N)','x'}},...
                    'mode','V - t with R',...
                    'labels',{{'','',...
                        't','V',...
                        's','V',...
                        '',''}});
        end

        % Button pushed function: ImportfromdatasetButton
        function ImportfromdatasetButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uigetfile( ...
                { '*.ndl','NanoDataLyzer dataset (*.ndl)'; ...
                '*.mat','Binary MAT-file (*.mat)'; ...
                '*.*',  'All Files (*.*)'}, ...
                'Open a file',[app.savePath,'dataset.ndl'],'MultiSelect','off');
            
            delete(f); %delete the dummy figure

            if filterindex==0
                return;
            end

            app.savePath = pathname;
            app.SaveintofolderLabel.Text=pathname;

            try
                load([pathname,filename],'-mat','data');
                app.REditField.Value=num2str(data.R);
                app.data=data;
                app.CodeTextArea.Value={''};
                app.CommentsTextArea.Value={''};
                app.NameEditField.Value=data.labels{1};
                app.FilenameEditField.Value=sprintf('$i$_%s',data.labels{1});
                if ~isa(data.labels{2},'cell')
                    data.labels{2}={data.labels{2}};
                end
                for k=1:numel(data.labels{2})
                    if strncmp(data.labels{2}{k},'\run',4)
                        str=data.labels{2}{k}(5:end);
                        iBreak=strfind(str,' ');
                        if ~isempty(iBreak)
                        str=str(iBreak(1)+1:end);
                        end
                        iBreak=[0,strfind(str,';')];
                        if iBreak(end)~=numel(str)
                            iBreak(end+1)=numel(str);
                        end
                        for q=1:numel(iBreak)-1
                            app.CodeTextArea.Value{end+1}=str(iBreak(q)+1:iBreak(q+1));
                        end
                        
                    else
                        app.CommentsTextArea.Value{end+1}=data.labels{2}{k};
                    end
                end
                if numel(app.CodeTextArea.Value)>1
                    app.CodeTextArea.Value(1)=[];
                end
                if numel(app.CommentsTextArea.Value)>1
                    app.CommentsTextArea.Value(1)=[];
                end
                
            catch ME
                uialert(app.BatchEditorUIFigure,sprintf('Error loading dataset\n%s',ME.message), 'Error','Icon','error');
                        
            end

        end

        % Value changing function: CommandWindowTextArea
        function CommandWindowTextAreaValueChanging(app, event)
            changingValue = event.Value;
            
            if numel(app.lastCommandWindow)+1==numel(changingValue)
                chk=0;
                for k=1:numel(app.lastCommandWindow)
                    if ~strcmp(app.lastCommandWindow{k},changingValue{k+chk})
                        if strcmp(app.lastCommandWindow{k},[changingValue{(k+chk)+(0:1)}])
                            chk=chk+1;
                        else
                            chk=-1;
                            break;
                        end
                    end
                end
                if chk==1
                    changingValue=app.lastCommandWindow;
                end
            end

            if ((numel(changingValue)-numel(event.Source.Value))>0)||isempty(changingValue{end})||isequal(app.lastCommandWindow,changingValue)
                for k=numel(changingValue):-1:1
                    if strncmp(changingValue{k},' >>',3)
                        app.CommandWindowTextArea.Value=changingValue;
                        err=app.evalCommand(changingValue{k}(4:end));
                        for q=k+1:numel(changingValue)
                            if ~isempty(err)
                                app.CommandWindowTextArea.Value{end+1}=' >> ';
                                scroll(app.CommandWindowTextArea, 'bottom');
                                return;
                            end

                            err=app.evalCommand(changingValue{q});
                        end
                        app.CommandWindowTextArea.Value{end+1}=' >> ';
                        scroll(app.CommandWindowTextArea, 'bottom');
                        return;
                    end
                end
            end
            app.lastCommandWindow=changingValue;
        end

        % Value changed function: NumberoffilesSpinner
        function NumberoffilesSpinnerValueChanged(app, event)
            value = app.NumberoffilesSpinner.Value;
            i = string((1:value)');
            old_i = app.UITable.Data.i;
            var={};
            varNames=app.UITable.Data.Properties.VariableNames;
            for q=1:numel(varNames)
                var{q}=app.UITable.Data.(varNames{q});
            end

            if numel(old_i)>numel(i)
                for q=1:numel(varNames)
                    var{q}=var{q}((1:value)');
                end
            else
                for k=1:value-numel(old_i)
                    for q=1:numel(varNames)
                        var{q}=[var{q};var{q}(end)];
                    end
                end
                var{find(strcmp(varNames, 'i'),1)}=i;
            end
            
            if ~isempty(event)&&isa(event,"cell")
                var{find(strcmp(varNames, event{2}),1)}=event{1};
            end

            t=table(var{:});
            app.UITable.Data = renamevars(t,t.Properties.VariableNames,varNames);
        end

        % Button pushed function: RunnowButton
        function RunnowButtonPushed(app, event)
            CD=cd;
            cd(app.addfilesPath);
            try
                evalin('base','clear');
                evalin('base','[]');
                evalin('base',app.code2str)

                dataEval=app.getVariables;

                uialert(app.BatchEditorUIFigure,sprintf('Output detected:\n%s',formattedDisplayText(dataEval)),'Output detected','Icon','info');

                if ~isempty(dataEval.data)||~isempty(dataEval.dataStimulus)
                    f=figure;
                    g=axes(f);
                    hold(g,'on');
                    Legend={};
                    if ~isempty(dataEval.data)
                        plot(g,dataEval.data(:,1),dataEval.data(:,2));
                        Legend{end+1}='Signal data';
                    end
                    if ~isempty(dataEval.dataStimulus)
                        plot(g,dataEval.dataStimulus(:,1),dataEval.dataStimulus(:,2));
                        Legend{end+1}='Stimulus data CH1';
                        if size(dataEval.dataStimulus,2)==4
                            plot(g,dataEval.dataStimulus(:,3),dataEval.dataStimulus(:,4));
                            Legend{end+1}='Stimulus data CH2';
                        end
                    end
                    title('Detected output');
                    legend(g,Legend);
                end

            catch ME
                uialert(app.BatchEditorUIFigure,ME.message,'Error evaluating code','Icon','warning')
            end
            cd(CD);
        end

        % Button pushed function: CreatebatchButton
        function CreatebatchButtonPushed(app, event)
            try
                N=app.NumberoffilesSpinner.Value;
                str='';
                for k=1:N

                    strR = addVariables(app,app.REditField.Value,k);
                    strR=replace(strR,'M','e6');
                    strR=replace(strR,{'k','K'},'e3');
                    strR=replace(strR,{'G','g'},'e9');
                    strR=replace(strR,'m','e-3');
                    strR=replace(strR,{'U','u','μ'},'e-6');
                    strR=replace(strR,{'N','n'},'e-9');
                    R=str2double(strR);
                    R=abs(R(1));
                    if isempty(R)
                        R=1e7;
                    end
                    name = addVariables(app,app.NameEditField.Value,k);
                    filename = addVariables(app,app.FilenameEditField.Value,k);
                    labels=app.data.labels;
                    labels{1}=name;
                    boolStr='';
                    if app.RunonlyonceCheckBox.Value
                        boolStr='1';
                    end
                    comments=app.CommentsTextArea.Value;
                    for q=1:numel(comments)
                        comments{q}=app.addVariables(comments{q},k);
                    end
                    
                    if app.SaveafterrunCheckBox.Value||app.RepeatoptionCheckBox.Value||app.PauseafterrunCheckBox.Value||app.CloseafterrunCheckBox.Value
                        strSav='\run1 %';
                        if app.SaveafterrunCheckBox.Value
                            strSav=sprintf('%s \\save',strSav);
                        end
                        if app.RepeatoptionCheckBox.Value
                            strSav=sprintf('%s \\repeat',strSav);
                        end
                        if app.PauseafterrunCheckBox.Value
                            strSav=sprintf('%s \\pause %s',strSav,addVariables(app,app.DialogmessageEditField.Value,k));
                        end
                        if app.CloseafterrunCheckBox.Value
                            strSav=sprintf('%s \\close',strSav);
                        end
                        labels{2}=[comments;{sprintf('\\run%s %s',boolStr,app.code2str(k))};strSav];
                    else
                        labels{2}=[comments;{sprintf('\\run%s %s',boolStr,app.code2str(k))}];
                    end

                    clear data
                    data = app.data;
                    data.R = R;
                    data.labels = labels;

                    [filepath,file] = fileparts([app.savePath,filename,'.ndl']);
                    if ~exist(filepath,'dir')
                        [status,msg]=mkdir(filepath);
                        if status
                            str=sprintf('%s\nFolder created in %s',str,filepath);
                        else
                            str=sprintf('%s\n%s',str,msg);
                        end
                    end
                    filepath = [filepath,'\'];
                    save([app.savePath,filename,'.ndl'],'data','-mat');
                    str=sprintf('%s\nFile %s succesfully saved.',str,file);
                            
                    if isa(app.AddfilesLabel.Text,'char')
                        files={app.AddfilesLabel.Text};
                    else
                        files=app.AddfilesLabel.Text;
                    end

                    if ~strcmp(app.AddfilesLabel.Text,'none')
                        for q=1:numel(files)
                            if ~exist([filepath,files{q}],"file")||k==1
                                [status,msg]=copyfile([app.addfilesPath,files{q}],[filepath,files{q}]);
                                if status
                                    str=sprintf('%s\nFile %s copied to %s',str,files{q},filepath);
                                else
                                    str=sprintf('%s\n%s',str,msg);
                                end
                            end
                        end
                    end
                end
                uialert(app.BatchEditorUIFigure,str,'Log','Icon','info');
            catch ME
                uialert(app.BatchEditorUIFigure,ME.message,'Error saving the files')
            end
        end

        % Button pushed function: SaveintofolderButton
        function SaveintofolderButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uigetdir doesn't minimize our GUI
            pathname = uigetdir(app.savePath,'Select folder to save data in');
            delete(f); %delete the dummy figure

            if pathname==0
                return;
            end

            app.savePath = [pathname,'\'];
            app.SaveintofolderLabel.Text=pathname;
        end

        % Button pushed function: AddfilesButton
        function AddfilesButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uigetfile( ...
                {'*.*',  'All Files (*.*)'}, ...
                'Select file(s) to be copied into the folder',app.addfilesPath,'MultiSelect','on');
            delete(f); %delete the dummy figure
            if filterindex==0
                app.AddfilesLabel.Text='none';
                return;
            end

            app.addfilesPath=pathname;
            app.AddfilesLabel.Text=filename;
        end

        % Node text changed function: Tree
        function TreeNodeTextChanged(app, event)
            node = event.Node;
            varNames=app.UITable.Data.Properties.VariableNames;
            if ~isvarname(event.Text)||~isempty(find(strcmp([varNames,'Variables'], event.Text),1))
                node.Text=event.PreviousText;
            else
                varNames{find(strcmp(varNames, event.PreviousText),1)}=event.Text;
                t = app.UITable.Data;
                app.UITable.Data = renamevars(t,t.Properties.VariableNames,varNames);
                app.UITable.ColumnName=varNames;
            end

        end

        % Clicked callback: Tree
        function TreeClicked(app, event)
            node = event.InteractionInformation.Node;
            if isempty(node)
                return;
            end
            
            app.Tree.Editable=node.NodeData;
            switch node.Text
                case 'Add new variable'
                    app.UITable.Selection=[];

                    varNames=app.UITable.Data.Properties.VariableNames;
                    n=1;
                    text='var1';
                    while(~isempty(find(strcmp(varNames, text),1)))
                        n=n+1;
                        text=sprintf('var%d', n);
                    end
                    uitreenode(app.VariablesNode,...
                        app.VariablesNode.Children(end),...
                        'Text', text,...
                        'NodeData','on');
                    t=addvars(app.UITable.Data,repmat("",app.NumberoffilesSpinner.Value,1),'After',numel(varNames));
                    varNames{end+1}=text;
                    editable=app.UITable.ColumnEditable;
                    editable(end+1)=true;
                    app.UITable.Data = renamevars(t,t.Properties.VariableNames,varNames);
                    app.UITable.ColumnName=varNames;
                    app.UITable.ColumnEditable=editable;

                case 'Delete variable'
                    app.UITable.Selection=[];

                    if numel(app.VariablesNode.Children)>1
                        selected=app.VariablesNode.Children(end);
                        varNames=app.UITable.Data.Properties.VariableNames;
                        varNames(find(strcmp(varNames, selected.Text),1))=[];
                        t = app.UITable.Data;
                        app.UITable.Data = removevars(t,selected.Text);
                        app.UITable.ColumnName=varNames;
                        delete(selected);
                    end

                case 'Variables'
                    app.UITable.Selection=[];

                otherwise

                    varNames=app.UITable.Data.Properties.VariableNames;
                    tableSelection=find(strcmp(varNames, node.Text),1)*ones([app.NumberoffilesSpinner.Value,2]);
                    tableSelection(1:end,1)=1:app.NumberoffilesSpinner.Value;
                    app.UITable.Selection=tableSelection;
                    scroll(app.UITable,'column',tableSelection(1,2));
                    scroll(app.Tree,node);
            end
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            if ~isempty(selection)
                cols=unique(selection(:,2));
                Cols={app.UITable.ColumnName{cols}};
                for k=1:numel(Cols)
                    Cols{k}=findobj(app.VariablesNode,'Text',Cols{k});
                end
                app.Tree.SelectedNodes=[Cols{:}]';
            end
        end

        % Value changed function: PauseafterrunCheckBox
        function PauseafterrunCheckBoxValueChanged(app, event)
            value = app.PauseafterrunCheckBox.Value;
            if value
                app.DialogmessageEditField.Enable="on";
                app.DialogmessageEditFieldLabel.Enable="on";
            else
                app.DialogmessageEditField.Enable="off";
                app.DialogmessageEditFieldLabel.Enable="off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BatchEditorUIFigure = uifigure('Visible', 'off');
            app.BatchEditorUIFigure.Name = 'Batch Editor';
            app.BatchEditorUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.ImportfromdatasetButton = uibutton(app.BatchEditorUIFigure, 'push');
            app.ImportfromdatasetButton.ButtonPushedFcn = createCallbackFcn(app, @ImportfromdatasetButtonPushed, true);
            app.ImportfromdatasetButton.Tooltip = {'Select a dataset as a template.'};
            app.ImportfromdatasetButton.Text = 'Import from dataset';
            app.CreatebatchButton = uibutton(app.BatchEditorUIFigure, 'push');
            app.CreatebatchButton.ButtonPushedFcn = createCallbackFcn(app, @CreatebatchButtonPushed, true);
            app.CreatebatchButton.Tooltip = {'Output the files into the selected folder.'};
            app.CreatebatchButton.Text = 'Create batch';
            app.NameEditFieldLabel = uilabel(app.BatchEditorUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.BatchEditorUIFigure, 'text');
            app.NameEditField.Tooltip = {'Name of the datasets.'; 'Use $var$ to use the content of variable var in the name.'};
            app.NameEditField.Value = 'Sample_$R$';
            app.CommentsTextAreaLabel = uilabel(app.BatchEditorUIFigure);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.BatchEditorUIFigure);
            app.CommentsTextArea.Tooltip = {'Comments in the dataset.'; 'Use $var$ to use the content of variable var in the comments.'};
            app.CodeTextAreaLabel = uilabel(app.BatchEditorUIFigure);
            app.CodeTextAreaLabel.Text = 'Code ';
            app.CodeTextArea = uitextarea(app.BatchEditorUIFigure);
            app.CodeTextArea.Tooltip = {'MATLAB code executed as the dataset opens.'; 'Use $var$ to use the content of variable var in the code.'};
            app.RunnowButton = uibutton(app.BatchEditorUIFigure, 'push');
            app.RunnowButton.ButtonPushedFcn = createCallbackFcn(app, @RunnowButtonPushed, true);
            app.RunnowButton.Tooltip = {'Execute the code.'};
            app.RunnowButton.Text = 'Run now';
            app.SaveintofolderButton = uibutton(app.BatchEditorUIFigure, 'push');
            app.SaveintofolderButton.ButtonPushedFcn = createCallbackFcn(app, @SaveintofolderButtonPushed, true);
            app.SaveintofolderButton.Tooltip = {'Select the folder to save the files into.'};
            app.SaveintofolderButton.Text = 'Save into folder';
            app.SaveintofolderLabel = uilabel(app.BatchEditorUIFigure);
            app.SaveintofolderLabel.Text = 'none';
            app.AddfilesButton = uibutton(app.BatchEditorUIFigure, 'push');
            app.AddfilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddfilesButtonPushed, true);
            app.AddfilesButton.Tooltip = {'File dependencies.'};
            app.AddfilesButton.Text = 'Add files';
            app.AddfilesLabel = uilabel(app.BatchEditorUIFigure);
            app.AddfilesLabel.Text = 'none';
            app.UITable = uitable(app.BatchEditorUIFigure);
            app.UITable.ColumnName = {'i'; 'R'; 'var1'; 'var2'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true];
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Tooltip = {'Manage variable contents.'};
            app.CommandWindowTextAreaLabel = uilabel(app.BatchEditorUIFigure);
            app.CommandWindowTextAreaLabel.Text = 'Command Window';
            app.CommandWindowTextArea = uitextarea(app.BatchEditorUIFigure);
            app.CommandWindowTextArea.ValueChangingFcn = createCallbackFcn(app, @CommandWindowTextAreaValueChanging, true);
            app.CommandWindowTextArea.Tooltip = {'Command line for debugging purposes.'};
            app.REditFieldLabel = uilabel(app.BatchEditorUIFigure);
            app.REditFieldLabel.Text = 'R (Ω)';
            app.REditField = uieditfield(app.BatchEditorUIFigure, 'text');
            app.REditField.Tooltip = {'Impedance in the dataset.'; 'Use $R$ to use the content of variable R.'};
            app.REditField.Value = '$R$';
            app.NumberoffilesSpinnerLabel = uilabel(app.BatchEditorUIFigure);
            app.NumberoffilesSpinnerLabel.Text = 'Number of files';
            app.NumberoffilesSpinner = uispinner(app.BatchEditorUIFigure);
            app.NumberoffilesSpinner.Limits = [1 Inf];
            app.NumberoffilesSpinner.RoundFractionalValues = 'on';
            app.NumberoffilesSpinner.ValueDisplayFormat = '%i';
            app.NumberoffilesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberoffilesSpinnerValueChanged, true);
            app.NumberoffilesSpinner.Tooltip = {'Number of datasets in the batch.'};
            app.NumberoffilesSpinner.Value = 1;
            app.FilenameEditFieldLabel = uilabel(app.BatchEditorUIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.BatchEditorUIFigure, 'text');
            app.FilenameEditField.Tooltip = {'Name of the files to be created.'; 'Use $var$ to use the content of variable var in the filenames.'};
            app.FilenameEditField.Value = 'Folder\$i$_Sample_$R$';
            app.Tree = uitree(app.BatchEditorUIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.NodeTextChangedFcn = createCallbackFcn(app, @TreeNodeTextChanged, true);
            app.Tree.Tooltip = {'Manage variable list.'};
            app.Tree.ClickedFcn = createCallbackFcn(app, @TreeClicked, true);
            app.VariablesNode = uitreenode(app.Tree);
            app.VariablesNode.NodeData = 'off';
            app.VariablesNode.Text = 'Variables';
            app.iNode = uitreenode(app.VariablesNode);
            app.iNode.NodeData = 'off';
            app.iNode.Text = 'i';
            app.RNode = uitreenode(app.VariablesNode);
            app.RNode.NodeData = 'on';
            app.RNode.Text = 'R';
            app.var1Node = uitreenode(app.VariablesNode);
            app.var1Node.NodeData = 'on';
            app.var1Node.Text = 'var1';
            app.var2Node = uitreenode(app.VariablesNode);
            app.var2Node.NodeData = 'on';
            app.var2Node.Text = 'var2';
            app.AddnewvariableNode = uitreenode(app.Tree);
            app.AddnewvariableNode.NodeData = 'off';
            app.AddnewvariableNode.Text = 'Add new variable';
            app.DeletevariableNode = uitreenode(app.Tree);
            app.DeletevariableNode.NodeData = 'off';
            app.DeletevariableNode.Text = 'Delete variable';
            app.ExecutionoptionsPanel = uipanel(app.BatchEditorUIFigure);
            app.ExecutionoptionsPanel.Title = 'Execution options';
            app.RunonlyonceCheckBox = uicheckbox(app.ExecutionoptionsPanel);
            app.RunonlyonceCheckBox.Tooltip = {'Remove the code from the dataset after its execution.'};
            app.RunonlyonceCheckBox.Text = 'Run only once';
            app.RunonlyonceCheckBox.Value = true;
            app.SaveafterrunCheckBox = uicheckbox(app.ExecutionoptionsPanel);
            app.SaveafterrunCheckBox.Tooltip = {'Save automatically the dataset after the code execution.'};
            app.SaveafterrunCheckBox.Text = 'Save after run';
            app.PauseafterrunCheckBox = uicheckbox(app.ExecutionoptionsPanel);
            app.PauseafterrunCheckBox.ValueChangedFcn = createCallbackFcn(app, @PauseafterrunCheckBoxValueChanged, true);
            app.PauseafterrunCheckBox.Tooltip = {'Prompt a non modal dialog after the execution.of the code'};
            app.PauseafterrunCheckBox.Text = 'Pause after run';
            app.DialogmessageEditField = uieditfield(app.ExecutionoptionsPanel, 'text');
            app.DialogmessageEditField.Enable = 'off';
            app.DialogmessageEditField.Value = 'Continue';
            app.RepeatoptionCheckBox = uicheckbox(app.ExecutionoptionsPanel);
            app.RepeatoptionCheckBox.Tooltip = {'Prompt a dialog after the execution.of the code to allow a new execution'};
            app.RepeatoptionCheckBox.Text = 'Repeat option';
            app.CloseafterrunCheckBox = uicheckbox(app.ExecutionoptionsPanel);
            app.CloseafterrunCheckBox.Tooltip = {'Close automatically the dataset after the code execution.'};
            app.CloseafterrunCheckBox.Text = 'Close after run';
            app.DialogmessageEditFieldLabel = uilabel(app.ExecutionoptionsPanel);
            app.DialogmessageEditFieldLabel.Enable = 'off';
            app.DialogmessageEditFieldLabel.Text = 'Dialog message';
            % Show the figure after all components are created
            app.BatchEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatchCreator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BatchEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BatchEditorUIFigure)
        end
    end
end