classdef DataSplitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataSplitterUIFigure           matlab.ui.Figure
        AdvancedRsettingsButtonGroup   matlab.ui.container.ButtonGroup
        CustomTextArea                 matlab.ui.control.TextArea
        R2parallelEditField            matlab.ui.control.EditField
        R2EditField_2Label             matlab.ui.control.Label
        R2seriesEditField              matlab.ui.control.EditField
        R2EditFieldLabel               matlab.ui.control.Label
        CustomfunctionButton           matlab.ui.control.RadioButton
        R2inparalleltoRButton          matlab.ui.control.RadioButton
        R2inseriestoRButton            matlab.ui.control.RadioButton
        JustRButton                    matlab.ui.control.RadioButton
        ExportoptionsPanel             matlab.ui.container.Panel
        Button                         matlab.ui.control.StateButton
        SplitXpositionEditField        matlab.ui.control.NumericEditField
        SplitXpositionEditFieldLabel   matlab.ui.control.Label
        XrightEditField                matlab.ui.control.NumericEditField
        XrightEditFieldLabel           matlab.ui.control.Label
        XleftEditField                 matlab.ui.control.NumericEditField
        XleftEditFieldLabel            matlab.ui.control.Label
        ColorButton                    matlab.ui.control.Button
        MarkinplotCheckBox             matlab.ui.control.CheckBox
        REditField                     matlab.ui.control.EditField
        REditFieldLabel                matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        SplitNumberSpinner             matlab.ui.control.Spinner
        SplitNumberSpinnerLabel        matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        SaveintofolderLabel            matlab.ui.control.Label
        SaveintofolderButton           matlab.ui.control.Button
        SignaldetailsPanel             matlab.ui.container.Panel
        TabGroup3                      matlab.ui.container.TabGroup
        ImportTab                      matlab.ui.container.Tab
        OpenfromadatasetfileButton     matlab.ui.control.Button
        ImportdatafromafileButton      matlab.ui.control.Button
        DefineTab                      matlab.ui.container.Tab
        DefinedatasetButton            matlab.ui.control.Button
        t_maxSpinner                   matlab.ui.control.Spinner
        t_maxSpinnerLabel              matlab.ui.control.Label
        t_minSpinner                   matlab.ui.control.Spinner
        t_minSpinnerLabel              matlab.ui.control.Label
        FtEditField                    matlab.ui.control.EditField
        FtLabel                        matlab.ui.control.Label
        YnameEditField                 matlab.ui.control.EditField
        YnameEditFieldLabel            matlab.ui.control.Label
        XnameEditField                 matlab.ui.control.EditField
        XnameEditFieldLabel            matlab.ui.control.Label
        YunitsEditField                matlab.ui.control.EditField
        YunitsEditFieldLabel           matlab.ui.control.Label
        XunitsEditField                matlab.ui.control.EditField
        XunitsEditFieldLabel           matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        ExportCheckBox                 matlab.ui.control.CheckBox
        AddtoplotCheckBox              matlab.ui.control.CheckBox
        DeletedatasetButton            matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        addTab                         matlab.ui.container.Tab
        DefinesplittingpositionsPanel  matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        PeakfinderTab                  matlab.ui.container.Tab
        FindpeaksButton                matlab.ui.control.Button
        MinPeakHeightEditField         matlab.ui.control.NumericEditField
        MinPeakHeightEditFieldLabel    matlab.ui.control.Label
        MinPeakWidthEditField          matlab.ui.control.NumericEditField
        MinPeakWidthEditFieldLabel     matlab.ui.control.Label
        MinPeakProminenceEditField     matlab.ui.control.NumericEditField
        MinPeakProminenceEditFieldLabel  matlab.ui.control.Label
        FindthepositivepeaksButton     matlab.ui.control.StateButton
        FindpeaksinDropDown            matlab.ui.control.DropDown
        FindpeaksinDropDownLabel       matlab.ui.control.Label
        ListTab                        matlab.ui.container.Tab
        DeleteButton                   matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        savePath % Description
        DATA % Description
        PKSplot % Description
        currPKplot
        currPK;
        PKS
        LOCS
        METADATA % Description
        markColor={[0.1490,0.5490,0.8660],1}; % Description
        R % Description
        patches % Description
        axLims
        operations % Description
        xLimits = [0,0,0] % Description
    end
    
    methods (Access = private)
        
        function loadData(app,event)
            app.FindpeaksinDropDown.Items=fields(app.DATA);
            vars=fieldnames(app.DATA);
            cla(app.UIAxes);
            hold(app.UIAxes,'on')
            cla(app.UIAxes2);
            hold(app.UIAxes2,'on')
            for k=1:numel(vars)
                try
                    app.METADATA.(vars{k}).graphics{1}=plot(app.UIAxes,app.DATA.(vars{k}){:});
                    app.METADATA.(vars{k}).graphics{2}=plot(app.UIAxes2,0,0);
                    app.METADATA.(vars{k}).graphics{2}.Visible='off';
                catch me
                    app.METADATA.(vars{k}).graphics{1}=plot(app.UIAxes,app.DATA.(vars{k}));
                    app.METADATA.(vars{k}).graphics{2}=plot(app.UIAxes2,0,0);
                    app.METADATA.(vars{k}).graphics{2}.Visible='off';
                end
            end
            app.PKSplot=plot(app.UIAxes,0,0,'kx','Visible','off');
            app.currPKplot=plot(app.UIAxes,0,0,'mo','Visible','off');
            legend(app.UIAxes,vars);
            app.FindpeaksinDropDownValueChanged(event)
            app.DefinesplittingpositionsPanel.Enable='on';


            tabOriginal = app.TabGroup.SelectedTab;
            i = find(app.TabGroup.Children == tabOriginal);
            %app.updateData(i);
            % if strcmp(event.EventName,'OpenDataset')
            %     app.DATA{end+1} = app.addData(event.Value);
            % else
            %     app.DATA{end+1} = app.addData;
            % end
            for k=1:numel(vars)
                if k<=numel(findall(app.TabGroup, 'type', 'uitab'))
                    app.TabGroup.Children(k).Title=vars{k};
                else
                    %// Create a new uitab in this group
                    tab = uitab(app.TabGroup,'Title', vars{k});
                end
            end
            if numel(vars)==numel(findall(app.TabGroup, 'type', 'uitab'))
                uitab(app.TabGroup,'Title', 'New signal');
            end
            app.TabGroupSelectionChanged(event);
            
        end
        
        function defaultMetadata(app,name,text)
            if ~exist('text','var')
                text=cell(2);
            elseif size(text,1)==1
                text=[text;cell(size(text))];
                for k=1:size(text,2)
                    str = text{1,k};
                    expression = '\((\w+).*\)';
                    [units,names] = regexp(str,expression,'tokens','split');
                    if ~isempty(units)&&~isempty(units{1})
                        text{2,k}=[units{1}{:}];
                        text{1,k}=[names{:}];
                    end
                end

            end
        
            if isempty(text{1,1})
                app.METADATA.(name).Xname=app.XnameEditField.Value;%'t';
            else
                app.METADATA.(name).Xname=text{1,1};
            end
            if isempty(text{2,1})
                app.METADATA.(name).Xunits=app.XunitsEditField.Value;%'s';
            else
                app.METADATA.(name).Xunits=text{2,1};
            end
            if isempty(text{1,2})
                app.METADATA.(name).Yname=app.YnameEditField.Value;%'V';
            else
                app.METADATA.(name).Yname=text{1,2};
            end
            if isempty(text{2,2})
                app.METADATA.(name).Yunits=app.YunitsEditField.Value;%'V';
            else
                app.METADATA.(name).Yunits=text{2,2};
            end
            app.METADATA.(name).plot=true;
            app.METADATA.(name).export=true;

        end
        
        function splitData(app,i)
            if ~exist('i','var')
                i=app.SplitNumberSpinner.Value;
            end
            if i==0
                vars=fieldnames(app.DATA);
                for k=1:numel(vars)
                    app.METADATA.(vars{k}).graphics{2}.XData=[];
                    app.METADATA.(vars{k}).graphics{2}.YData=[];
                end
            else
                dX=app.LOCS(i)-app.LOCS(app.currPK);
                app.SplitXpositionEditField.Value=app.SplitXpositionEditField.Value+dX;
                app.currPK=i;
                m=app.LOCS(i)-app.XleftEditField.Value;
                M=app.LOCS(i)+app.XrightEditField.Value;
                vars=fieldnames(app.DATA);
                for k=1:numel(vars)
                    ind=app.METADATA.(vars{k}).graphics{1}.XData>=m&app.METADATA.(vars{k}).graphics{1}.XData<=M;
                    app.METADATA.(vars{k}).graphics{2}.XData=app.METADATA.(vars{k}).graphics{1}.XData(ind)-app.LOCS(i);
                    app.METADATA.(vars{k}).graphics{2}.YData=app.METADATA.(vars{k}).graphics{1}.YData(ind);
                    if app.METADATA.(vars{k}).plot
                        app.METADATA.(vars{k}).graphics{2}.Visible='on';
                    else
                        app.METADATA.(vars{k}).graphics{2}.Visible='off';
                    end
                end

                app.currPKplot.XData=app.LOCS(app.currPK);
                app.currPKplot.YData=app.PKS(app.currPK);
            end
        end
        
        function [X,Y] = deleteInf(app,X,Y)
            ind=~isfinite(Y);
            X(ind)=[];
            Y(ind)=[];
        end
        
        function value = calculateR(app,R,Rvalue)
            if exist('Rvalue','var')
                evalin('base',sprintf('R=%e;',Rvalue));
                evalin('base',sprintf('r=%e;',Rvalue));
            end
            replacements={'Kk',3;'M',6;'Gg',9;'Tt',12;'m',-3;'uU',-6;'nN',-9;'pP',-12};
            for k=1:size(replacements,1)
                ind=regexp(R,sprintf('\\d[%s]',replacements{k,1}));
                if ~isempty(ind)
                    ind(end+1)=numel(R);
                    auxR=R(1:ind(1));
                    for q=1:numel(ind)-1
                        auxR=sprintf('%se%i%s',auxR,replacements{k,2},R(ind(q)+2:ind(q+1)));
                    end
                    R=auxR;
                end
            end
            
            R=evalin("base",[R,';']);

            value=R;
        end
        
        function limitsChangedCallbackFcn(app,hObj,data)
            
            Y=[data.NewLimits(1) data.NewLimits(1) data.NewLimits(2) data.NewLimits(2)];
            for k=1:numel(app.patches)
                app.patches{k}.YData=Y;
            end
        end
        
        function str2 = renameStructField(app,str, oldName, newName)
            if ~exist('newName','var')
                newName='';
            end

            if ~strcmp(oldName, newName)
                vars = fieldnames(str);
                
                for k=1:numel(vars)
                    if strcmp(vars{k},oldName)
                        if ~isempty(newName)
                            str2.(newName)=str.(oldName);
                        end
                    else
                        str2.(vars{k})=str.(vars{k});
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.savePath = [cd,'\'];
            app.R.last='10M';
            app.R.series='1K';
            app.R.parallel='10M';
            app.R.custom='R*1.5+1M';
            app.R.Req=10e6;
            app.patches={};
            str=app.savePath(1:end-1);
            if numel(str)>44
                str=sprintf('%s......%s',str(1:19),str(end-18:end));
            end
            app.SaveintofolderLabel.Text=str;
            app.ColorButton.Icon=cat(3,app.markColor{1}(1)*ones(16),app.markColor{1}(2)*ones(16),app.markColor{1}(3)*ones(16));

            app.UIAxes.YAxis.LimitsChangedFcn = @(hObj,data)app.limitsChangedCallbackFcn(hObj,data);
        end

        % Button pushed function: ImportdatafromafileButton
        function ImportdatafromafileButtonPushed(app, event)
                        
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uigetfile( ...
                { '*.txt;*.csv;*.dat;*.asc;*.m', 'All ASCII Files (*.txt, *.csv, *.dat, *.asc, *.m)';
                '*.mat', 'MATLAB Binary file (*.mat)';
                '*.mat', 'PicoScope exported MATLAB Binary file (*.mat)';
                '*.csv', 'CSV from Tektronix oscilloscope (*.csv)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Data File',app.savePath);
            delete(f);
            

            if filterindex==0
                return;
            end

            if filterindex==3
                IN=load([pathname,filename],'A','B','C','D','Tstart','Tinterval');
                vars={'A','B','C','D'};
                for k=1:numel(vars)
                    if isfield(IN,vars{k})
                        t=zeros(size(IN.(vars{k})));
                        t(:)=(1:numel(t))*IN.Tinterval+IN.Tstart;
                        [X,Y] = app.deleteInf(t,IN.(vars{k}));
                        app.DATA.(vars{k}){1}=X;
                        app.DATA.(vars{k}){2}=Y;
                        app.defaultMetadata(vars{k});
                    end
                end
                
            elseif filterindex==2
                IN=load([pathname,filename],'-mat');
                vars=fieldnames(IN);
                if numel(vars)==1
                    t=zeros(size(IN.(vars{1})));
                    t(:)=1:numel(t);
                    [X,Y] = app.deleteInf(t,IN.(vars{1}));
                    app.DATA.(vars{1}){1}=X;
                    app.DATA.(vars{1}){2}=Y;
                    app.defaultMetadata(vars{1});
                else
                    for k=2:numel(vars)
                        [X,Y] = app.deleteInf(IN.(vars{1}),IN.(vars{k}));
                        app.DATA.(vars{k}){1}=X;
                        app.DATA.(vars{k}){2}=Y;
                        app.defaultMetadata(vars{k});
                    end
                end
            else
                IN=uiimport([pathname,filename]);
                if isempty(IN)
                    return;
                end
                if isequal(fieldnames(IN),{'data'})||isequal(fieldnames(IN),{'data';'textdata'})||isequal(fieldnames(IN),{'data';'textdata';'colheaders'})||(numel(fieldnames(IN))==1&&min(size(IN.(subsref(fieldnames(IN),substruct('{}',{1})))))==2)
                    if (numel(fieldnames(IN))==1&&min(size(IN.(subsref(fieldnames(IN),substruct('{}',{1})))))==2)
                        auxvarname=fieldnames(IN);
                        IN=app.renameStructField(IN,auxvarname{1},'data');
                    end
                    siz=size(IN.data);
                    ind=struct('type','()','subs',{{':',':'}});
                    pos=0;
                    if siz(1)>=siz(2)
                        pos=1;
                        ind.subs{2}='k';
                    end
                    ind1=ind;
                    ind1.subs{pos+1}=1;
                    if ~isfield(IN,'colheaders')
                        IN.colheaders=cell(siz(pos+1),1);
                        if exist('auxvarname','var')
                            IN.colheaders(:)={auxvarname{1}};
                        else
                            IN.colheaders(:)={app.NameEditField.Value};
                        end
                    end
                    if ~isfield(IN,'textdata')||size(IN.textdata,2)==1
                        IN.textdata=cell(2,siz(pos+1));
                    end

                    if siz(pos+1)==1
                        t=zeros(size(IN.data));
                        t(:)=1:numel(t);
                        varname=IN.colheaders{1};
                        i=1;
                        while isfield(app.DATA,varname)
                            varname=sprintf('%s_%i',vars{1},i);
                            i=i+1;
                        end
                        [X,Y] = app.deleteInf(t,subsref(IN.data,ind1));
                        app.DATA.(varname){1}=X;
                        app.DATA.(varname){2}=Y;
                        app.defaultMetadata(varname,[cell(siz((~pos)+1),1),IN.textdata(:,1)]);
                        
                    else
                        for k=2:siz(pos+1)

                            varname=IN.colheaders{k};
                            if ~isvarname(varname)
                                varname=matlab.lang.makeValidName(varname);
                                if ~isvarname(varname)
                                    varname='Dataset';
                                end
                                IN.colheaders{k}=varname;
                            end
                            i=1;
                            while isfield(app.DATA,varname)
                                varname=sprintf('%s_%i',IN.colheaders{k},i);
                                i=i+1;
                            end


                            ind.subs{pos+1}=k;
                            [X,Y] = app.deleteInf(subsref(IN.data,ind1),subsref(IN.data,ind));
                            app.DATA.(varname){1}=X;
                            app.DATA.(varname){2}=Y;
                            app.defaultMetadata(varname,IN.textdata(:,[1,k]));
                        end
                    end

                else
                    vars=fieldnames(IN);
                    if numel(vars)==1
                        t=zeros(size(IN.(vars{1})));
                        t(:)=1:numel(t);
                        varname=vars{1};
                        i=1;
                        while isfield(app.DATA,varname)
                            varname=sprintf('%s_%i',vars{1},i);
                            i=i+1;
                        end

                        [X,Y] = app.deleteInf(t,IN.(vars{1})); 
                        app.DATA.(varname){1}=X;
                        app.DATA.(varname){2}=Y;
                        app.defaultMetadata(varname);
                    else
                        for k=2:numel(vars)
                            varname=vars{k};
                            i=1;
                            while isfield(app.DATA,varname)
                                varname=sprintf('%s_%i',vars{k},i);
                                i=i+1;
                            end
                            
                            [X,Y] = app.deleteInf(IN.(vars{1}),IN.(vars{k}));
                            app.DATA.(varname){1}=X;
                            app.DATA.(varname){2}=Y;
                            app.defaultMetadata(varname);
                        end
                    end
                end
            end
            loadData(app,event);
        end

        % Button pushed function: OpenfromadatasetfileButton
        function OpenfromadatasetfileButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uigetfile( ...
                { '*.ndl','NanoDataLyzer dataset (*.ndl)'; ...
                '*.mat','Binary MAT-file (*.mat)'; ...
                '*.*',  'All Files (*.*)'}, ...
                'Open a dataset file',app.savePath,'MultiSelect','on');
                
            delete(f);

            if filterindex==0
                return;
            end

            if ~isa(filename,'cell')
                filename = {filename};
            end
            
            for k = 1:numel(filename)

                IN=load([pathname,filename{k}],'-mat','data');
                
                varname=IN.data.labels{1};
                if isempty(varname)
                    varname=filename;
                    IN.data.labels{1}=varname;
                end
                            if ~isvarname(varname)
                                varname=matlab.lang.makeValidName(varname);
                                if ~isvarname(varname)
                                    varname='Dataset';
                                end
                                IN.data.labels{1}=varname;
                            end
                            i=1;
                            while isfield(app.DATA,varname)
                                varname=sprintf('%s_%i',IN.data.labels{1},i);
                                i=i+1;
                            end
                    
                    app.DATA.(varname){1}=IN.data.plotX(:);
                    app.DATA.(varname){2}=IN.data.plotY(:);
                    app.defaultMetadata(varname,{IN.data.labels{3},IN.data.labels{4};IN.data.labels{5},IN.data.labels{6}});
                    app.REditField.Value=num2str(IN.data.R);
                    app.JustRButton.Value=true;
                

            end
        loadData(app,event);
            
        end

        % Button pushed function: FindpeaksButton
        function FindpeaksButtonPushed(app, event)
            data=app.DATA.(app.FindpeaksinDropDown.Value);
            if ~isa(data,'cell')
                Y=data;
                X=1:numel(Y);
            else
                Y=data{2};
                X=data{1};
            end

            if app.FindthepositivepeaksButton.Value
                Y=-Y;
            end
            
            [app.PKS,app.LOCS,W,P]=findpeaks(Y,X,"MinPeakProminence",app.MinPeakProminenceEditField.Value,...
                "MinPeakWidth",app.MinPeakWidthEditField.Value,"MinPeakHeight",app.MinPeakHeightEditField.Value*(-app.FindthepositivepeaksButton.Value+0.5)*2);

            if app.FindthepositivepeaksButton.Value
                app.PKS=-app.PKS;
            end

            app.PKSplot.XData=app.LOCS;
            app.PKSplot.YData=app.PKS;
            app.PKSplot.Visible='on';
            if numel(app.LOCS)>0
                app.UITable.Data = num2cell([(1:numel(app.LOCS))',app.LOCS]);

                app.ExportoptionsPanel.Enable="on";
                app.SplitNumberSpinner.Limits=[1,numel(app.LOCS)];
                app.SplitXpositionEditField.Value=app.LOCS(1);
                app.XleftEditField.Value=W(1)*3;
                app.XrightEditField.Value=W(1)*3;
                app.currPK=1;
                app.splitData();
                app.currPKplot.Visible='on';

            else
                app.ExportoptionsPanel.Enable="off";
                app.currPKplot.Visible="off";
                app.SplitNumberSpinner.Value=1;
            end

        end

        % Value changed function: FindpeaksinDropDown
        function FindpeaksinDropDownValueChanged(app, event)
            
            data=app.DATA.(app.FindpeaksinDropDown.Value);
            if ~isa(data,'cell')
                Y=data;
                X=1:numel(Y);
                W=5;
            else
                Y=data{2};
                X=data{1};
                W=5*mean(diff(X));
            end

            if app.FindthepositivepeaksButton.Value
                M=mean(Y);
                m=min(Y);
            else
                m=mean(Y);
                M=max(Y);
            end

            app.MinPeakHeightEditField.Value=(m+M)/2;
            app.MinPeakProminenceEditField.Value=(M-m)/5;
            app.MinPeakWidthEditField.Value=W;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title,'New signal')
                app.TabGroup3.Visible='on';
                app.AddtoplotCheckBox.Visible='off';
                app.ExportCheckBox.Visible='off';
                app.DeletedatasetButton.Visible='off';
                str='Dataset';
                varname=str;
                i=1;
                while isfield(app.DATA,varname)
                    varname=sprintf('%s_%i',str,i);
                    i=i+1;
                end
                app.NameEditField.Value=varname;
            else
                app.TabGroup3.Visible='off';
                app.AddtoplotCheckBox.Visible='on';
                app.ExportCheckBox.Visible='on';
                app.DeletedatasetButton.Visible='on';
                name=app.NameEditField.Value;
                if isfield(app.METADATA,name)
                    app.METADATA.(name).Xname=app.XnameEditField.Value;
                    app.METADATA.(name).Xunits=app.XunitsEditField.Value;
                    app.METADATA.(name).Yname=app.YnameEditField.Value;
                    app.METADATA.(name).Yunits=app.YunitsEditField.Value;
                    app.METADATA.(name).plot=app.AddtoplotCheckBox.Value;
                    app.METADATA.(name).export=app.ExportCheckBox.Value;
                end
                name=selectedTab.Title;
                app.NameEditField.Value=name;
                app.XnameEditField.Value=app.METADATA.(name).Xname;
                app.XunitsEditField.Value=app.METADATA.(name).Xunits;
                app.YnameEditField.Value=app.METADATA.(name).Yname;
                app.YunitsEditField.Value=app.METADATA.(name).Yunits;
                app.AddtoplotCheckBox.Value=app.METADATA.(name).plot;
                app.ExportCheckBox.Value=app.METADATA.(name).export;
            end
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            str = app.NameEditField.Value;
            if strcmp(app.TabGroup.SelectedTab.Title,'New signal')
                if ~isvarname(str)
                    app.NameEditField.Value='Dataset';
                end
            else
                if isvarname(str)
                    varname=str;
                    i=1;
                    while isfield(app.DATA,varname)
                        varname=sprintf('%s_%i',str,i);
                        i=i+1;
                    end
                    app.DATA = app.renameStructField(app.DATA, app.TabGroup.SelectedTab.Title, varname);
                    app.METADATA = app.renameStructField(app.METADATA, app.TabGroup.SelectedTab.Title, varname);
                    app.TabGroup.SelectedTab.Title=varname;
                    app.FindpeaksinDropDown.Items=fields(app.DATA);
                    legend(app.UIAxes,fieldnames(app.DATA));
                else
                    str=app.TabGroup.SelectedTab.Title;
                    if strcmp(str,'New Signal')
                        str='Dataset';
                        varname=str;
                        i=1;
                        while isfield(app.DATA,varname)
                            varname=sprintf('%s_%i',str,i);
                            i=i+1;
                        end
                    end
                    app.NameEditField.Value=str;
                end
            end
        end

        % Value changed function: FindthepositivepeaksButton
        function FindthepositivepeaksButtonValueChanged(app, event)
            value = app.FindthepositivepeaksButton.Value;
            if value
                app.FindthepositivepeaksButton.Text='Find negative peaks';
                app.MinPeakHeightEditFieldLabel.Text='Max Peak Height';
            else
                app.FindthepositivepeaksButton.Text='Find positive peaks';
                app.MinPeakHeightEditFieldLabel.Text='Min Peak Height';
            end
            app.FindpeaksinDropDownValueChanged(event);
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uisetcolor doesn't minimize our GUI
            app.markColor{1} = uisetcolor(app.markColor{1});
            app.ColorButton.Icon=cat(3,app.markColor{1}(1)*ones(16),app.markColor{1}(2)*ones(16),app.markColor{1}(3)*ones(16));
            delete(f);
            figure(app.DataSplitterUIFigure)
        end

        % Value changed function: XleftEditField
        function XleftEditFieldValueChanged(app, event)
            app.splitData;
            
        end

        % Value changed function: XrightEditField
        function XrightEditFieldValueChanged(app, event)
            app.splitData;
            
        end

        % Value changing function: SplitNumberSpinner
        function SplitNumberSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.splitData(changingValue);
        end

        % Value changed function: AddtoplotCheckBox
        function AddtoplotCheckBoxValueChanged(app, event)
            value = app.AddtoplotCheckBox.Value;
            if value
                app.METADATA.(app.TabGroup.SelectedTab.Title).graphics{1}.Visible='on';
                if strcmp(app.DefinesplittingpositionsPanel.Enable,'on')
                    app.METADATA.(app.TabGroup.SelectedTab.Title).graphics{2}.Visible='on';
                end
            else
                app.METADATA.(app.TabGroup.SelectedTab.Title).graphics{1}.Visible='off';
                app.METADATA.(app.TabGroup.SelectedTab.Title).graphics{2}.Visible='off';
            end
        end

        % Button pushed function: SaveintofolderButton
        function SaveintofolderButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uigetdir doesn't minimize our GUI
            pathname = uigetdir(app.savePath,'Select folder to save data in');
            delete(f); %delete the dummy figure

            if pathname==0
                return;
            end
            str=pathname;
            if numel(str)>44
                str=sprintf('%s......%s',str(1:19),str(end-18:end));
            end
            
            app.savePath = [pathname,'\'];
            app.SaveintofolderLabel.Text=str;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            name=app.NameEditField.Value;
            if isfield(app.METADATA,name)
                app.METADATA.(name).Xname=app.XnameEditField.Value;
                app.METADATA.(name).Xunits=app.XunitsEditField.Value;
                app.METADATA.(name).Yname=app.YnameEditField.Value;
                app.METADATA.(name).Yunits=app.YunitsEditField.Value;
                app.METADATA.(name).plot=app.AddtoplotCheckBox.Value;
                app.METADATA.(name).export=app.ExportCheckBox.Value;
            end


            dataR=app.R.Req;
            
            num=app.SplitNumberSpinner.Value;
            pathname=app.savePath;
            name=app.FileNameEditField.Value;

            vars=fieldnames(app.DATA);
            for k=1:numel(vars)
                if app.METADATA.(vars{k}).export
                    X=zeros(numel(app.METADATA.(vars{k}).graphics{2}.XData),1);
                    Y=X;
                    X(:)=app.METADATA.(vars{k}).graphics{2}.XData;
                    Y(:)=app.METADATA.(vars{k}).graphics{2}.YData;

                    if strncmpi(app.METADATA.(vars{k}).Yname,'i',1)||strcmpi(app.METADATA.(vars{k}).Yunits,'a')
                        mode='I - t with R';
                    else
                        mode='V - t with R';
                    end

                    data = struct('Table',table(X,Y),...
                        'plotX',X,...
                        'plotY',Y,...
                        'dataForce',[],...
                        'R',dataR,...
                        'plotForceParams',{{'none',true,true,'x/22.5e-3',0,'F (N)','x + 0'}},...
                        'mode',mode,...
                        'labels',{{[name,vars{k}],'',...
                        app.METADATA.(vars{k}).Xname,app.METADATA.(vars{k}).Yname,...
                        app.METADATA.(vars{k}).Xunits,app.METADATA.(vars{k}).Yunits,...
                        '',vars{k}}});

                    save(sprintf('%s%s_%i%s.ndl',pathname,name,num,vars{k}),'data','-mat');
                end
            end

            if app.MarkinplotCheckBox.Value
                ax=axis(app.UIAxes);

                m=app.LOCS(num)-app.XleftEditField.Value;
                M=app.LOCS(num)+app.XrightEditField.Value;

                app.patches{end+1}=patch(app.UIAxes,[m M M m],[ax(3) ax(3) ax(4) ax(4)],app.markColor{1},'Linestyle','none','FaceAlpha',.5);
                app.patches{end}.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end

            try
                app.SplitNumberSpinner.Value=app.SplitNumberSpinner.Value+1;
                app.splitData(app.SplitNumberSpinner.Value);
            end

        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            R=app.calculateR(app.REditField.Value);
            
            if isfinite(R)
                app.R.last=app.REditField.Value;
                app.REditField.Value=sprintf('%1.4e',R);
            else
                if app.R2inparalleltoRButton.Value
                    if isinf(R)
                        app.REditField.Value='inf';
                    else
                        app.REditField.Value=app.R.last;
                        R=app.calculateR(app.REditField.Value);
                    end
                elseif app.CustomfunctionButton.Value
                    if isinf(R)&&isfinite(app.calculateR(app.R.custom,R))
                        app.REditField.Value='inf';
                        app.R.last='inf';
                    else
                        app.REditField.Value=app.R.last;
                        R=app.calculateR(app.REditField.Value);
                    end
                else
                    app.REditField.Value=app.R.last;
                    R=app.calculateR(app.REditField.Value);
                end
            end

            if app.JustRButton.Value
                app.R.Req=R;
            elseif app.R2inseriestoRButton.Value
                app.R.Req=R+app.calculateR(app.R.series);
            elseif app.R2inparalleltoRButton.Value
                app.R.Req=1./(1./R+1./app.calculateR(app.R.parallel));
            elseif app.CustomfunctionButton.Value
                app.R.Req=app.calculateR(app.R.custom,R);
            end
            app.REditField.Tooltip=sprintf('Req=%.4e',app.R.Req);

            glow12=[0.1490    0.5490    0.8660
                0.9600    0.4660    0.1600
                1.0000    0.9090    0.3920
                0.7520    0.3600    0.9840
                0.2860    0.8580    0.2500
                0.4230    0.9560    1.0000
                0.9490    0.4030    0.7720
                1.0000    0.4780    0.4540
                0.4900    0.6620    1.0000
                0.9960    0.7520    0.2980
                0.1210    0.8110    0.7450
                0.8620    0.6000    0.4230];

            if ~isempty(app.patches)
                if isequal(app.markColor{1},app.patches{end}.FaceColor)

                    app.markColor{2}=rem(app.markColor{2},size(glow12,1))+1;
                    while isequal(app.markColor{1},glow12(app.markColor{2},:))
                        app.markColor{2}=rem(app.markColor{2},size(glow12,1))+1;
                    end
                    app.markColor{1}=glow12(app.markColor{2},:);
                    app.ColorButton.Icon=cat(3,app.markColor{1}(1)*ones(16),app.markColor{1}(2)*ones(16),app.markColor{1}(3)*ones(16));
                end
            end

        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            
            if value
                app.AdvancedRsettingsButtonGroup.Visible="on";
                app.Button.Text='-';
            else
                app.AdvancedRsettingsButtonGroup.Visible="off";
                app.Button.Text='+';
            end
            
            
        end

        % Selection changed function: AdvancedRsettingsButtonGroup
        function AdvancedRsettingsButtonGroupSelectionChanged(app, event)
            
            app.CustomTextArea.Enable='off';
            app.R2seriesEditField.Enable='off';
            app.R2EditFieldLabel.Enable='off';
            app.R2parallelEditField.Enable='off';
            app.R2EditField_2Label.Enable='off';

            if app.CustomfunctionButton.Value
                app.CustomTextArea.Enable='on';
            elseif app.R2inseriestoRButton.Value
                app.R2seriesEditField.Enable='on';
                app.R2EditFieldLabel.Enable='on';
            elseif app.R2inparalleltoRButton.Value
                app.R2parallelEditField.Enable='on';
                app.R2EditField_2Label.Enable='on';
            else
                
            end

            app.REditFieldValueChanged(event);
        end

        % Value changed function: R2seriesEditField
        function R2seriesEditFieldValueChanged(app, event)
            
            R=app.calculateR(app.R2seriesEditField.Value);
            
            if isfinite(R)
                app.R.series=app.R2seriesEditField.Value;
                app.R2seriesEditField.Value=sprintf('%1.4e',R);
            else
                app.R2seriesEditField.Value=app.R.series;
                return;
            end

            Req=app.calculateR(app.R.last)+R;


            if ~isfinite(Req)
                app.REditField.Value='10M';
                app.R.last='10M';
                Req=10e6+R;
            end

            app.REditField.Tooltip=sprintf('Req=%.4e',Req);
            app.R.Req=Req;
        end

        % Value changed function: R2parallelEditField
        function R2parallelEditFieldValueChanged(app, event)
            R=app.calculateR(app.R2parallelEditField.Value);
            
            if isfinite(R)
                app.R.parallel=app.R2parallelEditField.Value;
                app.R2parallelEditField.Value=sprintf('%1.4e',R);
            else
                app.R2parallelEditField.Value=app.R.parallel;
                return;
            end

            Req=1./(1./app.calculateR(app.R.last)+1./R);

            if ~isfinite(Req)
                app.REditField.Value='10M';
                app.R.last='10M';
                Req=1./(1/10e6+1./R);
            end
            
            app.REditField.Tooltip=sprintf('Req=%.4e',Req);
            app.R.Req=Req;
        end

        % Value changed function: CustomTextArea
        function CustomTextAreaValueChanged(app, event)
            R=app.calculateR(app.CustomTextArea.Value,app.calculateR(app.R.last));
            
            if isfinite(R)
                app.R.custom=app.CustomTextArea.Value;
                app.REditField.Tooltip=sprintf('Req=%.4e',R);
                app.R.Req=R;
            else
                app.CustomTextArea.Value=app.R.custom;
            end
        end

        % Value changed function: XnameEditField
        function XnameEditFieldValueChanged(app, event)
            value = app.XnameEditField.Value;
            if isempty(value)||~isvarname(value)
                value='t';
                app.XnameEditField.Value=value;
            end
            oldvalue = app.FtLabel.Text(3:end-3);
            app.FtLabel.Text=sprintf('F(%s) =',value);
            app.t_minSpinnerLabel.Text=sprintf('%s_min',value);
            app.t_maxSpinnerLabel.Text=sprintf('%s_max',value);
            app.FtEditField.Value=replace(app.FtEditField.Value,oldvalue,value);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if isempty(app.UITable.Data)
                app.UITable.Data={1,0};
                app.LOCS=0;
                app.PKS=nan;

                app.ExportoptionsPanel.Enable="on";
                app.SplitNumberSpinner.Limits=[1,numel(app.LOCS)];
                app.SplitXpositionEditField.Value=app.LOCS(1);
                app.XleftEditField.Value=app.MinPeakWidthEditField.Value*5;
                app.XrightEditField.Value=app.MinPeakWidthEditField.Value*5;
                app.currPK=1;
                %app.splitData();
                app.currPKplot.Visible='on';
                app.UITable.Selection=[1,2];
                app.UITableSelectionChanged();

            else
                [positions,I]=sort([app.UITable.Data{:,2},0]');
                i=find(positions==0,1);
                app.UITable.Data = num2cell([(1:numel(positions))',positions]);
                app.LOCS = positions;
                app.PKS(end+1) = nan;
                app.PKS = app.PKS(I);
                app.PKSplot.XData=app.LOCS;
                app.PKSplot.YData=app.PKS;
                app.SplitNumberSpinner.Limits=[1,numel(app.LOCS)];
                app.UITable.Selection=[i,2];
                app.UITableSelectionChanged();
            end

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
             i = app.UITable.Selection;
            if ~isempty(i)
                iX = unique(i(:,1),'stable');
                app.UITable.Data(iX,:) = [];
                positions=[app.UITable.Data{:,2}]';
                if isempty(positions)
                    app.UITable.Data=[];
                    app.LOCS=[];
                    app.PKS=[];
                    app.ExportoptionsPanel.Enable="off";
                    app.currPKplot.Visible="off";
                    app.SplitNumberSpinner.Value=1;
                    app.splitData(0);
                else
                    app.UITable.Data = num2cell([(1:numel(positions))',positions]);
                    app.LOCS(iX)=[];
                    app.PKS(iX)=[];
                    if isempty(app.UITable.Selection)
                        app.UITable.Selection=[iX(1)-1,2];
                        app.currPK=iX(1)-1;
                    else
                        app.currPK=iX(1);
                    end
                    app.UITableSelectionChanged();
                    app.SplitNumberSpinner.Limits=[1,numel(app.LOCS)];
                end
                app.PKSplot.XData=app.LOCS;
                app.PKSplot.YData=app.PKS;
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
            newData = event.NewData;
            [positions,I]=sort([app.UITable.Data{:,2}]');
            i=find(positions==newData,1);
            app.UITable.Data = num2cell([(1:numel(positions))',positions]);
            app.LOCS=positions;
            app.PKS=app.PKS(I);
            app.PKS(i)=nan;
            app.PKSplot.XData=app.LOCS;
            app.PKSplot.YData=app.PKS;
            app.SplitNumberSpinner.Value=i;
            app.splitData(i);
            app.UITable.Selection=[i,2];
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            i = app.UITable.Selection;
            if ~isempty(i)
                iX = unique(i(:,1),'stable');
                app.SplitNumberSpinner.Value=iX(1);
                app.splitData(iX(1));
            end
        end

        % Button pushed function: DefinedatasetButton
        function DefinedatasetButtonPushed(app, event)
            
            if isempty(app.DATA)
                vars={};
            else
            vars=fieldnames(app.DATA);
            end

            dx=(app.t_maxSpinner.Value-app.t_minSpinner.Value)/100;

            for k=1:numel(vars)
                try
                    dx=min([diff(app.DATA.(vars{k}){1});dx]);
                catch me
                    dx=min([1,dx]);
                end
            end

            n=10*(app.t_maxSpinner.Value-app.t_minSpinner.Value)/dx;
            
            xq=linspace(app.t_minSpinner.Value,app.t_maxSpinner.Value,n)';
            
            evalin('base','clear');
            assignin('base',app.XnameEditField.Value,xq);

            for k=1:numel(vars)
                try
                    [x,v]=app.DATA.(vars{k}){:};
                catch me
                    v=app.DATA.(vars{k});
                    x=zeros(size(v));
                    x(:)=1:numel(x);
                end
                vq = interp1(x,v,xq);

                assignin('base',vars{k},vq);
            end

            try
                yq=evalin('base',vectorize(app.FtEditField.Value));
                app.DATA.(app.NameEditField.Value){1}=xq;
                app.DATA.(app.NameEditField.Value){2}=yq;
                app.defaultMetadata(app.NameEditField.Value);
                loadData(app,event)
            catch me
                uialert(app.DataSplitterUIFigure,me.message,'Error','Icon','error')
            end

            app.FindpeaksinDropDown.Items=fields(app.DATA);
        end

        % Value changed function: t_minSpinner
        function t_minSpinnerValueChanged(app, event)
            value = app.t_minSpinner.Value;
            if value>=app.t_maxSpinner.Value
                value=app.t_maxSpinner.Value-1;
                app.t_minSpinner.Value = value;
            end
            app.t_maxSpinner.Limits=[value,inf];
        end

        % Value changed function: t_maxSpinner
        function t_maxSpinnerValueChanged(app, event)
            value = app.t_maxSpinner.Value;
            if value<=app.t_minSpinner.Value
                value=app.t_minSpinner.Value+1;
                app.t_maxSpinner.Value = value;
            end
            app.t_minSpinner.Limits=[-inf,value];
        end

        % Button pushed function: DeletedatasetButton
        function DeletedatasetButtonPushed(app, event)
            
            if numel(fields(app.DATA))==1
                app.DefinesplittingpositionsPanel.Enable="off";
                app.UITable.Data=[];
                app.LOCS=[];
                app.PKS=[];
                app.ExportoptionsPanel.Enable="off";
                app.currPKplot.Visible="off";
                app.SplitNumberSpinner.Value=1;
                app.splitData(0);
                app.PKSplot.XData=app.LOCS;
                app.PKSplot.YData=app.PKS;
                delete(app.METADATA.(app.TabGroup.SelectedTab.Title).graphics{2});
                delete(app.METADATA.(app.TabGroup.SelectedTab.Title).graphics{1});
                app.METADATA=[];
                app.DATA=[];
            else
                delete(app.METADATA.(app.TabGroup.SelectedTab.Title).graphics{2});
                delete(app.METADATA.(app.TabGroup.SelectedTab.Title).graphics{1});
                app.METADATA = app.renameStructField(app.METADATA, app.TabGroup.SelectedTab.Title);
                app.DATA = app.renameStructField(app.DATA, app.TabGroup.SelectedTab.Title);
            end
            tab=app.TabGroup.SelectedTab;
            i=find(tab==app.TabGroup.Children);
            app.TabGroup.SelectedTab=app.TabGroup.Children(i+1);
            drawnow;
            app.TabGroupSelectionChanged(event)
            delete(tab);
            if isempty(app.DATA)
                app.FindpeaksinDropDown.Items={''};
                lgd=legend(app.UIAxes);
                set(lgd,'Visible','off')
            else
                app.FindpeaksinDropDown.Items=fields(app.DATA);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DataSplitterUIFigure = uifigure('Visible', 'off');
            app.DataSplitterUIFigure.Name = 'Data Splitter';
            app.DataSplitterUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.UIAxes = uiaxes(app.DataSplitterUIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'Xname (Xunits)')
            ylabel(app.UIAxes, 'Yname (Yunits)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.DataSplitterUIFigure);
            title(app.UIAxes2, 'Selected split')
            xlabel(app.UIAxes2, 'Xname (Xunits)')
            ylabel(app.UIAxes2, 'Yname (Yunits)')
            zlabel(app.UIAxes2, 'Z')
            app.DefinesplittingpositionsPanel = uipanel(app.DataSplitterUIFigure);
            app.DefinesplittingpositionsPanel.Enable = 'off';
            app.DefinesplittingpositionsPanel.Title = 'Define splitting positions';
            app.TabGroup2 = uitabgroup(app.DefinesplittingpositionsPanel);
            app.PeakfinderTab = uitab(app.TabGroup2);
            app.PeakfinderTab.Title = 'Peak finder';
            app.FindpeaksinDropDownLabel = uilabel(app.PeakfinderTab);
            app.FindpeaksinDropDownLabel.Text = 'Find peaks in';
            app.FindpeaksinDropDown = uidropdown(app.PeakfinderTab);
            app.FindpeaksinDropDown.Items = {};
            app.FindpeaksinDropDown.ValueChangedFcn = createCallbackFcn(app, @FindpeaksinDropDownValueChanged, true);
            app.FindpeaksinDropDown.Value = {};
            app.FindthepositivepeaksButton = uibutton(app.PeakfinderTab, 'state');
            app.FindthepositivepeaksButton.ValueChangedFcn = createCallbackFcn(app, @FindthepositivepeaksButtonValueChanged, true);
            app.FindthepositivepeaksButton.Text = 'Find the positive peaks';
            app.MinPeakProminenceEditFieldLabel = uilabel(app.PeakfinderTab);
            app.MinPeakProminenceEditFieldLabel.Text = 'Min Peak Prominence';
            app.MinPeakProminenceEditField = uieditfield(app.PeakfinderTab, 'numeric');
            app.MinPeakProminenceEditField.Limits = [0 Inf];
            app.MinPeakProminenceEditField.Value = 1;
            app.MinPeakWidthEditFieldLabel = uilabel(app.PeakfinderTab);
            app.MinPeakWidthEditFieldLabel.Text = 'Min Peak Width';
            app.MinPeakWidthEditField = uieditfield(app.PeakfinderTab, 'numeric');
            app.MinPeakHeightEditFieldLabel = uilabel(app.PeakfinderTab);
            app.MinPeakHeightEditFieldLabel.Text = 'Min Peak Height';
            app.MinPeakHeightEditField = uieditfield(app.PeakfinderTab, 'numeric');
            app.FindpeaksButton = uibutton(app.PeakfinderTab, 'push');
            app.FindpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindpeaksButtonPushed, true);
            app.FindpeaksButton.Text = 'Find peaks';
            app.ListTab = uitab(app.TabGroup2);
            app.ListTab.Title = 'List';
            app.UITable = uitable(app.ListTab);
            app.UITable.ColumnName = {'i'; 'X position'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.AddButton = uibutton(app.ListTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.ListTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.SignaldetailsPanel = uipanel(app.DataSplitterUIFigure);
            app.SignaldetailsPanel.Title = 'Signal details';
            app.TabGroup = uitabgroup(app.SignaldetailsPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.addTab = uitab(app.TabGroup);
            app.addTab.Tooltip = {''};
            app.addTab.Title = 'New signal';
            app.DeletedatasetButton = uibutton(app.SignaldetailsPanel, 'push');
            app.DeletedatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DeletedatasetButtonPushed, true);
            app.DeletedatasetButton.Visible = 'off';
            app.DeletedatasetButton.Text = 'Delete dataset';
            app.AddtoplotCheckBox = uicheckbox(app.SignaldetailsPanel);
            app.AddtoplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddtoplotCheckBoxValueChanged, true);
            app.AddtoplotCheckBox.Visible = 'off';
            app.AddtoplotCheckBox.Text = 'Add to plot';
            app.AddtoplotCheckBox.Value = true;
            app.ExportCheckBox = uicheckbox(app.SignaldetailsPanel);
            app.ExportCheckBox.Visible = 'off';
            app.ExportCheckBox.Text = 'Export';
            app.ExportCheckBox.Value = true;
            app.NameEditFieldLabel = uilabel(app.SignaldetailsPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.SignaldetailsPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.NameEditField.Value = 'Dataset';
            app.XunitsEditFieldLabel = uilabel(app.SignaldetailsPanel);
            app.XunitsEditFieldLabel.Text = 'X  units';
            app.XunitsEditField = uieditfield(app.SignaldetailsPanel, 'text');
            app.XunitsEditField.Value = 's';
            app.YunitsEditFieldLabel = uilabel(app.SignaldetailsPanel);
            app.YunitsEditFieldLabel.Text = 'Y  units';
            app.YunitsEditField = uieditfield(app.SignaldetailsPanel, 'text');
            app.YunitsEditField.Value = 'V';
            app.XnameEditFieldLabel = uilabel(app.SignaldetailsPanel);
            app.XnameEditFieldLabel.Text = 'X  name';
            app.XnameEditField = uieditfield(app.SignaldetailsPanel, 'text');
            app.XnameEditField.ValueChangedFcn = createCallbackFcn(app, @XnameEditFieldValueChanged, true);
            app.XnameEditField.Value = 't';
            app.YnameEditFieldLabel = uilabel(app.SignaldetailsPanel);
            app.YnameEditFieldLabel.Text = 'Y  name';
            app.YnameEditField = uieditfield(app.SignaldetailsPanel, 'text');
            app.YnameEditField.Value = 'V';
            app.TabGroup3 = uitabgroup(app.SignaldetailsPanel);
            app.ImportTab = uitab(app.TabGroup3);
            app.ImportTab.Title = 'Import';
            app.ImportdatafromafileButton = uibutton(app.ImportTab, 'push');
            app.ImportdatafromafileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdatafromafileButtonPushed, true);
            app.ImportdatafromafileButton.Text = 'Import data from a file';
            app.OpenfromadatasetfileButton = uibutton(app.ImportTab, 'push');
            app.OpenfromadatasetfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfromadatasetfileButtonPushed, true);
            app.OpenfromadatasetfileButton.Text = 'Open from a dataset file';
            app.DefineTab = uitab(app.TabGroup3);
            app.DefineTab.Title = 'Define';
            app.FtLabel = uilabel(app.DefineTab);
            app.FtLabel.Text = 'F(t) =';
            app.FtEditField = uieditfield(app.DefineTab, 'text');
            app.FtEditField.Value = 't';
            app.t_minSpinnerLabel = uilabel(app.DefineTab);
            app.t_minSpinnerLabel.Interpreter = 'tex';
            app.t_minSpinnerLabel.Text = 't_min';
            app.t_minSpinner = uispinner(app.DefineTab);
            app.t_minSpinner.ValueChangedFcn = createCallbackFcn(app, @t_minSpinnerValueChanged, true);
            app.t_maxSpinnerLabel = uilabel(app.DefineTab);
            app.t_maxSpinnerLabel.Interpreter = 'tex';
            app.t_maxSpinnerLabel.Text = 't_max';
            app.t_maxSpinner = uispinner(app.DefineTab);
            app.t_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @t_maxSpinnerValueChanged, true);
            app.t_maxSpinner.Value = 1;
            app.DefinedatasetButton = uibutton(app.DefineTab, 'push');
            app.DefinedatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DefinedatasetButtonPushed, true);
            app.DefinedatasetButton.Text = 'Define dataset';
            app.ExportoptionsPanel = uipanel(app.DataSplitterUIFigure);
            app.ExportoptionsPanel.Enable = 'off';
            app.ExportoptionsPanel.Title = 'Export options';
            app.SaveintofolderButton = uibutton(app.ExportoptionsPanel, 'push');
            app.SaveintofolderButton.ButtonPushedFcn = createCallbackFcn(app, @SaveintofolderButtonPushed, true);
            app.SaveintofolderButton.Text = 'Save into folder';
            app.SaveintofolderLabel = uilabel(app.ExportoptionsPanel);
            app.SaveintofolderLabel.Text = 'none';
            app.FileNameEditFieldLabel = uilabel(app.ExportoptionsPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.ExportoptionsPanel, 'text');
            app.FileNameEditField.Value = 'Sample';
            app.SplitNumberSpinnerLabel = uilabel(app.ExportoptionsPanel);
            app.SplitNumberSpinnerLabel.Text = 'Split Number';
            app.SplitNumberSpinner = uispinner(app.ExportoptionsPanel);
            app.SplitNumberSpinner.ValueChangingFcn = createCallbackFcn(app, @SplitNumberSpinnerValueChanging, true);
            app.SplitNumberSpinner.Limits = [1 Inf];
            app.SplitNumberSpinner.RoundFractionalValues = 'on';
            app.SplitNumberSpinner.Value = 1;
            app.SaveButton = uibutton(app.ExportoptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.REditFieldLabel = uilabel(app.ExportoptionsPanel);
            app.REditFieldLabel.Text = 'R(Ω)';
            app.REditField = uieditfield(app.ExportoptionsPanel, 'text');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.REditField.Tooltip = {'Req=1.0000e+07'};
            app.REditField.Value = '10M';
            app.MarkinplotCheckBox = uicheckbox(app.ExportoptionsPanel);
            app.MarkinplotCheckBox.Text = 'Mark in plot';
            app.MarkinplotCheckBox.Value = true;
            app.ColorButton = uibutton(app.ExportoptionsPanel, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = 'Color';
            app.XleftEditFieldLabel = uilabel(app.ExportoptionsPanel);
            app.XleftEditFieldLabel.Text = 'X left';
            app.XleftEditField = uieditfield(app.ExportoptionsPanel, 'numeric');
            app.XleftEditField.Limits = [0 Inf];
            app.XleftEditField.ValueDisplayFormat = '%.1e';
            app.XleftEditField.ValueChangedFcn = createCallbackFcn(app, @XleftEditFieldValueChanged, true);
            app.XrightEditFieldLabel = uilabel(app.ExportoptionsPanel);
            app.XrightEditFieldLabel.Text = 'X right';
            app.XrightEditField = uieditfield(app.ExportoptionsPanel, 'numeric');
            app.XrightEditField.Limits = [0 Inf];
            app.XrightEditField.ValueDisplayFormat = '%.1e';
            app.XrightEditField.ValueChangedFcn = createCallbackFcn(app, @XrightEditFieldValueChanged, true);
            app.SplitXpositionEditFieldLabel = uilabel(app.ExportoptionsPanel);
            app.SplitXpositionEditFieldLabel.Text = 'Split X position';
            app.SplitXpositionEditField = uieditfield(app.ExportoptionsPanel, 'numeric');
            app.Button = uibutton(app.ExportoptionsPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Tooltip = {'Advanced R settings'};
            app.Button.Text = '+';
            app.AdvancedRsettingsButtonGroup = uibuttongroup(app.DataSplitterUIFigure);
            app.AdvancedRsettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AdvancedRsettingsButtonGroupSelectionChanged, true);
            app.AdvancedRsettingsButtonGroup.Title = 'Advanced R settings';
            app.AdvancedRsettingsButtonGroup.Visible = 'off';
            app.JustRButton = uiradiobutton(app.AdvancedRsettingsButtonGroup);
            app.JustRButton.Text = 'Just R';
            app.JustRButton.Value = true;
            app.R2inseriestoRButton = uiradiobutton(app.AdvancedRsettingsButtonGroup);
            app.R2inseriestoRButton.Text = 'R2 in series to R';
            app.R2inparalleltoRButton = uiradiobutton(app.AdvancedRsettingsButtonGroup);
            app.R2inparalleltoRButton.Text = 'R2 in parallel to R';
            app.CustomfunctionButton = uiradiobutton(app.AdvancedRsettingsButtonGroup);
            app.CustomfunctionButton.Text = 'Custom function';
            app.R2EditFieldLabel = uilabel(app.AdvancedRsettingsButtonGroup);
            app.R2EditFieldLabel.Enable = 'off';
            app.R2EditFieldLabel.Text = 'R2';
            app.R2seriesEditField = uieditfield(app.AdvancedRsettingsButtonGroup, 'text');
            app.R2seriesEditField.ValueChangedFcn = createCallbackFcn(app, @R2seriesEditFieldValueChanged, true);
            app.R2seriesEditField.Enable = 'off';
            app.R2seriesEditField.Value = '1K';
            app.R2EditField_2Label = uilabel(app.AdvancedRsettingsButtonGroup);
            app.R2EditField_2Label.Enable = 'off';
            app.R2EditField_2Label.Text = 'R2';
            app.R2parallelEditField = uieditfield(app.AdvancedRsettingsButtonGroup, 'text');
            app.R2parallelEditField.ValueChangedFcn = createCallbackFcn(app, @R2parallelEditFieldValueChanged, true);
            app.R2parallelEditField.Enable = 'off';
            app.R2parallelEditField.Value = '10M';
            app.CustomTextArea = uitextarea(app.AdvancedRsettingsButtonGroup);
            app.CustomTextArea.ValueChangedFcn = createCallbackFcn(app, @CustomTextAreaValueChanged, true);
            app.CustomTextArea.Enable = 'off';
            app.CustomTextArea.Value = {'R*1.5+1M'};
            % Show the figure after all components are created
            app.DataSplitterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataSplitter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataSplitterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataSplitterUIFigure)
        end
    end
end