classdef Nanodatalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NanoDataLyzerUIFigure         matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        NewDatasetMenu                matlab.ui.container.Menu
        CloseDatasetMenu              matlab.ui.container.Menu
        OpenDatasetMenu               matlab.ui.container.Menu
        SaveDatasetMenu               matlab.ui.container.Menu
        SavecurrentdatasetMenu        matlab.ui.container.Menu
        SavealldatasetsMenu           matlab.ui.container.Menu
        Blank                         matlab.ui.container.Menu
        ImportDataMenu                matlab.ui.container.Menu
        ExportDataMenu                matlab.ui.container.Menu
        ExportcurrentdatasetMenu      matlab.ui.container.Menu
        ExportalldatasetsMenu         matlab.ui.container.Menu
        ExportalltoOriginMenu         matlab.ui.container.Menu
        Blank_2                       matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        UndoMenu                      matlab.ui.container.Menu
        RedoMenu                      matlab.ui.container.Menu
        Menu                          matlab.ui.container.Menu
        ClearMenu                     matlab.ui.container.Menu
        DeleteRowMenu                 matlab.ui.container.Menu
        AddRowMenu                    matlab.ui.container.Menu
        InsertRowMenu                 matlab.ui.container.Menu
        Blank_3                       matlab.ui.container.Menu
        CopyTableMenu                 matlab.ui.container.Menu
        PasteTableMenu                matlab.ui.container.Menu
        CopyPlotDataMenu              matlab.ui.container.Menu
        Blank_4                       matlab.ui.container.Menu
        ResetPlottoTableDataMenu      matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        IterateforalldatasetsMenu     matlab.ui.container.Menu
        ApplydenoisingMenu            matlab.ui.container.Menu
        ApplyintegrationMenu          matlab.ui.container.Menu
        CreatedatasetbatchMenu        matlab.ui.container.Menu
        SplitsignalintodatasetsMenu   matlab.ui.container.Menu
        SplittedpeaksanalysisMenu     matlab.ui.container.Menu
        ApplyaverageandstdMenu        matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        VersioninfoMenu               matlab.ui.container.Menu
        ProjectsiteMenu               matlab.ui.container.Menu
        AboutusMenu                   matlab.ui.container.Menu
        AdditionaloperationsPanel     matlab.ui.container.Panel
        FunctionafteroperationusexasvariableEditField  matlab.ui.control.EditField
        FunctionLabel                 matlab.ui.control.Label
        OperationDropDown             matlab.ui.control.DropDown
        OperationDropDownLabel        matlab.ui.control.Label
        MorePanel                     matlab.ui.container.Panel
        ApplyfunctionButton           matlab.ui.control.Button
        FunctionEditField             matlab.ui.control.EditField
        FunctionLabel_2               matlab.ui.control.Label
        StandarddeviationofthedistributionEditField  matlab.ui.control.NumericEditField
        StandarddeviationofthedistributionEditFieldLabel  matlab.ui.control.Label
        AveragevalueofthedistributionEditField  matlab.ui.control.NumericEditField
        AveragevalueofthedistributionEditFieldLabel  matlab.ui.control.Label
        TotalNumberofpointsEditField  matlab.ui.control.NumericEditField
        TotalNumberofpointsEditFieldLabel  matlab.ui.control.Label
        SmoothoptionsPanel            matlab.ui.container.Panel
        PolynomialorderSpinner        matlab.ui.control.Spinner
        PolynomialorderSpinnerLabel   matlab.ui.control.Label
        NSpinner                      matlab.ui.control.Spinner
        NSpinnerLabel                 matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        DataProcessingButton          matlab.ui.control.Button
        SaveTableDataButton           matlab.ui.control.Button
        IntegrationPanel              matlab.ui.container.Panel
        CalculatepowerButton          matlab.ui.control.Button
        IntegrationregionSlider       matlab.ui.control.Slider
        IntegrationregionLabel        matlab.ui.control.Label
        NcyclesSpinner                matlab.ui.control.Spinner
        NcyclesSpinnerLabel           matlab.ui.control.Label
        Button_2                      matlab.ui.control.StateButton
        TimepercyclesEditField        matlab.ui.control.NumericEditField
        TimepercyclesEditFieldLabel   matlab.ui.control.Label
        DenoisingPanel                matlab.ui.container.Panel
        BiascorrectionSpinnerLabel    matlab.ui.control.Label
        HighSpinnerLabel              matlab.ui.control.Label
        ReplaceDataButton             matlab.ui.control.Button
        ThresholdSpinner_2Label       matlab.ui.control.Label
        HighSpinner                   matlab.ui.control.Spinner
        LowSpinner                    matlab.ui.control.Spinner
        LowSpinnerLabel               matlab.ui.control.Label
        BandStopCheckBox              matlab.ui.control.CheckBox
        ThresholdSpinner_2            matlab.ui.control.Spinner
        DenoiseCheckBox               matlab.ui.control.CheckBox
        FFTButton                     matlab.ui.control.StateButton
        MethodDropDown                matlab.ui.control.DropDown
        SmoothCheckBox                matlab.ui.control.CheckBox
        BiascorrectionSpinner         matlab.ui.control.Spinner
        SaveparamsButton              matlab.ui.control.Button
        RLabel                        matlab.ui.control.Label
        CodeandCommentsLabel          matlab.ui.control.Label
        LoadparamsButton              matlab.ui.control.Button
        Button_3                      matlab.ui.control.StateButton
        RemoveunsortedXpointsButton   matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        StimulusDataPanel             matlab.ui.container.Panel
        ShowstimulusplotCheckBox      matlab.ui.control.CheckBox
        ShowsignalplotCheckBox        matlab.ui.control.CheckBox
        PlotStimulusButton            matlab.ui.control.Button
        Button                        matlab.ui.control.StateButton
        RescaleEditField              matlab.ui.control.EditField
        DatafromButtonGroup           matlab.ui.container.ButtonGroup
        TriggerCheckBox               matlab.ui.control.CheckBox
        CH2Button                     matlab.ui.control.RadioButton
        CH1Button                     matlab.ui.control.RadioButton
        FunctionbeforeoperationusexasvariableLabel  matlab.ui.control.Label
        StimulusunitsEditField        matlab.ui.control.EditField
        StimulusunitsEditFieldLabel   matlab.ui.control.Label
        StimulusFilenameLabel         matlab.ui.control.Label
        LoadfromfileButton            matlab.ui.control.Button
        CodeandCommentsTextArea       matlab.ui.control.TextArea
        MetadataPanel                 matlab.ui.container.Panel
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        CommentsLabel                 matlab.ui.control.Label
        UnitsEditFieldLabel           matlab.ui.control.Label
        NameLabel                     matlab.ui.control.Label
        YcommentsEditField            matlab.ui.control.EditField
        CommentsEditField             matlab.ui.control.EditField
        YEditField                    matlab.ui.control.EditField
        UnitsEditField                matlab.ui.control.EditField
        XEditField                    matlab.ui.control.EditField
        NameEditField_2               matlab.ui.control.EditField
        REditField                    matlab.ui.control.NumericEditField
        NameEditField                 matlab.ui.control.EditField
        ProcessingModeDropDown        matlab.ui.control.DropDown
        CreateNewDatasetButton        matlab.ui.control.Button
        OpenDatasetButton             matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        Dataset1Tab                   matlab.ui.container.Tab
        addTab                        matlab.ui.container.Tab
        NameEditFieldLabel            matlab.ui.control.Label
        ProcessingModeDropDownLabel   matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        plotTable % Handle to plot of the data
        plotTemp % Handle to temporal plot
        plotForce % Handle to plot of the force sensor
        plotZero % Handle to plot of the zero reference
        areaIntegration % Handle to plot of integration region
        plotFFT
        dataFFT
        areaHthrshld
        areaVthrshld
        dataForce
        boolProcessing
        savePath
        paramsPath
        nTab
        tableSelected
        DATA % All data in the app to allow changing between tabs
        OriginExport % path to Origin file when saving all to Origin
        SaveIntegration % File name to save area calculation for all open datasets
        undoData={};
        redoData={};
        nMaxUndo=10; % Description
        memMaxUndo
        lastTable % Description
        memoryWarning=true; % Description
        MemoryWarningLoop=0; % Description
        RootFolder % Description
        appName % Description
    end
    methods (Access = public)

        function copyFnc(app,data)
            str = mat2str(data,16);
            str([1,end])=[];
            clipboard('copy',regexprep(str,{' ',';'},{'\t','\n'}));
        end

        function pasteFnc(app,event)
            str = clipboard('paste');
            if strcmp(event.Source.Type,'uitable')
                if isnumeric(event.Source.Data.Variables)

                    A = splitlines(str);

                    if numel(str2num(A{floor(numel(A)/2)}))>size(event.Source.Data.Variables,2)
                        if contains(A{floor(numel(A)/2)},',')
                            if numel(str2num(replace(A{floor(numel(A)/2)},',','.')))==size(event.Source.Data.Variables,2)
                                A = splitlines(replace(str,',','.'));
                            end
                        end
                    end
                    data=[];
                    for i=1:numel(A)
                        try
                            data(end+1,:)=str2num(A{i});
                        end
                    end
                end
                if isempty(data)
                    str = clipboard('pastespecial');
                    f=fields(str);
                    data=str.(f{1});
                end
                var={};
                for i=1:numel(event.Source.Data.Properties.VariableNames)
                    if size(data,2)>=i
                        var{i}=data(:,i);
                    else
                        var{i}=cat(1,event.Source.Data.(event.Source.Data.Properties.VariableNames{i}),zeros(size(data,2)-size(event.Source.Data.Variables,1),1));
                    end
                end
                t=table(var{:});
                event.Source.Data = renamevars(t,t.Properties.VariableNames,event.Source.Data.Properties.VariableNames);
                event.Source.CellEditCallback(app,event);
            end
        end

        function updateLabels(app)
            xlabel(app.UIAxes,sprintf('%s (%s)',app.NameEditField_2.Value,app.UnitsEditField.Value));
            ylabel(app.UIAxes,sprintf('%s (%s)',app.XEditField.Value,app.YEditField.Value));
            ylabel(app.UIAxes2,sprintf('%s (%s)',app.XEditField.Value,app.YEditField.Value));
            if strcmp(app.UnitsEditField.Value,'s')
                xlabel(app.UIAxes2,'Frequency (Hz)');
            else
                xlabel(app.UIAxes2,sprintf('%s^{-1} (%s^{-1})',app.NameEditField_2.Value,app.UnitsEditField.Value));
            end
        end

        function [xdft,t] = calculateFFT(app,event)

            if app.boolProcessing

                %ff = nufft(app.plotTable.YData,app.plotTable.XData);
                if numel(app.plotTable.XData)/numel(app.UITable.Data.X)>5
                    N = 1;
                else
                    N = 10; %Divide original sampling rate by N in an uniform way
                end

                inX=app.plotTable.XData(:);
                inY=app.plotTable.YData(:);
            else
                N = 10;


                if app.TriggerCheckBox.Value
                    inY = app.dataForce(:,2+2*~app.CH2Button.Value);
                    i = find(inY>0.4,1);
                    dt=app.dataForce(i,1+2*~app.CH2Button.Value);
                else
                    dt=0;
                end
                inX = app.dataForce(:,1+2*app.CH2Button.Value)-dt;
                inY = app.dataForce(:,2+2*app.CH2Button.Value);
                assignin('base','x',inY)
                assignin('base','t',inX)
                try
                    y=evalin('base',app.RescaleEditField.Value);
                    if numel(y)==1
                        inY=inY*y;
                    else
                        inY=y;
                    end
                catch ME
                    warndlg(sprintf('Error evaluating function before operation:\n%s',ME.message), 'Error');
                end

                bool = (inX<=max(app.plotTable.XData))&(inX>=min(app.plotTable.XData));

                inX = inX(bool);
                inY = inY(bool);
                app.plotForce.XData=inX;
                app.plotForce.YData=inY;
                yyaxis(app.UIAxes,'right');
                ax = [min(inX),max(inX),[min(inY),max(inY)]+[-.1,.1]*max(abs(inY))];
                if ax(3)==ax(4)
                    ax(3)=ax(3)-.1;
                    ax(4)=ax(4)+.1;
                end
                axis(app.UIAxes,ax);
                ylabel(app.UIAxes,app.StimulusunitsEditField.Value);
                app.BiascorrectionSpinner.Step=(ax(4)-ax(3))/50;

                app.plotForce.Visible = 'on';

                delete(app.plotTemp);
                hold(app.UIAxes,'on');
                app.plotTemp = plot(app.UIAxes,0,0,'-','Color',[1,.64,0],"LineWidth",2);
                app.UIAxes.YAxis(2).Visible='on';
                yyaxis(app.UIAxes,'left');

            end

            dt = mean(inX(2:end)-inX(1:end-1))/N;
            t = min(inX):dt:max(inX);
            app.dataFFT.t = [min(inX),dt,max(inX)];
            x = interp1(inX,inY,t);

            xdft = fft(x);
            app.dataFFT.fft = xdft;
            freq = 0:1/dt/length(x):1/dt/2;
            y = xdft(1:length(x)/2+1);
            %plot(freq,abs(xdft));
            [Y,I] = max(abs(y));
            f=freq(I);

            app.plotFFT.XData = freq;
            app.plotFFT.YData = abs(y);
            aux=0;
            auxk=0;
            for k=1:10
                aux=freq(find(cumsum(abs(y),'reverse')/sum(abs(y))>=(0.4/2^k),1,'last'));
                if aux~=0
                    auxk=k;
                    break;
                end
            end

            if aux==0
                aux=freq(end);
            end

            ax = [0,aux,0,1.1*Y];
            axis(app.UIAxes2,ax);

            app.LowSpinner.Limits = [1/(max(inX)-min(inX)),max(freq)];
            app.LowSpinner.Value=aux;
            
            if app.LowSpinner.Value>app.HighSpinner.Value
                app.areaHthrshld.XData = [app.LowSpinner.Value*[1,1],ax(2)*[1,1];[0,0],app.HighSpinner.Value*[1,1]]';
                app.areaHthrshld.YData = ax([4,3,3,4;4,3,3,4]');
            else
                app.areaHthrshld.XData = [app.LowSpinner.Value*[1,1],app.HighSpinner.Value*[1,1]];
                app.areaHthrshld.YData = ax([4,3,3,4]);
            end

            app.ThresholdSpinner_2.Value = 0;
            app.ThresholdSpinner_2.Limits = [0,max(app.plotFFT.YData)];
            app.areaVthrshld.XData = ax([1,2,2,1]) ;
            app.areaVthrshld.YData = [app.ThresholdSpinner_2.Value*[1,1],[0,0]];

            try
                if app.Button_2.Value
                    app.TimepercyclesEditField.Value = f;
                else
                    app.TimepercyclesEditField.Value = 1/f;
                end
            end
            TimepercyclesEditFieldValueChanged(app, event);
            %             y=app.plotTable.YData;
            %             Y=fft(y);
            %             f=(fftfreq(1/(t(end)-t(1)),numel(t))).';
            %             threshold=600;
            %             Y=ifftshift(abs(f)<threshold).*Y;
            %             newY=real(ifft(Y));

        end

        function updateIFFTplot(app,newVal,bool)


            if (~app.DenoiseCheckBox.Value)&&(~app.BandStopCheckBox.Value)

                if (app.BiascorrectionSpinner.Value==0)&&(~app.SmoothCheckBox.Value)
                    app.plotTemp.Visible = 'off';
                    if ~app.boolProcessing
                        app.ReplaceDataButton.Text = 'Cancel';
                        app.ReplaceDataButton.Enable = 'on';
                    end

                else
                    if app.boolProcessing
                        app.plotTemp.XData = app.plotTable.XData;
                        app.plotTemp.YData = app.plotTable.YData+app.BiascorrectionSpinner.Value;

                    else
                        app.plotTemp.XData = app.plotForce.XData;
                        app.plotTemp.YData = app.plotForce.YData+app.BiascorrectionSpinner.Value;

                    end
                    app.plotTemp.Visible = 'on';
                end
                if app.SmoothCheckBox.Value
                    app.plotTemp.Visible = 'on';
                    switch app.MethodDropDown.Value
                        case 'Adjacent averaging'
                            %app.plotTemp.YData = movmean(app.plotTemp.YData,app.NSpinner.Value);
                            app.plotTemp.YData = smooth(app.plotTemp.YData,app.NSpinner.Value,'moving');
                        case 'Savitzky-Golay'
                            
                            w=zeros(size(app.plotTemp.YData));
                            w(2:end)=diff(app.plotTemp.YData);
                            w(1:end-1)=w(1:end-1)+w(2:end);
                            %app.plotTemp.YData = sgolayfilt(app.plotTemp.YData,app.PolynomialorderSpinner.Value,app.NSpinner.Value);
                            app.plotTemp.YData = smooth(app.plotTemp.YData,app.NSpinner.Value,'sgolay',app.PolynomialorderSpinner.Value);

                        case 'Loess'
                            app.plotTemp.YData = smooth(app.plotTemp.YData,app.NSpinner.Value,'loess');

                        case 'Lowess'
                            app.plotTemp.YData = smooth(app.plotTemp.YData,app.NSpinner.Value,'lowess');

                        case 'Robust Loess'
                            app.plotTemp.YData = smooth(app.plotTemp.YData,app.NSpinner.Value,'rloess');

                        case 'Robust Lowess'
                            app.plotTemp.YData = smooth(app.plotTemp.YData,app.NSpinner.Value,'rlowess');
                    end
                end

                if ~app.boolProcessing
                    if strcmp(app.plotTemp.Visible,'off')
                        app.ReplaceDataButton.Text = 'Cancel';
                        app.ReplaceDataButton.Enable = 'on';
                    else
                        app.ReplaceDataButton.Text = 'Accept Changes';
                        app.ReplaceDataButton.Enable = 'on';
                    end
                end
                return;
            end

            if nargin == 3
                if bool
                    thrshld1 = newVal(1);
                    thrshld2 = app.ThresholdSpinner_2.Value;
                    thrshld0 = newVal(2);
                else
                    thrshld1 = app.LowSpinner.Value;
                    thrshld0 = app.HighSpinner.Value;
                    thrshld2 = newVal;
                end
            else
                thrshld1 = app.LowSpinner.Value;
                thrshld0 = app.HighSpinner.Value;
                thrshld2 = app.ThresholdSpinner_2.Value;
            end

            x = app.dataFFT.fft;
            if app.BandStopCheckBox.Value
                n=ceil(thrshld1*app.dataFFT.t(2)*length(x));
                n2=ceil(thrshld0*app.dataFFT.t(2)*length(x));
                if n2>n
                    x([n:n2,end-n2:end-n])=0;
                elseif n2==n
                    x(n:end-n)=0;
                else
                    x([1:n2,n:end-n,end-n2:end])=0;
                end

            end
            if app.DenoiseCheckBox.Value
                x(abs(x)<=thrshld2) = 0;
            end

            if app.DenoiseCheckBox.Value||app.BandStopCheckBox.Value

                app.plotTemp.XData = app.dataFFT.t(1):app.dataFFT.t(2):app.dataFFT.t(3);
                app.plotTemp.YData = real(ifft(x))+app.BiascorrectionSpinner.Value;
                app.plotTemp.Visible = 'on';
            elseif app.BiascorrectionSpinner.Value==0
                app.plotTemp.Visible = 'off';
            end

            if app.SmoothCheckBox.Value
                app.plotTemp.Visible = 'on';
                app.plotTemp.YData = movmean(app.plotTemp.YData,app.NSpinner.Value);
            end

            if ~app.boolProcessing
                if strcmp(app.plotTemp.Visible,'off')
                    app.ReplaceDataButton.Text = 'Cancel';
                    app.ReplaceDataButton.Enable = 'on';
                else
                    app.ReplaceDataButton.Text = 'Accept Changes';
                    app.ReplaceDataButton.Enable = 'on';
                end
            end

        end

        function bool = updateArea(app,slider)
            if nargin == 1
                slider = app.IntegrationregionSlider.Value;
            end
            x = app.plotTable.XData;
            if app.Button_2.Value
                timepercycle=1/app.TimepercyclesEditField.Value;
            else
                timepercycle=app.TimepercyclesEditField.Value;
            end
            dx = max(x) - min(x) - timepercycle*app.NcyclesSpinner.Value;
            bool = (x >= min(x) + dx * slider) & (x <= max(x) - dx * (1 - slider));
            app.areaIntegration.XData = app.plotTable.XData(bool);
            app.areaIntegration.YData = app.plotTable.YData(bool);
            app.areaIntegration.Visible = 'on';
        end
    end

    methods (Access = private)

        function data = updateData(app,i,del)
            if nargin == 2
                del = false;
            end
            if del
                app.DATA(i) = [];
                data = [];
            else
                data=struct('Table',app.UITable.Data,...
                    'plotX',app.plotTable.XData,...
                    'plotY',app.plotTable.YData,...
                    'dataForce',app.dataForce,...
                    'R',app.REditField.Value,...
                    'plotForceParams',{{app.StimulusFilenameLabel.Text,...
                    app.CH1Button.Value,app.TriggerCheckBox.Value,...
                    app.RescaleEditField.Value,...
                    app.OperationDropDown.Value,app.StimulusunitsEditField.Value,...
                    app.FunctionafteroperationusexasvariableEditField.Value}},...
                    'mode',app.ProcessingModeDropDown.Value,...
                    'labels',{{app.NameEditField.Value,app.CodeandCommentsTextArea.Value,...
                    app.NameEditField_2.Value,app.XEditField.Value,...
                    app.UnitsEditField.Value,app.YEditField.Value,...
                    app.CommentsEditField.Value,app.YcommentsEditField.Value}});
                app.DATA{i} = data;
            end

        end

        function [data,memoryShortageAction] = addData(app,filename)
            memoryShortageAction=true;
            if nargin == 2
                try
                    memoryShortageAction=app.checkMemory();
                    if memoryShortageAction
                        load(filename,'-mat','data');
                        default={'none',true,true,'x/22.5e-3',0,'F (N)','x + 0'};
                        if numel(data.plotForceParams)<numel(default)
                            data.plotForceParams(end:numel(default))=default(numel(data.plotForceParams):end);
                        end
                    else
                        data=addData(app);
                    end

                catch ME
                    data=addData(app);
                end
            else
                X = zeros(10,1);
                Y = zeros(10,1);

                data = struct('Table',table(X,Y),...
                    'plotX',0,...
                    'plotY',0,...
                    'dataForce',[],...
                    'R',10e6,...
                    'plotForceParams',{{'none',true,true,'x/22.5e-3',0,'F (N)','x + 0'}},...
                    'mode','V - t with R',...
                    'labels',{{'','',...
                    't','V',...
                    's','V',...
                    '',''}});

            end
        end

        function loadData(app,j)
            app.UITable.Data = app.DATA{j}.Table;
            app.plotTable.XData = app.DATA{j}.plotX;
            app.plotTable.YData = app.DATA{j}.plotY;
            app.dataForce = app.DATA{j}.dataForce;
            app.REditField.Value = app.DATA{j}.R;
            [app.StimulusFilenameLabel.Text,...
                app.CH1Button.Value,app.TriggerCheckBox.Value,...
                app.RescaleEditField.Value,...
                app.OperationDropDown.Value,...
                app.StimulusunitsEditField.Value,...
                app.FunctionafteroperationusexasvariableEditField.Value] = app.DATA{j}.plotForceParams{:};
            app.ProcessingModeDropDown.Value = app.DATA{j}.mode;
            app.ProcessingModeDropDownValueChanged;
            [app.NameEditField.Value,app.CodeandCommentsTextArea.Value,...
                app.NameEditField_2.Value,app.XEditField.Value,...
                app.UnitsEditField.Value,app.YEditField.Value,...
                app.CommentsEditField.Value,app.YcommentsEditField.Value] = app.DATA{j}.labels{:};

            app.updateAxis;
            app.updateLabels;

            pos = app.UITable.Position;
            pos(2) = app.IntegrationPanel.Position(2);
            pos(4) = app.UITable.Position(2) + pos(4) - pos(2);
            app.UITable.Position = pos;
            app.boolProcessing = false;
            app.dataFFT.t = [];
            app.dataFFT.fft = [];


            app.RemoveunsortedXpointsButton.Visible = 'off';
            app.LoadparamsButton.Visible = 'off';
            app.SaveparamsButton.Visible = 'off';
            app.Button_3.Visible = 'off';
            app.DenoisingPanel.Visible = 'off';
            app.IntegrationPanel.Visible = 'off';
            app.DataProcessingButton.Text = 'Data Processing';
            app.DataProcessingButton.Tooltip = 'Access to denoising and integration tools.';

            if size(app.dataForce,2)>=4
                app.DatafromButtonGroup.Enable = 'on';
            elseif size(app.dataForce,2)>=2
                app.CH1Button.Value = true;
                app.TriggerCheckBox.Value = false;
                app.DatafromButtonGroup.Enable = 'off';
            end

            if size(app.dataForce,2)>=2
                app.RescaleEditField.Enable = 'on';
                app.FunctionbeforeoperationusexasvariableLabel.Enable = 'on';
                app.Button.Enable = 'on';
                app.StimulusFilenameLabel.Enable = 'on';
                app.StimulusunitsEditField.Enable = 'on';
                app.StimulusunitsEditFieldLabel.Enable = 'on';
                app.PlotStimulusButton.Enable = 'on';
            else
                app.RescaleEditField.Enable = 'off';
                app.FunctionbeforeoperationusexasvariableLabel.Enable = 'off';
                app.Button.Enable = 'off';
                app.StimulusFilenameLabel.Enable = 'off';
                app.StimulusunitsEditField.Enable = 'off';
                app.StimulusunitsEditFieldLabel.Enable = 'off';
                app.PlotStimulusButton.Enable = 'off';

                app.CH1Button.Value = true;
                app.TriggerCheckBox.Value = false;
                app.DatafromButtonGroup.Enable = 'off';
            end

            if strcmp(app.StimulusFilenameLabel.Text,'none')
                app.plotForce.XData=0;
                app.plotForce.YData=0;
            end
            app.PlotStimulusButtonPushed;
            
            app.lastTable.X=app.UITable.Data.X;
            app.lastTable.Y=app.UITable.Data.Y;
        end

        function updateAxis(app)

            try
                ax = [min(app.plotTable.XData),max(app.plotTable.XData),[-1.1,1.1]*max(abs(app.plotTable.YData))];
                axis(app.UIAxes,ax);
                app.BiascorrectionSpinner.Step=(ax(4)-ax(3))/50;

            end
            try
                dT=max(app.plotTable.XData)-min(app.plotTable.XData);
                dt=max(diff(app.plotTable.XData));
                if app.Button_2.Value
                    app.TimepercyclesEditField.Limits = [1/dT,1/dt];
                else
                    app.TimepercyclesEditField.Limits = [dt,dT];
                end
            end
            app.NcyclesSpinner.Value = 1;
            app.areaIntegration.Visible = 'off';
        end

        function webWindow = getWebWindowOfUiFigure(app,fig)
            % returns the underlaying webwindow of the given figure handle in case of an uifigure, otherwise it returns an empty
            % webwindow array
            %
            %%Example
            %{
            % standard Java based Matlab figure -> no webwindow is returned
            fig = figure
            ww = getWebWindowOfUiFigure(fig);
            assert(isempty(ww))
            
            % Web based Matlab figure (uifigure) -> webwindow is returned
            figWeb = uifigure
            ww = getWebWindowOfUiFigure(figWeb);
            assert(numel(ww) == 1)
            
            % change the figures icon
            ww.Icon     % returns the current path of the icon
            % set a new icon; on Windows systems it must be an .ico file
            ww.Icon = fullfile(matlabroot, '\toolbox\matlab\icons\file_open.png')
            ww.Icon = fullfile(matlabroot, '\toolbox\matlab\icons\webicon.gif')
            % "C:\MATLAB\R2019b\toolbox\compiler\Resources\default_icon.ico"
            ww.Icon = fullfile(matlabroot, '\toolbox\matlab\toolstrip\web\image\uicontainer.ico')
    
            %}
            figNameOrig = fig.Name;                   % original figure name
            figNameTemp = [fig.Name, tempname('$')];  % creaet a unique temporary figure name (in case of multiple figures with the same name exists
            fig.Name = figNameTemp;     % rename the figure in case of multiple figures with the same name exists
            drawnow
            ww = matlab.internal.webwindowmanager.instance.findAllWebwindows();
            webWindow = matlab.internal.webwindow.empty();
            for ii = 1:numel(ww)
                if strcmp(ww(ii).Title, figNameTemp)
                    webWindow = ww(ii);
                    break;
                end
            end
            fig.Name = figNameOrig;     % restore orignal figure name
        end
        
        function results = getVariables(app)

            labels={'data',{'data','signal','signaldata','tabledata','plotdata','ans','table','plot'},[0,0];
                'R',{'r','res','resistance'},1e7;
                'Xname',{'xname','xlabel'},'t';
                'Yname',{'yname','ylabel'},'V';
                'Xunits',{'xunits'},'s';
                'Yunits',{'yunits','units'},'V';
                'dataStimulus',{'stimulus','stimulusdata','datastimulus','dataforce','force','forcedata','temperature','temperaturedata','datatemperature'},{[],[0,0],[0,0,0,0]};
                'labelStimulus',{'labelstimulus','namestimulus','unitstimulus','stimuluslabel','stimulusname','stimulusunits',...
                    'labelforce','nameforce','unitsforce','forcelabel','forcename','forceunits',...
                    'labeltemperature','nametemperature','unitstemperature','temperaturelabel','temperaturename','temperatureunits'},'F(N)';
                'channelOfStimulus',{'channelofstimulus','stimuluschannel','stimulusfrom','channelofforce','forcechannel','forcefrom',...
                    'channeloftemperature','temperaturechannel','temperaturefrom','channel'},{'CH 1','CH 2'};
                'trigger',{'trigger','sync'},false;
                'rescale',{'rescale','rescaling','functionbeforeoperation'},'x';
                'operationStimulus',{'operationstimulus','operationforce','operationtemperature','operation'},{'none','Derivation','Integration'};
                'additionalOperations',{'additionaloperations','additionalfunction','functionafteroperation','function'},'x';
                'processingMode',{'processingmode','mode'},{'V - t signal', 'I - t signal', 'V - t with R', 'I - t with R'}};

            varnames=evalin('base','who');

            for k=1:size(labels,1)
                if isa(labels{k,3},'cell')
                    bool=true;
                else
                    bool=false;
                end
                results.(labels{k,1})=[];
                for q=1:numel(labels{k,2})
                    ind = find(strcmpi(labels{k,2}{q},varnames),1);
                    if isempty(ind)
                        continue;
                    end
                    varData=evalin('base',varnames{ind});
                    BOOL=0;
                    if bool
                        if isa(labels{k,3}{1},'char')&&isa(varData,'char')
                            aux=strcmpi(varData,labels{k,3});
                            BOOL=sum(aux,"all");
                        elseif isnumeric(labels{k,3}{1})
                            if ~isnumeric(varData)
                                try
                                    varData=str2num(varData);
                                catch
                                    continue;
                                end
                            end
                            sizes=zeros(size(labels{k,3}));
                            for j=1:numel(sizes)
                                sizes(j)=size(labels{k,3}{j},2);
                            end
                            aux=(size(varData,2)==sizes);
                            BOOL=sum(aux,"all");
                            if ~BOOL
                                varData=varData.';
                                aux=(size(varData,2)==sizes);
                                BOOL=sum(aux,"all");
                            end
                        end
                    else
                        if isnumeric(labels{k,3})
                            if ~isnumeric(varData)
                                try
                                    varData=str2num(varData);
                                catch
                                    continue;
                                end
                            end
                            BOOL=(size(varData,2)==size(labels{k,3},2));
                            if ~BOOL
                                varData=varData.';
                                BOOL=(size(varData,2)==size(labels{k,3},2));
                            end
                        elseif isa(varData,class(labels{k,3}))
                            BOOL=1;
                        elseif isa(varData,'string')&&isa(labels{k,3},'char')
                            BOOL=1;
                            varData=char(varData);
                        end
                    end
                    if BOOL
                        results.(labels{k,1})=varData;
                        break;
                    end
                end
            end
                        
        end
        
        function undoSizeControl(app)
            [~,mem]=memory;
            while numel(app.undoData)>app.nMaxUndo||(~isempty(app.undoData)&&(mem.PhysicalMemory.Available/mem.PhysicalMemory.Total)<0.1)
                app.undoData(1)=[];
            end

            if isempty(app.undoData)
                app.UndoMenu.Enable='off';
            else
                app.UndoMenu.Enable='on';
            end
        end
        
        function checkTableChanged(app)

            if ~(isequal(app.lastTable.X,app.UITable.Data.X)&&isequal(app.lastTable.Y,app.UITable.Data.Y))&&~(isempty(app.lastTable.X)||isempty(app.lastTable.Y))
                app.redoData={};
                app.undoData{end+1}=struct('type','table','X',app.lastTable.X,'Y',app.lastTable.Y,'XData',app.plotTable.XData,'YData',app.plotTable.YData,'dataset',app.TabGroup.SelectedTab.Title);
                app.undoSizeControl;
                app.lastTable.X=app.UITable.Data.X;
                app.lastTable.Y=app.UITable.Data.Y;
            end

            
        end
        
        function [A,B]=UnReDo(app,A,B)
            t=findall(app.TabGroup,'Title',A{end}.dataset);
            if ~isempty(t)
                if t~=app.TabGroup.SelectedTab
                    i = find(app.TabGroup.Children == t);
                    app.TabGroup.SelectedTab=t;
                    app.loadData(i);
                end

                switch A{end}.type
                    case 'plotTable'
                        B{end+1}=struct('type','plotTable','XData',app.plotTable.XData,'YData',app.plotTable.YData,'dataset',app.TabGroup.SelectedTab.Title);
                        app.plotTable.XData=A{end}.XData;
                        app.plotTable.YData=A{end}.YData;
                        A(end)=[];

                        app.plotTemp.Visible = 'off';
                        app.BiascorrectionSpinner.Value=0;
                        app.updateAxis;
                        app.FFTButton.Value = false;
                        app.FFTButtonValueChanged;
                        updateArea(app);

                    case 'table'
                        B{end+1}=struct('type','table','X',app.UITable.Data.X,'Y',app.UITable.Data.Y,'XData',app.plotTable.XData,'YData',app.plotTable.YData,'dataset',app.TabGroup.SelectedTab.Title);
                        app.plotTable.XData=A{end}.XData;
                        app.plotTable.YData=A{end}.YData;
                        X = A{end}.X;
                        Y = A{end}.Y;
                        app.UITable.Data = table(X,Y);
                        app.lastTable.X=A{end}.X;
                        app.lastTable.Y=A{end}.Y;
                        A(end)=[];

                        app.plotZero.XData = [min(app.plotTable.XData),max(app.plotTable.XData)];
                        app.plotZero.YData = [0,0];
                        yyaxis(app.UIAxes,'left');
                        app.updateAxis;


                    case 'plotForce'
                        B{end+1}=struct('type','plotForce','XData',app.plotForce.XData,'YData',app.plotForce.YData,'dataset',app.TabGroup.SelectedTab.Title);
                        app.plotForce.XData=A{end}.XData;
                        app.plotForce.YData=A{end}.YData;
                        A(end)=[];

                        if numel(app.plotForce.XData)>1
                            yyaxis(app.UIAxes,'right');
                            ax = [min(app.plotForce.XData),max(app.plotForce.XData),[min(app.plotForce.YData),max(app.plotForce.YData)]+[-.1,.1]*max(abs(app.plotForce.YData))];
                            if ax(3)==ax(4)
                                ax(3)=ax(3)-.1;
                                ax(4)=ax(4)+.1;
                            end
                            axis(app.UIAxes,ax);
                            ylabel(app.UIAxes,app.StimulusunitsEditField.Value);

                            app.plotForce.Visible = 'on';
                            app.UIAxes.YAxis(2).Visible='on';
                            yyaxis(app.UIAxes,'left');

                        else
                            yyaxis(app.UIAxes,'right');
                            app.plotForce.Visible = 'off';
                            app.UIAxes.YAxis(2).Visible='off';
                            yyaxis(app.UIAxes,'left');

                        end
                end
            else
                A(end)=[];
                [A,B]=app.UnReDo(A,B);
            end
        end
        
        function fadeMoreTab(app)
            if app.Button_3.Value
                app.MorePanel.Visible="off";
                app.Button_3.Text='+';
                app.Button_3.Value=false;
            end
            app.SmoothoptionsPanel.Visible='off';
        end
        
        function SmoothOptions(app,method)
            oldPos=app.NSpinner.Position(2);
            switch method
                case 'Adjacent averaging'
                    app.NSpinner.Tooltip='Number of points to be averaged.';
                    app.NSpinner.Limits=[2,inf];
                    app.NSpinner.Step=1;
                    app.PolynomialorderSpinner.Visible='off';
                    app.PolynomialorderSpinnerLabel.Visible='off';
                    newPos=app.PolynomialorderSpinner.Position(2);
                    app.NSpinner.Position(2)=newPos;
                    app.NSpinnerLabel.Position(2)=newPos;
                case 'Savitzky-Golay'
                    app.NSpinner.Tooltip='Number of points of the frame.\nIt must be an odd integer.';
                    app.NSpinner.Value=round((app.NSpinner.Value-1)/2)*2+1;
                    app.NSpinner.Limits=[3,inf];
                    app.NSpinner.Step=2;
                    app.PolynomialorderSpinner.Limits=[0,app.NSpinner.Value-1];
                    app.PolynomialorderSpinner.Visible='on';
                    app.PolynomialorderSpinnerLabel.Visible='on';
                    app.PolynomialorderSpinner.Tooltip='Number of points of the frame.\nIt must be an odd integer.';
                    newPos=app.PolynomialorderSpinner.Position(2)+app.NSpinner.Position(4)*1.5;
                    app.NSpinner.Position(2)=newPos;
                    app.NSpinnerLabel.Position(2)=newPos;

                case {'Loess','Lowess','Robust Loess','Robust Lowess'}
                    app.NSpinner.Tooltip='Number of points of the frame.';
                    app.NSpinner.Limits=[1,inf];
                    app.NSpinner.Step=1;
                    app.PolynomialorderSpinner.Visible='off';
                    app.PolynomialorderSpinnerLabel.Visible='off';
                    newPos=app.PolynomialorderSpinner.Position(2);
                    app.NSpinner.Position(2)=newPos;
                    app.NSpinnerLabel.Position(2)=newPos;
            end
            app.SmoothoptionsPanel.Position(2)=app.SmoothoptionsPanel.Position(2)-newPos+oldPos;
            app.SmoothoptionsPanel.Position(4)=app.SmoothoptionsPanel.Position(4)+newPos-oldPos;
        end
        
        function bool = checkMemory(app,requirement)
            if ~exist('requirement','var')
                requirement=0;
            end
            bool=true;
            if app.memoryWarning&&app.MemoryWarningLoop==0
                [~,mem]=memory;
                if ((mem.PhysicalMemory.Available-requirement)/mem.PhysicalMemory.Total)<0.05
                    answer=uiconfirm(app.NanoDataLyzerUIFigure,'More than 95% of the memory will be used. Proceed anyway?','Memory shortage','Options',{'Cancel','Continue anyway','Continue and never ask again' },'Icon','warning');
                    switch answer
                        case 'Cancel'
                            bool=false;
                            app.MemoryWarningLoop=-1;
                        case 'Continue anyway'
                            bool=true;
                            app.MemoryWarningLoop=1;
                        case 'Continue and never ask again'
                            bool=true;
                            app.memoryWarning=false;
                            app.MemoryWarningLoop=1;
                    end
                end
            elseif app.MemoryWarningLoop<0
                bool=false;
            elseif app.MemoryWarningLoop>0
                bool=true;
            end
        end
        
        function results = fileAssociation(app,appPath)

            if ~ispref('NanoDataLyzer')
                addpref('NanoDataLyzer','Extension',0);
            else
                if ~ispref('NanoDataLyzer','Extension')
                    setpref('NanoDataLyzer','Extension',0);
                end
            end

            if getpref('NanoDataLyzer','Extension')
                return;
            end

            % Comprobar si la extensión .ndl está asociada con la aplicación
            [status, cmdout] = system('assoc .ndl');
            if status == 0
                disp(['Extensión .ndl already associated to ', cmdout]);
            else
                % Preguntar al usuario si quiere asociar la extensión .ndl
                choice = uiconfirm(app.NanoDataLyzerUIFigure, sprintf('%s\n%s','Would you like to associate .ndl extension to NanoDataLyzer?',fullfile(app.RootFolder, 'Settings', 'ConfigInfo.json')), 'Associate Extension', ...
                    'Options', {'Associate', 'Decline','Decline and never ask again'}, ...
                    'DefaultOption', 1, 'CancelOption', 2);
                % choice = questdlg(sprintf('%s\n%s','Would you like to associate .ndl extension to NanoDataLyzer?',directory), 'Associate Extension', ...
                %                    'Associate', 'Decline', 'Associate');


                if strcmp(choice, 'Associate')
                    
                    [~,tempPath]=system('echo %TEMP%');
                    tempPath(double(tempPath)==10)='';
                    batFile = fullfile(tempPath,'associateExtension.bat');
                    fid = fopen(batFile, 'w');


                    str='@echo off'; fprintf(fid, '%s\n',str);
                    str=':: BatchGotAdmin'; fprintf(fid, '%s\n',str);
                    str=':-------------------------------------'; fprintf(fid, '%s\n',str);
                    str='REM  --> Check for permissions'; fprintf(fid, '%s\n',str);
                    str='>nul 2>&1 "%SYSTEMROOT%\system32\cacls.exe" "%SYSTEMROOT%\system32\config\system"'; fprintf(fid, '%s\n',str);
                    str=''; fprintf(fid, '%s\n',str);
                    str='REM --> If error flag set, we do not have admin.'; fprintf(fid, '%s\n',str);
                    str='if ''%errorlevel%'' NEQ ''0'' ('; fprintf(fid, '%s\n',str);
                    str='    echo Waiting for admin privileges...'; fprintf(fid, '%s\n',str);
                    str='    goto UACPrompt'; fprintf(fid, '%s\n',str);
                    str=') else ( goto gotAdmin )'; fprintf(fid, '%s\n',str);
                    str=''; fprintf(fid, '%s\n',str);
                    str=':UACPrompt'; fprintf(fid, '%s\n',str);
                    str='    echo Set UAC = CreateObject^("Shell.Application"^) > "%temp%\getadmin.vbs"'; fprintf(fid, '%s\n',str);
                    str='    set params=%*'; fprintf(fid, '%s\n',str);
                    str='    echo UAC.ShellExecute "cmd.exe", "/c ""%~s0"" %params%", "", "runas", 1 >> "%temp%\getadmin.vbs"'; fprintf(fid, '%s\n',str);
                    str=''; fprintf(fid, '%s\n',str);
                    str='    "%temp%\getadmin.vbs"'; fprintf(fid, '%s\n',str);
                    str='    del "%temp%\getadmin.vbs"'; fprintf(fid, '%s\n',str);
                    str='    exit /B'; fprintf(fid, '%s\n',str);
                    str=''; fprintf(fid, '%s\n',str);
                    str=':gotAdmin'; fprintf(fid, '%s\n',str);
                    str='    pushd "%CD%"'; fprintf(fid, '%s\n',str);
                    str='    CD /D "%~dp0"'; fprintf(fid, '%s\n',str);
                    str=':--------------------------------------'; fprintf(fid, '%s\n',str);
                    str=''; fprintf(fid, '%s\n',str);
                    str='REM Asociate the extension .ndl to NanoDataLyzer application'; fprintf(fid, '%s\n',str);
                    str='assoc .ndl=NanoDataLyzer'; fprintf(fid, '%s\n',str);
                    str=appPath; fprintf(fid, 'ftype NanoDataLyzer="%s" "%%%%1"\n',str);
                    str='set PATHEXT=.ndl;%PATHEXT%'; fprintf(fid, '%s\n',str);
                    str=''; fprintf(fid, '%s\n',str);
                    str='exit /B'; fprintf(fid, '%s\n',str);

                    fclose(fid);

                    system(batFile);
                    %delete(batFile);

                elseif strcmp(choice,'Decline and never ask again')
                    setpref('NanoDataLyzer','Extension',1);
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            startDir=[cd,filesep];
            app.paramsPath=startDir;

            X = zeros(10,1);
            Y = zeros(10,1);
            app.UITable.Data = table(X,Y);
            app.lastTable.X=[];
            app.lastTable.Y=[];

            yyaxis(app.UIAxes,'right');
            app.plotForce = plot(app.UIAxes,0,0);
            ylabel(app.UIAxes,'F (N)');
            app.plotForce.Visible = 'off';
            app.UIAxes.YAxis(2).Visible='off';

            yyaxis(app.UIAxes,'left');
            hold(app.UIAxes,'on');
            app.plotZero = plot(app.UIAxes,0,0,'k-');
            app.plotTable = plot(app.UIAxes,0,0,'-');
            app.plotTemp = plot(app.UIAxes,0,0,'-','Color',[1,.64,0],"LineWidth",2);
            app.plotTemp.Visible = 'off';
            app.areaIntegration = area(app.UIAxes,0,0,'LineStyle',':','FaceColor','y','EdgeColor',[.75,.5,0]);
            app.areaIntegration.Visible = 'off';

            app.plotFFT =  plot(app.UIAxes2,0,0,'b-');
            hold(app.UIAxes2,'on');
            app.areaHthrshld = fill(app.UIAxes2,0,0,[0.8,0.8,0.8],'LineStyle',':','FaceAlpha',0.5,'EdgeColor','r');
            app.areaHthrshld.Visible = 'off';
            app.areaVthrshld = fill(app.UIAxes2,0,0,[0.8,0.8,0.8],'LineStyle',':','FaceAlpha',0.5,'EdgeColor','r');
            app.areaVthrshld.Visible = 'off';
            app.UIAxes2.Visible = 'off';


            pos = app.UITable.Position;
            pos(2) = app.IntegrationPanel.Position(2);
            pos(4) = app.UITable.Position(2) + pos(4) - pos(2);
            app.UITable.Position = pos;
            app.boolProcessing = false;
            app.dataFFT.t = [];
            app.dataFFT.fft = [];
            app.nTab = 1;
            app.tableSelected = [];

            app.DATA = {};
            app.OriginExport='';

            app.CloseDatasetMenuSelected;

            if ~isempty(varargin)
                evnt=struct('Source','startup','EventName','startupOpenDataset','Value',{varargin});
                app.OpenDatasetMenuSelected(evnt);
            end

            app.appName = 'NanoDataLyzer';

            % PATH SEARCH
            Flag = 1;
            if isdeployed
                [~, result]    = system('path');
                app.RootFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                if ~isfile(fullfile(app.RootFolder, sprintf('%s.exe', app.appName)))
                    Flag = 0;
                end
            else
                Flag = -1;
                prjPath = matlab.project.rootProject;
                appPath = fullfile(char(com.mathworks.appmanagement.MlappinstallUtil.getAppInstallationFolder), app.appName);

                if ~isempty(prjPath) & strcmp(prjPath.Name, app.appName)
                    app.RootFolder = char(prjPath.RootFolder);
                elseif isfolder(appPath)
                    app.RootFolder = appPath;
                else
                    Flag = 0;
                end
            end
            % INITIALIZATION
            if Flag==1
                app.fileAssociation([app.RootFolder,filesep,sprintf('%s.exe', app.appName)]);
            else
                % Error message...
            end


        end

        % Value changed function: ProcessingModeDropDown
        function ProcessingModeDropDownValueChanged(app, event)
            value = app.ProcessingModeDropDown.Value;
            switch value
                case 'V - t signal'
                    app.XEditField.Value = 'V';
                    app.YEditField.Value = 'V';

                    app.RLabel.Enable = 'off';
                    app.REditField.Enable = 'off';
                    app.REditField.Value = inf;
                    % app.StimulusDataPanel.Visible = 'off';
                    % % app.LoadfromfileButton.Visible = 'off';
                    % % app.StimulusFilenameLabel.Visible = 'off';
                    % % app.DatafromButtonGroup.Visible = 'off';
                    % % app.OperationDropDownLabel.Visible = 'off';
                    % % app.OperationDropDown.Visible = 'off';
                    % % app.FunctionbeforeoperationusexasvariableLabel.Visible = "off";
                    % % app.RescaleEditField.Visible = "off";
                    % % app.FunctionLabel.Visible = "off";
                    % % app.FunctionafteroperationusexasvariableEditField.Visible = "off";
                    % % app.StimulusunitsEditFieldLabel.Visible = 'off';
                    % % app.StimulusunitsEditField.Visible = 'off';
                    % % app.PlotStimulusButton.Visible = 'off';
                    % app.plotForce.Visible = 'off';
                    % app.UIAxes.YAxis(2).Visible='off';
                case 'I - t signal'
                    app.XEditField.Value = 'I';
                    app.YEditField.Value = 'A';

                    app.RLabel.Enable = 'off';
                    app.REditField.Enable = 'off';
                    app.REditField.Value = 0;
                    % app.StimulusDataPanel.Visible = 'off';
                    % % app.LoadfromfileButton.Visible = 'off';
                    % % app.StimulusFilenameLabel.Visible = 'off';
                    % % app.DatafromButtonGroup.Visible = 'off';
                    % % app.OperationDropDownLabel.Visible = 'off';
                    % % app.OperationDropDown.Visible = 'off';
                    % % app.FunctionbeforeoperationusexasvariableLabel.Visible = "off";
                    % % app.RescaleEditField.Visible = "off";
                    % % app.FunctionLabel.Visible = "off";
                    % % app.FunctionafteroperationusexasvariableEditField.Visible = "off";
                    % % app.StimulusunitsEditFieldLabel.Visible = 'off';
                    % % app.StimulusunitsEditField.Visible = 'off';
                    % % app.PlotStimulusButton.Visible = 'off';
                    % app.plotForce.Visible = 'off';
                    % app.UIAxes.YAxis(2).Visible='off';
                case 'V - t with R'
                    app.XEditField.Value = 'V';
                    app.YEditField.Value = 'V';

                    app.RLabel.Enable = 'on';
                    app.REditField.Enable = 'on';
                    % app.StimulusDataPanel.Visible = 'on';
                    % % app.LoadfromfileButton.Visible = 'on';
                    % % app.StimulusFilenameLabel.Visible = 'on';
                    % % app.DatafromButtonGroup.Visible = 'on';
                    % % app.OperationDropDownLabel.Visible = 'on';
                    % % app.OperationDropDown.Visible = 'on';
                    % % app.FunctionbeforeoperationusexasvariableLabel.Visible = "on";
                    % % app.RescaleEditField.Visible = "on";
                    % % app.FunctionLabel.Visible = "on";
                    % % app.FunctionafteroperationusexasvariableEditField.Visible = "on";
                    % % app.StimulusunitsEditFieldLabel.Visible = 'on';
                    % % app.StimulusunitsEditField.Visible = 'on';
                    % % app.PlotStimulusButton.Visible = 'on';
                    % app.plotForce.Visible = 'on';
                    % app.UIAxes.YAxis(2).Visible='on';

                case 'I - t with R'
                    app.XEditField.Value = 'I';
                    app.YEditField.Value = 'A';

                    app.RLabel.Enable = 'on';
                    app.REditField.Enable = 'on';
                    % app.StimulusDataPanel.Visible = 'on';
                    % % app.LoadfromfileButton.Visible = 'on';
                    % % app.StimulusFilenameLabel.Visible = 'on';
                    % % app.DatafromButtonGroup.Visible = 'on';
                    % % app.OperationDropDownLabel.Visible = 'on';
                    % % app.OperationDropDown.Visible = 'on';
                    % % app.FunctionbeforeoperationusexasvariableLabel.Visible = "on";
                    % % app.RescaleEditField.Visible = "on";
                    % % app.FunctionLabel.Visible = "on";
                    % % app.FunctionafteroperationusexasvariableEditField.Visible = "on";
                    % % app.StimulusunitsEditFieldLabel.Visible = 'on';
                    % % app.StimulusunitsEditField.Visible = 'on';
                    % % app.PlotStimulusButton.Visible = 'on';
                    % app.plotForce.Visible = 'on';
                    % app.UIAxes.YAxis(2).Visible='on';
            end
            updateLabels(app);
        end

        % Callback function: ResetPlottoTableDataMenu, UITable
        function UITableCellEdit(app, event)
            
            app.fadeMoreTab;
            
            if ~(strcmp(event.EventName,'KeyPress')||strcmp(event.EventName,'Action')||strcmp(event.EventName,'ButtonPushed')||strcmp(event.EventName,'MenuSelected')||strcmp(event.EventName,'startupOpenDataset'))

                indices = event.Indices;
                newData = event.NewData;
            end
            
            app.checkTableChanged;
            app.plotTable.XData = app.UITable.Data.X;
            app.plotTable.YData = app.UITable.Data.Y;
            app.plotZero.XData = [min(app.plotTable.XData),max(app.plotTable.XData)];
            app.plotZero.YData = [0,0];
            yyaxis(app.UIAxes,'left');
            app.updateAxis;
        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            key = event.Key;

            if isequal(event.Modifier,{'control'})
                switch key
                    case 'v'
                        pasteFnc(app,event)
                end
            else
                switch key
                    case 'delete'
                        i = app.tableSelected;
                        if ~isempty(i)
                            iX = i(i(:,2) == 1,1);
                            iY = i(i(:,2) == 2,1);
                            if isequal(iX,iY)
                                app.UITable.Data(iX,:) = [];
                                app.tableSelected = [];

                            else
                                app.UITable.Data.X(iX) = 0;
                                app.UITable.Data.Y(iY) = 0;
                            end
                            app.UITable.CellEditCallback(app,event);
                        end

                    case 'backspace'
                        i = app.tableSelected;
                        if ~isempty(i)
                            iX = i(i(:,2) == 1,1);
                            iY = i(i(:,2) == 2,1);
                            app.UITable.Data.X(iX) = 0;
                            app.UITable.Data.Y(iY) = 0;
                            app.UITable.CellEditCallback(app,event);
                        end
                end
            end
            app.checkTableChanged;
            
        end

        % Key release function: UITable
        function UITableKeyRelease(app, event)

        end

        % Button pushed function: LoadfromfileButton
        function LoadfromfileButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uigetfile( ...
                {'*.txt;*.csv;*.dat;*.asc;*.m', 'All ASCII Files (*.txt, *.csv, *.dat, *.asc, *.m)';
                '*.csv', 'CSV from Tektronix oscilloscope (*.csv)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Data File',[app.savePath,'SensorData.csv']);

            delete(f); %delete the dummy figure
            if filterindex==0
                return;
            end

            app.savePath = pathname;
            app.StimulusFilenameLabel.Text=filename;
            [~, app.NameEditField.Value, ~]= fileparts(filename);

            if filterindex==2
                data=importdata([pathname,filename],',',18);
                if size(data.data,2)==11
                    Data=data.data(:,[4,5,10,11]);
                elseif size(data.data,2)==5
                    Data=data.data(:,[4,5]);
                else
                    Data=data.data;
                end
            else
                data=uiimport([pathname,filename]);
                if isempty(data)
                    return;
                end
                if isfield(data,'data')
                    Data=data.data;
                elseif numel(fieldnames(data))==1
                    field=fieldnames(data);
                    Data=data.(field{1});
                else
                    uialert(app.NanoDataLyzerUIFigure,'Import the data with ''data'' name', 'Import Data Error','Icon','error');
                    return;
                end
            end

            if size(Data,2)>=4
                app.DatafromButtonGroup.Enable = 'on';
                app.dataForce = Data(:,1:4);
            elseif size(Data,2)>=2
                app.CH1Button.Value = true;
                app.TriggerCheckBox.Value = false;
                app.DatafromButtonGroup.Enable = 'off';
                app.dataForce = Data(:,1:2);
            end

            if size(Data,2)>=2
                app.RescaleEditField.Enable = 'on';
                app.FunctionbeforeoperationusexasvariableLabel.Enable = 'on';
                app.Button.Enable = 'on';
                app.StimulusFilenameLabel.Enable = 'on';
                app.StimulusunitsEditField.Enable = 'on';
                app.StimulusunitsEditFieldLabel.Enable = 'on';
                app.PlotStimulusButton.Enable = 'on';
            end


        end

        % Value changed function: NameEditField_2
        function NameEditField_2ValueChanged(app, event)
            updateLabels(app);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            updateLabels(app);
        end

        % Value changed function: UnitsEditField
        function UnitsEditFieldValueChanged(app, event)
            updateLabels(app);
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            updateLabels(app);
        end

        % Button pushed function: PlotStimulusButton
        function PlotStimulusButtonPushed(app, event)
            if ~isempty(app.dataForce)

                if app.OperationDropDown.Value==3
                    Y = app.plotForce.YData;
                    X = app.plotForce.XData;

                else

                    if app.TriggerCheckBox.Value
                        Y = app.dataForce(:,2+2*~app.CH2Button.Value);
                        i = find(Y>0.4,1);
                        dt=app.dataForce(i,1+2*~app.CH2Button.Value);
                    else
                        dt=0;
                    end
                    X = app.dataForce(:,1+2*app.CH2Button.Value)-dt;
                    Y = app.dataForce(:,2+2*app.CH2Button.Value);
                    assignin('base','x',Y)
                    assignin('base','t',X)
                    try
                        y=evalin('base',app.RescaleEditField.Value);
                        if numel(y)==1
                            Y=Y*y;
                        else
                            Y=y;
                        end
                    catch ME
                        warndlg(sprintf('Error evaluating function before operation:\n%s',ME.message), 'Error');
                    end

                    if app.OperationDropDown.Value==1
                        Y=gradient(Y)./gradient(X);
                        %Y=(Y(3:end)-Y(1:end-2))./((X(3:end)-X(1:end-2)));
                        %X([1,end])=[];
                    elseif app.OperationDropDown.Value==2
                        Y=cumtrapz(X,Y);
                    end
                end

                assignin('base','x',Y)
                assignin('base','t',X)
                try
                    y=evalin('base',app.FunctionafteroperationusexasvariableEditField.Value);
                    if numel(y)==1
                        Y=Y*y;
                    else
                        Y=y;
                    end
                catch ME
                    warndlg(sprintf('Error evaluating function after operation:\n%s',ME.message), 'Error');
                end
                

                bool = (X<=max(app.plotTable.XData))&(X>=min(app.plotTable.XData));

                if isempty(app.undoData)||~(isequal(app.undoData{end},struct('type','plotForce','XData',app.plotForce.XData,'YData',app.plotForce.YData,'dataset',app.TabGroup.SelectedTab.Title))||(isequaln(X(bool),app.plotForce.XData(:))&&isequaln(Y(bool),app.plotForce.YData(:))))
                    app.redoData={};
                    app.undoData{end+1}=struct('type','plotForce','XData',app.plotForce.XData,'YData',app.plotForce.YData,'dataset',app.TabGroup.SelectedTab.Title);
                    app.undoSizeControl;
                end

                app.plotForce.XData = X(bool);
                app.plotForce.YData = Y(bool);
                yyaxis(app.UIAxes,'right');
                ax = [min(X(bool)),max(X(bool)),[min(Y(bool)),max(Y(bool))]+[-.1,.1]*max(abs(Y(bool)))];
                if ax(3)==ax(4)
                    ax(3)=ax(3)-.1;
                    ax(4)=ax(4)+.1;
                end
                axis(app.UIAxes,ax);
                ylabel(app.UIAxes,app.StimulusunitsEditField.Value);
                
                app.ShowsignalplotCheckBox.Enable='on';
                app.ShowstimulusplotCheckBox.Enable='on';
                app.ShowsignalplotCheckBoxValueChanged;
                app.ShowstimulusplotCheckBoxValueChanged;
                
            else
                app.ShowsignalplotCheckBox.Enable='off';
                app.ShowstimulusplotCheckBox.Enable='off';
                yyaxis(app.UIAxes,'right');
                app.plotForce.Visible = 'off';
                app.UIAxes.YAxis(2).Visible='off';
                yyaxis(app.UIAxes,'left');
                app.plotTable.Visible = 'on';

            end
        end

        % Button pushed function: SaveTableDataButton
        function SaveTableDataButtonPushed(app, event)
            name = app.NameEditField.Value;
            if isempty(name)
                name = 'data';
            end
            file = [app.savePath,name,'.txt'];
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uiputfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uiputfile( ...
                {'*.txt;*.csv;*.dat;*.asc;*.m', 'All ASCII Files (*.txt, *.csv, *.dat, *.asc, *.m)';
                '*.*',  'All Files (*.*)'}, ...
                'Save as',file);

            delete(f); %delete the dummy figure
            if filterindex==0
                return;
            end

            app.savePath = pathname;
            fid = fopen([pathname,filename],'w');
            for i=1:size(app.UITable.Data.Variables,1)
                fprintf(fid,'%e\t%e\n',app.UITable.Data.X(i),app.UITable.Data.Y(i));
            end
            fclose(fid);
        end

        % Button pushed function: DataProcessingButton
        function DataProcessingButtonPushed(app, event)
            if ~app.boolProcessing
                pos = app.UITable.Position;
                pos(2) = app.RemoveunsortedXpointsButton.Position(2)+1.5*app.RemoveunsortedXpointsButton.Position(4);
                pos(4) = app.UITable.Position(2) + pos(4) - pos(2);
                app.UITable.Position = pos;
                app.ReplaceDataButton.Visible = 'on';
                app.RemoveunsortedXpointsButton.Visible = 'on';
                app.LoadparamsButton.Visible = 'on';
                app.SaveparamsButton.Visible = 'on';
                app.Button_3.Visible = 'on';
                app.DenoisingPanel.Visible = 'on';
                app.ReplaceDataButton.Enable = 'off';
                app.BiascorrectionSpinner.Value = 0;
                app.IntegrationPanel.Visible = 'on';
                app.boolProcessing = true;
                app.DataProcessingButton.Text = 'Save Plot Data';
                app.DataProcessingButton.Tooltip = 'Save processed data.';
                focus(app.UITable);
            else
                if isempty(app.OriginExport)
                    name = app.NameEditField.Value;
                    if isempty(name)
                        name = 'data';
                    end
                    name = [name,'Processed'];
                    file = [app.savePath,name,'.txt'];
                    f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uiputfile doesn't minimize our GUI
                    [filename, pathname, filterindex] = uiputfile( ...
                        {'*.txt;*.csv;*.dat;*.asc;*.m', 'All ASCII Files (*.txt, *.csv, *.dat, *.asc, *.m)';
                        '*.opj;*.opju','Origin Projects (*.opj,*.opju)';
                        '*.mat','Matlab Binary file (*.mat)';
                        '*.*',  'All Files (*.*)'}, ...
                        'Save as',file);

                    delete(f); %delete the dummy figure
                    if filterindex==0
                        return;
                    end

                    app.savePath = pathname;
                else
                    filterindex=2;
                    pathname=app.OriginExport{1};
                    filename='';
                end


                if filterindex==2

                    if isempty(app.OriginExport)
                        choice = questdlg(sprintf('%s\n\n%s%s\n',...
                            'Any currently open Origin Projects will be saved and closed in order to continue.',...
                            'If an open Origin project does not have a filepath specified already the project will be saved ',...
                            'to a new file in your Origin User Files directory, entitled ''UNTITLED.opj'''),...
                            'Warning!',...
                            'OK','Cancel','OK');

                        if strcmpi(choice,'Cancel') % If user pressed Cancel, return
                            return
                        end

                    end

                    % Open waitbar, opening Origin takes a while
                    hWaitbar = waitbar(0,'Opening Origin. Please wait...','Name','ExportOrigin');

                    % Obtain Origin COM Server object
                    % This will connect to an existing instance of Origin, or create a new one if none exist
                    try originObj = actxserver('Origin.ApplicationSI');
                        originObj.Visible=1;
                    catch err
                        if strcmpi(err.identifier,'MATLAB:COM:InvalidProgid') % If an Origin server object was not found on the computer
                            msgbox('Sorry, OriginPro must be installed on your computer.','ExportOrigin')
                        end
                        if nargout>0
                            data = []; % Reset data
                        end
                        try delete(hWaitbar), end  % Delete waitbar
                        return
                    end

                    try delete(hWaitbar), end  % Delete waitbar
                    % Save and close existing projects
                    invoke(originObj, 'Execute', 'Save');
                    invoke(originObj, 'IsModified', 'false');

                    %                 % Make the Origin session visible
                    %                 invoke(originObj, 'Execute', 'doc -mc 1;');
                    %
                    %                 % Clear "dirty" flag in Origin to suppress prompt for saving current project
                    %                 % You may want to replace this with code to handling of saving current project
                    %                 invoke(originObj, 'IsModified', 'false');

                    if exist([pathname,filename], 'file') == 2
                        invoke(originObj, 'Load', [pathname,filename]);
                    end

                    % Create a workbook
                    strBook = invoke(originObj, 'CreatePage', 2, '', 'Origin');

                    % Find the worksheet
                    wks = invoke(originObj, 'FindWorksheet', strBook);

                    % Get the workbook
                    wkb = wks.Parent;

                    % Rename the workbook
                    invoke(wkb, 'LongName', app.NameEditField.Value);

                    % Rename the worksheet
                    invoke(wks, 'Name', sprintf('R = %e Ohm',app.REditField.Value));

                    % Set the number of columns
                    if strcmp(app.plotForce.Visible,'on')
                        n=6;
                    else
                        n=4;
                    end
                    invoke(wks, 'Cols', n);

                    % Get column collection in the worksheet
                    cols = invoke(wks, 'Columns');
                    col={{};{}};
                    for k=1:(n/2)
                        % Get the columns
                        col{1}{k} = invoke(cols, 'Item', uint8(2*k-2));
                        col{2}{k} = invoke(cols, 'Item', uint8(2*k-1));

                        % Set column type
                        invoke(col{1}{k}, 'Type', 3);  % x column
                        invoke(col{2}{k}, 'Type', 0);  % y column

                        % Set data to the columns
                        switch k
                            case 1
                                data = [app.UITable.Data.X, app.UITable.Data.Y];
                                names = {['Original ',app.NameEditField_2.Value],['Original ',app.XEditField.Value]};
                                units = {app.UnitsEditField.Value,app.YEditField.Value};
                                comments = {app.CommentsEditField.Value,app.YcommentsEditField.Value};
                            case 2
                                data = [app.plotTable.XData', app.plotTable.YData'];
                                names = {app.NameEditField_2.Value,app.XEditField.Value};
                                units = {app.UnitsEditField.Value,app.YEditField.Value};
                                comments = {app.CommentsEditField.Value,app.YcommentsEditField.Value};
                            case 3
                                data = [app.plotForce.XData', app.plotForce.YData'];

                                i1=strfind(app.StimulusunitsEditField.Value,'(');
                                i2=strfind(app.StimulusunitsEditField.Value,')');
                                if isempty(i1)
                                    i1=numel(app.StimulusunitsEditField.Value)+1;
                                end
                                if isempty(i2)
                                    i2=numel(app.StimulusunitsEditField.Value)+1;
                                end
                                names = {app.NameEditField_2.Value,app.StimulusunitsEditField.Value(1:i1(1)-1)};
                                units = {app.UnitsEditField.Value,app.StimulusunitsEditField.Value(i1(1)+1:i2(1)-1)};
                                comments = {app.CommentsEditField.Value,app.StimulusFilenameLabel.Text};
                        end
                        invoke(wks, 'SetData', data,0,2*k-2);
                        for q=1:2
                            if ~isempty(names{q});invoke(col{q}{k}, 'LongName', names{q});end
                            if ~isempty(units{q});invoke(col{q}{k}, 'Units', units{q});end
                            if ~isempty(comments{q});invoke(col{q}{k}, 'Comments', comments{q});end
                        end
                    end

                    % Create a graph
                    strGraph = invoke(originObj, 'CreatePage', 3, '', 'Origin');

                    % Find the graph layer
                    gl = invoke(originObj, 'FindGraphLayer', strGraph);

                    % Get the workbook
                    fig = gl.Parent;

                    % Rename the workbook
                    invoke(fig, 'LongName', app.NameEditField.Value);

                    % Get dataplot collection from the graph layer
                    dps = invoke(gl, 'DataPlots');

                    % Create a data range
                    dr = invoke(originObj, 'NewDataRange');

                    % Add data to data range
                    invoke(dr, 'Add', 'X', wks, 0, 2, -1, 2);
                    invoke(dr, 'Add', 'Y', wks, 0, 3, -1, 3);

                    % Add data plot to graph layer
                    invoke(dps, 'Add', dr, 200);  % 200 for line, 201 for symbol, 202 for symbol+line plots

                    % Rescale the graph layer
                    invoke(gl, 'Execute', 'rescale;');


                    % Change the bottom x' title
                    invoke(gl, 'Execute', sprintf('xb.text$ = "%s";',app.NameEditField_2.Value));
                    % Change the left y's title
                    invoke(gl, 'Execute', sprintf('yl.text$ = "%s";',app.XEditField.Value));

                    %show the top and right axes
                    invoke(gl, 'Execute', 'range ll = !;');
                    invoke(gl, 'Execute', 'll.x2.showAxes=3;');
                    invoke(gl, 'Execute', 'll.y2.showAxes=3;');

                    %set the x axis scale
                    yyaxis(app.UIAxes,'left')
                    ax = axis(app.UIAxes);
                    invoke(gl, 'Execute', sprintf('ll.x.from=%f;',ax(1)));
                    invoke(gl, 'Execute', sprintf('ll.x.to=%f;',ax(2)));
                    invoke(gl, 'Execute', sprintf('ll.y.from=%f;',ax(3)));
                    invoke(gl, 'Execute', sprintf('ll.y.to=%f;',ax(4)));

                    %set the x axis Major tick increment.
                    invoke(gl, 'Execute', 'll.x.inc=10;');

                    %delete the legend
                    invoke(gl, 'Execute', 'label -r legend;');

                    if ~isfield(event,'Nleft')||event.Nleft==0
                        %Save the current project using the specified path and filename
                        invoke(originObj, 'Execute', sprintf('save -DIX %s',[pathname,filename]));
                    end

                    % Release

                    release(dr);
                    release(dps);
                    release(gl);
                    release(fig);
                    release(cols);
                    release(wks);
                    release(wkb);
                    for k=1:(n/2)
                        release(col{1}{k});
                        release(col{2}{k});
                    end

                    if isfield(event,'Nleft')&&event.Nleft>0
                        %release(originObj);
                    else
                        %Exit(originObj)
                        invoke(originObj, 'Execute', 'Exit');
                        delete(originObj);
                        %invoke(originObj, 'Execute', 'Exit');
                        %release(originObj);

                    end
                elseif filterindex==3
                    if numel(filename)<4||isvarname(filename(1:end-4))
                        varname=filename(1:end-4);
                    else
                        varname='data';
                    end
                    var=[app.plotTable.XData(:),app.plotTable.YData(:)];

                    evalin('base','clear')
                    assignin('base',varname,var)
                    evalin('base',sprintf('save(''%s'',''%s'');',[pathname,filename],varname));
                    
                    figure(app.NanoDataLyzerUIFigure);
                else
                    fid = fopen([pathname,filename],'w');

                    if strcmp(app.plotForce.Visible,'on')
                        fprintf(fid,'%s(%s)\t%s(%s)\t%s(%s)\t%s\n',app.NameEditField_2.Value,app.UnitsEditField.Value,...
                            app.XEditField.Value,app.YEditField.Value,app.NameEditField_2.Value,...
                            app.UnitsEditField.Value,app.StimulusunitsEditField.Value);
                        for k=1:max(numel(app.plotTable.XData),numel(app.plotForce.XData))
                            if k<=numel(app.plotTable.XData)

                                x1=sprintf('%e',app.plotTable.XData(k));
                                y1=sprintf('%e',app.plotTable.YData(k));
                            else
                                x1='';
                                y1='';
                            end
                            if k<=numel(app.plotForce.XData)
                                x2=sprintf('%e',app.plotForce.XData(k));
                                y2=sprintf('%e',app.plotForce.YData(k));
                            else
                                x2='';
                                y2='';
                            end
                            fprintf(fid,'%s\t%s\t%s\t%s\n',x1,y1,x2,y2);
                        end
                    else
                        fprintf(fid,'%s(%s)\t%s(%s)\n',app.NameEditField_2.Value,app.UnitsEditField.Value,...
                            app.XEditField.Value,app.YEditField.Value);
                        for k=1:numel(app.plotTable.XData)
                            fprintf(fid,'%e\t%e\n',app.plotTable.XData(k),app.plotTable.YData(k));
                        end
                    end
                    switch k
                        case 1
                            data = [app.UITable.Data.X, app.UITable.Data.Y];
                            names = {['Original ',app.NameEditField_2.Value],['Original ',app.XEditField.Value]};
                            units = {app.UnitsEditField.Value,app.YEditField.Value};
                            comments = {app.CommentsEditField.Value,app.YcommentsEditField.Value};
                        case 2
                            data = [app.plotTable.XData', app.plotTable.YData'];
                            names = {app.NameEditField_2.Value,app.XEditField.Value};
                            units = {app.UnitsEditField.Value,app.YEditField.Value};
                            comments = {app.CommentsEditField.Value,app.YcommentsEditField.Value};
                        case 3
                            data = [app.plotForce.XData', app.plotForce.YData'];
                            names = {app.NameEditField_2.Value,'Force'};
                            units = {app.UnitsEditField.Value,'N'};
                            comments = {app.CommentsEditField.Value,app.StimulusFilenameLabel.Text};
                    end


                    fclose(fid);
                    figure(app.NanoDataLyzerUIFigure);
                end
            end
        end

        % Button pushed function: RemoveunsortedXpointsButton
        function RemoveunsortedXpointsButtonPushed(app, event)
            
            app.fadeMoreTab;


            t = app.plotTable.XData;
            y = app.plotTable.YData;
            dt = mean(diff(t));
            %             bool = abs(t(2:end) - t(1:(end-1))) > 2 * dt;
            %             bool = [0;bool] & [bool;0];
            bool = abs(t(3:end) - t(1:(end-2))) > 4 * dt;
            bool = [0,0,bool] & [bool,0,0];
            t(bool) = [];
            y(bool) = [];

            bool(:)=[0,(diff(t)<=0)]|[(diff(t)<=0),0];
            t(bool) = [];
            y(bool) = [];

            app.plotTable.XData = t;app.plotTable.YData = y;

            yyaxis(app.UIAxes,'left');
            ax = [min(app.plotTable.XData),max(app.plotTable.XData),[-1.1,1.1]*max(abs(app.plotTable.YData))];
            axis(app.UIAxes,ax);
            dT=max(app.plotTable.XData)-min(app.plotTable.XData);
            dt=max(diff(app.plotTable.XData));
            if app.Button_2.Value
                app.TimepercyclesEditField.Limits = [1/dT,1/dt];
            else
                app.TimepercyclesEditField.Limits = [dt,dT];
            end
            app.NcyclesSpinner.Value = 1;
        end

        % Button pushed function: ReplaceDataButton
        function ReplaceDataButtonPushed(app, event)
            
            app.fadeMoreTab;
            
            if app.boolProcessing
                
                app.redoData={};
                app.undoData{end+1}=struct('type','plotTable','XData',app.plotTable.XData,'YData',app.plotTable.YData,'dataset',app.TabGroup.SelectedTab.Title);
                app.undoSizeControl;

                app.plotTable.XData = app.plotTemp.XData;
                app.plotTable.YData = app.plotTemp.YData;
                app.plotTemp.Visible = 'off';
                app.BiascorrectionSpinner.Value=0;
                app.updateAxis;
                app.FFTButton.Value = false;
                app.FFTButtonValueChanged(event);
                updateArea(app);
            else
                if strcmp(app.plotTemp.Visible,'on')
                    if isempty(app.undoData)||~isequal(app.undoData{end},struct('type','plotForce','XData',app.plotForce.XData,'YData',app.plotForce.YData,'dataset',app.TabGroup.SelectedTab.Title))
                        app.redoData={};
                        app.undoData{end+1}=struct('type','plotForce','XData',app.plotForce.XData,'YData',app.plotForce.YData,'dataset',app.TabGroup.SelectedTab.Title);
                        app.undoSizeControl;
                    end

                    app.plotForce.XData = app.plotTemp.XData;
                    app.plotForce.YData = app.plotTemp.YData;
                
                end

                delete(app.plotTemp);
                yyaxis(app.UIAxes,'left');
                hold(app.UIAxes,'on');
                app.plotTemp = plot(app.UIAxes,0,0,'-','Color',[1,.64,0],"LineWidth",2);
                app.plotTemp.Visible = 'off';
                app.BiascorrectionSpinner.Value=0;
                app.FFTButton.Value = false;
                app.FFTButtonValueChanged(event);
                
                app.AdditionaloperationsPanel.Visible = 'on';

                app.TabGroup.Visible = 'on';
                app.FileMenu.Enable = 'on';
                app.EditMenu.Enable = 'on';
                app.IterateforalldatasetsMenu.Enable = 'on';
                app.IntegrationPanel.Enable = 'on';
                app.DataProcessingButton.Enable = 'on';
                app.UITable.Enable = 'on';
                app.SaveTableDataButton.Enable = 'on';
                app.FFTButton.Enable = 'on';
                app.ReplaceDataButton.Visible = 'on';
                app.ReplaceDataButton.Text = 'Replace Data';
                app.ReplaceDataButton.Enable = 'off';
                app.RemoveunsortedXpointsButton.Enable = 'on';
                app.ProcessingModeDropDown.Enable = 'on';
                app.ProcessingModeDropDownLabel.Enable = 'on';

                pos = app.UITable.Position;
                pos(2) = app.IntegrationPanel.Position(2);
                pos(4) = app.UITable.Position(2) + pos(4) - pos(2);
                app.UITable.Position = pos;
                app.boolProcessing = false;

                app.RemoveunsortedXpointsButton.Visible = 'off';
                app.LoadparamsButton.Visible = 'off';
                app.SaveparamsButton.Visible = 'off';
                app.Button_3.Visible = 'off';
                app.DenoisingPanel.Visible = 'off';
                app.IntegrationPanel.Visible = 'off';
                app.DataProcessingButton.Text = 'Data Processing';
                app.DataProcessingButton.Tooltip = 'Access to denoising and integration tools.';

            end


        end

        % Value changed function: TimepercyclesEditField
        function TimepercyclesEditFieldValueChanged(app, event)
            value = app.TimepercyclesEditField.Value;
            if app.Button_2.Value
                value=1/value;
            end
            spinnerLimit = floor((max(app.plotTable.XData)-min(app.plotTable.XData))/value);
            if spinnerLimit == 1
                app.NcyclesSpinner.Value = 1;
                app.NcyclesSpinner.Enable = 'off';
            else
                app.NcyclesSpinner.Enable = 'on';
                app.NcyclesSpinner.Limits = [1,spinnerLimit];
            end
            updateArea(app);
        end

        % Value changed function: NcyclesSpinner
        function NcyclesSpinnerValueChanged(app, event)
            %value = app.NcyclesSpinner.Value;
            updateArea(app);
        end

        % Value changing function: IntegrationregionSlider
        function IntegrationregionSliderValueChanging(app, event)
            changingValue = event.Value;
            updateArea(app,changingValue);
        end

        % Value changed function: IntegrationregionSlider
        function IntegrationregionSliderValueChanged(app, event)
            %value = app.IntegrationregionSlider.Value;
            updateArea(app);
        end

        % Button pushed function: CalculatepowerButton
        function CalculatepowerButtonPushed(app, event)
            if isequal(event.Source,app.ApplyintegrationMenu)&&~isempty(app.SaveIntegration)
                boolASK=false;
            else
                boolASK=true;
            end
            bool = updateArea(app);
            t = app.plotTable.XData(bool);
            y = app.plotTable.YData(bool);

            switch app.ProcessingModeDropDown.Value
                case 'V - t signal'
                    P = y.^2 / app.REditField.Value;
                case 'I - t signal'
                    P = y.^2 * app.REditField.Value;
                case 'V - t with R'
                    P = y.^2 / app.REditField.Value;
                case 'I - t with R'
                    P = y.^2 * app.REditField.Value;
            end

            E = trapz(t,P);
            energy = E / app.NcyclesSpinner.Value;
            timepercycle = app.TimepercyclesEditField.Value;
            if app.Button_2.Value
                timepercycle=1/timepercycle;
            end
            power = energy / timepercycle;
            if boolASK||(isequal(event.Source,app.ApplyintegrationMenu)&&numel(app.SaveIntegration)==1)
                str = sprintf('     Energy per cycle %e J\n\n     Mean power %e W\n',energy,power);
                ButtonName = questdlg(str, ...
                    'Results', ...
                    'Close', sprintf('Copy to clipboard'), sprintf('Append to file'), ...
                    'Close');
            else
                ButtonName = sprintf('Append to file');
            end
            switch ButtonName
                case sprintf('Copy to clipboard')
                    clipboard("copy",str);
                case sprintf('Append to file')
                    if isequal(event.Source,app.ApplyintegrationMenu)&&numel(app.SaveIntegration)>=4
                        [fig, filename, pathname, filterindex] = app.SaveIntegration{:};

                        if isempty(fig)||~ishandle(fig)
                            clear fig
                        end

                    else
                        name = app.NameEditField.Value;
                        name(strfind(name,'_'):end) = [];
                        file = [app.savePath,name,'.txt'];
                        f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uiputfile doesn't minimize our GUI
                        [filename, pathname, filterindex] = uiputfile( ...
                            {'*.txt;*.csv;*.dat;*.asc;*.m', 'All ASCII Files (*.txt, *.csv, *.dat, *.asc, *.m)';
                            '*.*',  'All Files (*.*)'}, ...
                            'Save as',file);
                        delete(f); %delete the dummy figure
                    end

                    if filterindex == 0
                        return;
                    end
                    try
                        data = importdata([pathname,filename]);
                        R = data.data(:,1);
                        POWER = data.data(:,2);
                        ENERGY = data.data(:,3);
                        R(end+1) = app.REditField.Value;
                        POWER(end+1) = power;
                        ENERGY(end+1) = energy;

                        [R,i] = sort(R);
                        POWER = POWER(i);
                        ENERGY = ENERGY(i);
                        if ~exist('fig','var')
                            fig = figure;
                            fig.Units = 'pixels';
                            fig.Position(3) = 475;
                            fig.Resize = 'off';
                            ax = axes(fig);
                            ax.Units = 'pixels';
                            ax.Position = [75 100 325 280];
                            semilogx(ax,R,POWER,'bx-');
                            hold(ax,'on')
                            semilogx(ax,app.REditField.Value,power,'rx');
                            xlabel(ax,'R (\Omega)')
                            ylabel(ax,'P (W)')
                            uicontrol('Parent', fig,'Style','pushbutton','String','OK',...
                                'Position',[200, 25, 75, 25],'Callback','closereq');
                        else
                            ax = findobj(fig,'Type','Axes');
                            hold(ax,'off')
                            semilogx(ax,R,POWER,'bx-');
                            hold(ax,'on')
                            semilogx(ax,app.REditField.Value,power,'rx');
                        end

                        drawnow;

                    catch ME
                        R = app.REditField.Value;
                        POWER = power;
                        ENERGY = energy;

                    end
                    if ~exist('fig','var')
                        fig=[];
                    end
                    app.savePath = pathname;
                    fid = fopen([pathname,filename],'w');
                    fprintf(fid,'R (Ohm)\tMean Power (W)\tEnergy per cycle (J)\n');
                    for n=1:numel(R)
                        fprintf(fid,'%e\t%e\t%e\n',R(n), POWER(n), ENERGY(n));
                    end
                    fclose(fid);

                    if isequal(event.Source,app.ApplyintegrationMenu)&&boolASK
                        Repeat = questdlg('Repeat this action for next iterations', ...
                            'Repeat', ...
                            sprintf('Yes'), sprintf('No'), sprintf('No and don''t show this dialog again'), ...
                            sprintf('Yes'));
                        if isequal(Repeat,'')
                            Repeat='No';
                        end
                        switch Repeat
                            case sprintf('Yes')
                                app.SaveIntegration={fig,filename, pathname, filterindex};

                            case sprintf('No and don''t show this dialog again')
                                app.SaveIntegration={''};
                        end
                    end
                    if isequal(event.Source,app.ApplyintegrationMenu)&&~exist('Repeat','var')
                        app.SaveIntegration{1}=fig;
                    end
            end
        end

        % Value changed function: FFTButton
        function FFTButtonValueChanged(app, event)
            
            app.fadeMoreTab;
            
            value = app.FFTButton.Value;
            if value
                try
                    calculateFFT(app,event);
                catch ME
                    uialert(app.NanoDataLyzerUIFigure,ME.message,'Error','Icon','error')
                end

                app.areaIntegration.Visible = 'off';

                app.CodeandCommentsLabel.Visible = 'off';
                app.CodeandCommentsTextArea.Visible = 'off';
                app.MetadataPanel.Visible = 'off';
                % app.NameLabel.Visible = 'off';
                % app.NameEditField_2.Visible = 'off';
                % app.UnitsEditFieldLabel.Visible = 'off';
                % app.UnitsEditField.Visible = 'off';
                % app.CommentsLabel.Visible = 'off';
                % app.CommentsEditField.Visible = 'off';
                % app.XLabel.Visible = 'off';
                % app.XEditField.Visible = 'off';
                % app.YLabel.Visible = 'off';
                % app.YEditField.Visible = 'off';
                % %app.YcommentsEditFieldLabel.Visible = 'off';
                % app.YcommentsEditField.Visible = 'off';
                app.StimulusDataPanel.Visible = 'off';
                % app.LoadfromfileButton.Visible = 'off';
                % app.StimulusFilenameLabel.Visible = 'off';
                % app.DatafromButtonGroup.Visible = 'off';
                % app.OperationDropDownLabel.Visible = 'off';
                % app.OperationDropDown.Visible = 'off';
                % app.FunctionbeforeoperationusexasvariableLabel.Visible = "off";
                % app.RescaleEditField.Visible = "off";
                % app.FunctionLabel.Visible = "off";
                % app.FunctionafteroperationusexasvariableEditField.Visible = "off";
                % app.StimulusunitsEditFieldLabel.Visible = 'off';
                % app.StimulusunitsEditField.Visible = 'off';
                % app.PlotStimulusButton.Visible = 'off';

                app.UIAxes2.Visible = 'on';
                app.UIAxes2.Toolbar.Visible = 'on';
                app.plotFFT.Visible = 'on';
                app.BandStopCheckBox.Enable = 'on';
                if app.BandStopCheckBox.Value
                    app.areaHthrshld.Visible = 'on';
                    app.LowSpinner.Enable = 'on';
                    app.LowSpinnerLabel.Enable = 'on';
                    app.HighSpinner.Enable = 'on';
                    app.HighSpinnerLabel.Enable = 'on';
                    app.BandStopCheckBoxValueChanged(event);
                    app.ReplaceDataButton.Enable = 'on';
                end
                app.DenoiseCheckBox.Enable = 'on';
                if app.DenoiseCheckBox.Value
                    app.areaVthrshld.Visible = 'on';
                    app.ThresholdSpinner_2.Enable = 'on';
                    app.ThresholdSpinner_2Label.Enable = 'on';
                    app.DenoiseCheckBoxValueChanged(event);
                    app.ReplaceDataButton.Enable = 'on';
                end
            else
                app.BandStopCheckBox.Value=false;
                app.DenoiseCheckBox.Value=false;
                app.areaIntegration.Visible = 'on';
                app.plotTemp.Visible = 'off';

                app.UIAxes2.Visible = 'off';
                app.UIAxes2.Toolbar.Visible = 'off';
                app.plotFFT.Visible = 'off';
                app.areaHthrshld.Visible = 'off';
                app.areaVthrshld.Visible = 'off';
                app.BandStopCheckBox.Enable = 'off';
                app.LowSpinner.Enable = 'off';
                app.LowSpinnerLabel.Enable = 'off';
                app.HighSpinner.Enable = 'off';
                app.HighSpinnerLabel.Enable = 'off';
                app.DenoiseCheckBox.Enable = 'off';
                app.ThresholdSpinner_2.Enable = 'off';
                app.ThresholdSpinner_2Label.Enable = 'off';
                if app.BiascorrectionSpinner.Value==0
                    app.ReplaceDataButton.Enable = 'off';
                end

                app.CodeandCommentsLabel.Visible = 'on';
                app.CodeandCommentsTextArea.Visible = 'on';
                app.MetadataPanel.Visible = 'on';
                % app.NameLabel.Visible = 'on';
                % app.NameEditField_2.Visible = 'on';
                % app.UnitsEditFieldLabel.Visible = 'on';
                % app.UnitsEditField.Visible = 'on';
                % app.CommentsLabel.Visible = 'on';
                % app.CommentsEditField.Visible = 'on';
                % app.XLabel.Visible = 'on';
                % app.XEditField.Visible = 'on';
                % app.YLabel.Visible = 'on';
                % app.YEditField.Visible = 'on';
                % %app.YcommentsEditFieldLabel.Visible = 'on';
                % app.YcommentsEditField.Visible = 'on';
                app.StimulusDataPanel.Visible = 'on';
                % app.LoadfromfileButton.Visible = 'on';
                % app.StimulusFilenameLabel.Visible = 'on';
                % app.DatafromButtonGroup.Visible = 'on';
                % app.OperationDropDownLabel.Visible = 'on';
                % app.OperationDropDown.Visible = 'on';
                % app.FunctionbeforeoperationusexasvariableLabel.Visible = "on";
                % app.RescaleEditField.Visible = "on";
                % app.FunctionLabel.Visible = "on";
                % app.FunctionafteroperationusexasvariableEditField.Visible = "on";
                % app.StimulusunitsEditFieldLabel.Visible = 'on';
                % app.StimulusunitsEditField.Visible = 'on';
                % app.PlotStimulusButton.Visible = 'on';

            end

        end

        % Value changed function: BandStopCheckBox
        function BandStopCheckBoxValueChanged(app, event)
            value = app.BandStopCheckBox.Value;

            if value
                app.LowSpinner.Enable = 'on';
                app.LowSpinnerLabel.Enable = 'on';
                app.HighSpinner.Enable = 'on';
                app.HighSpinnerLabel.Enable = 'on';
                app.areaHthrshld.Visible = 'on';
                app.ReplaceDataButton.Enable = 'on';
            else
                app.LowSpinner.Enable = 'off';
                app.LowSpinnerLabel.Enable = 'off';
                app.HighSpinner.Enable = 'off';
                app.HighSpinnerLabel.Enable = 'off';
                app.areaHthrshld.Visible = 'off';
                if ~app.DenoiseCheckBox.Value&&app.BiascorrectionSpinner.Value==0&&(~app.SmoothCheckBox.Value)
                    app.ReplaceDataButton.Enable = 'off';
                end
            end

            updateIFFTplot(app)

        end

        % Value changing function: LowSpinner
        function LowSpinnerValueChanging(app, event)
            changingValue = event.Value;
            

            ax = axis(app.UIAxes2);


            if changingValue>app.HighSpinner.Value
                app.areaHthrshld.XData = [changingValue*[1,1],ax(2)*[1,1];[0,0],app.HighSpinner.Value*[1,1]]';
                app.areaHthrshld.YData = ax([4,3,3,4;4,3,3,4]');
            else
                app.areaHthrshld.XData = [changingValue*[1,1],app.HighSpinner.Value*[1,1]];
                app.areaHthrshld.YData = ax([4,3,3,4]);
            end

            updateIFFTplot(app,[changingValue,app.HighSpinner.Value],true)

        end

        % Value changed function: DenoiseCheckBox
        function DenoiseCheckBoxValueChanged(app, event)
            value = app.DenoiseCheckBox.Value;

            if value
                app.ThresholdSpinner_2.Enable = 'on';
                app.ThresholdSpinner_2Label.Enable = 'on';
                app.areaVthrshld.Visible = 'on';
                app.ReplaceDataButton.Enable = 'on';
            else
                app.ThresholdSpinner_2.Enable = 'off';
                app.ThresholdSpinner_2Label.Enable = 'off';
                app.areaVthrshld.Visible = 'off';
                if (~app.BandStopCheckBox.Value)&&(~app.SmoothCheckBox.Value)&&app.BiascorrectionSpinner.Value==0
                    app.ReplaceDataButton.Enable = 'off';
                end
            end

            updateIFFTplot(app)
        end

        % Value changing function: ThresholdSpinner_2
        function ThresholdSpinner_2ValueChanging(app, event)
            changingValue = event.Value;

            ax = axis(app.UIAxes2);

            app.areaVthrshld.XData = ax([1,2,2,1]) ;
            app.areaVthrshld.YData = [changingValue*[1,1],[0,0]];

            updateIFFTplot(app,changingValue,false)
        end

        % Callback function: CreateNewDatasetButton, NewDatasetMenu, addTab
        function addTabButtonDown(app, event)
            if strcmp(event.EventName,'SelectionChanged')
                tabOriginal = event.OldValue;
            else
                tabOriginal = app.TabGroup.SelectedTab;
            end
            i = find(app.TabGroup.Children == tabOriginal);
            app.updateData(i);
            if app.nTab==0
                app.DATA={};
            end

            if strcmp(event.EventName,'OpenDataset')
                [app.DATA{end+1},bool] = app.addData(event.Value);

            else
                [app.DATA{end+1},bool] = app.addData;
            end

            if ~bool %Returns if there is a Memory shortage
                return;
            end

            %// Create a new uitab in this group
            tab = uitab(app.TabGroup,...
                'Title', sprintf('Dataset%d', app.nTab + 1));
            app.nTab = app.nTab + 1;
            app.TabGroup.Children([end-1,end]) = app.TabGroup.Children([end,end-1]);
            app.TabGroup.SelectedTab = tab;
            j = numel(findall(app.TabGroup, 'type', 'uitab')) - 1;

            app.FFTButton.Value = 0;
            app.FFTButtonValueChanged(event);
            app.loadData(j);
            if j == 1
                app.TabGroup.Children(end).Title='  +';
                app.CloseDatasetMenu.Enable = 'on';
                app.SaveDatasetMenu.Enable = 'on';
                app.ImportDataMenu.Enable = 'on';
                app.ExportDataMenu.Enable = 'on';
                app.EditMenu.Enable = 'on';
                app.IterateforalldatasetsMenu.Enable = 'on';
                app.ProcessingModeDropDown.Enable = 'on';
                app.ProcessingModeDropDownLabel.Enable = 'on';
                app.UITable.Enable = 'on';
                app.SaveTableDataButton.Enable = 'on';
                app.DataProcessingButton.Enable = 'on';
                app.UIAxes.Visible = 'on';
                app.UIAxes.Toolbar.Visible = 'on';
                app.REditField.Enable = 'on';
                app.RLabel.Enable = 'on';
                app.NameEditField.Enable = 'on';
                app.NameEditFieldLabel.Enable = 'on';
                app.CodeandCommentsTextArea.Visible = 'on';
                app.CodeandCommentsLabel.Visible = 'on';
                app.MetadataPanel.Visible = 'on';
                % app.NameLabel.Visible = 'on';
                % app.NameEditField_2.Visible = 'on';
                % app.UnitsEditFieldLabel.Visible = 'on';
                % app.UnitsEditField.Visible = 'on';
                % app.CommentsLabel.Visible = 'on';
                % app.CommentsEditField.Visible = 'on';
                % app.XLabel.Visible = 'on';
                % app.XEditField.Visible = 'on';
                % app.YLabel.Visible = 'on';
                % app.YEditField.Visible = 'on';
                % %app.YcommentsEditFieldLabel.Visible = 'on';
                % app.YcommentsEditField.Visible = 'on';
                app.StimulusDataPanel.Visible = 'on';
                % app.LoadfromfileButton.Visible = 'on';
                % app.StimulusFilenameLabel.Visible = 'on';
                % app.DatafromButtonGroup.Visible = 'on';
                % app.OperationDropDownLabel.Visible = 'on';
                % app.OperationDropDown.Visible = 'on';
                % app.FunctionbeforeoperationusexasvariableLabel.Visible = "on";
                % app.RescaleEditField.Visible = "on";
                % app.FunctionLabel.Visible = "on";
                % app.FunctionafteroperationusexasvariableEditField.Visible = "on";
                % app.StimulusunitsEditFieldLabel.Visible = 'on';
                % app.StimulusunitsEditField.Visible = 'on';
                % app.PlotStimulusButton.Visible = 'on';
                app.plotZero.Visible = 'on';

                app.CreateNewDatasetButton.Visible = 'off';
                app.OpenDatasetButton.Visible = 'off';
            end
        end

        % Menu selected function: CloseDatasetMenu
        function CloseDatasetMenuSelected(app, event)
            tab = app.TabGroup.SelectedTab;
            if numel(findall(app.TabGroup, 'type', 'uitab')) == 2
                app.DATA{1} = app.addData;
                app.loadData(1)
                app.DATA={};
                delete(tab);
                app.TabGroup.Children(1).Title='Create Dataset';
                app.nTab=0;
                app.CloseDatasetMenu.Enable = 'off';
                app.SaveDatasetMenu.Enable = 'off';
                app.ImportDataMenu.Enable = 'off';
                app.ExportDataMenu.Enable = 'off';
                app.EditMenu.Enable = 'off';
                app.IterateforalldatasetsMenu.Enable = 'off';
                app.ProcessingModeDropDown.Enable = 'off';
                app.ProcessingModeDropDownLabel.Enable = 'off';
                app.UITable.Enable = 'off';
                app.SaveTableDataButton.Enable = 'off';
                app.DataProcessingButton.Enable = 'off';
                app.UIAxes.Toolbar.Visible = 'off';
                app.UIAxes.Visible = 'off';
                app.REditField.Enable = 'off';
                app.RLabel.Enable = 'off';
                app.NameEditField.Enable = 'off';
                app.NameEditFieldLabel.Enable = 'off';
                app.CodeandCommentsTextArea.Visible = 'off';
                app.CodeandCommentsLabel.Visible = 'off';
                app.MetadataPanel.Visible = 'off';
                % app.NameLabel.Visible = 'off';
                % app.NameEditField_2.Visible = 'off';
                % app.UnitsEditFieldLabel.Visible = 'off';
                % app.UnitsEditField.Visible = 'off';
                % app.CommentsLabel.Visible = 'off';
                % app.CommentsEditField.Visible = 'off';
                % app.XLabel.Visible = 'off';
                % app.XEditField.Visible = 'off';
                % app.YLabel.Visible = 'off';
                % app.YEditField.Visible = 'off';
                % %app.YcommentsEditFieldLabel.Visible = 'off';
                % app.YcommentsEditField.Visible = 'off';
                app.StimulusDataPanel.Visible ='off';

                % app.LoadfromfileButton.Visible = 'off';
                % app.StimulusFilenameLabel.Visible = 'off';
                % app.DatafromButtonGroup.Visible = 'off';
                % app.OperationDropDownLabel.Visible = 'off';
                % app.OperationDropDown.Visible = 'off';
                % app.FunctionbeforeoperationusexasvariableLabel.Visible = "off";
                % app.RescaleEditField.Visible = "off";
                % app.FunctionLabel.Visible = "off";
                % app.FunctionafteroperationusexasvariableEditField.Visible = "off";
                % app.StimulusunitsEditFieldLabel.Visible = 'off';
                % app.StimulusunitsEditField.Visible = 'off';
                % app.PlotStimulusButton.Visible = 'off';
                app.plotZero.Visible = 'off';

                app.CreateNewDatasetButton.Visible = 'on';
                app.OpenDatasetButton.Visible = 'on';

            else
                i = find(app.TabGroup.Children == tab);
                app.updateData(i,true);
                if i > 1; i = i - 1; end
                app.TabGroup.SelectedTab = app.TabGroup.Children(i);
                delete(tab);
                app.FFTButton.Value = 0;
                app.FFTButtonValueChanged(event);
                app.loadData(i);
            end
        end

        % Button down function: Dataset1Tab
        function DatasetTabButtonDown(app, event)


        end

        % Menu selected function: SavecurrentdatasetMenu
        function SaveDatasetMenuSelected(app, event)
            name = app.NameEditField.Value;
            if isempty(name)
                name = app.TabGroup.SelectedTab.Title;
            end
            file = [app.savePath,name,'.ndl'];
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uiputfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uiputfile( ...
                { '*.ndl','NanoDataLyzer dataset (*.ndl)'; ...
                '*.mat','Binary MAT-file (*.mat)'; ...
                '*.*',  'All Files (*.*)'}, ...
                'Save as',file);

            delete(f); %delete the dummy figure
            if filterindex==0
                return;
            end

            app.savePath = pathname;
            i = find(app.TabGroup.Children == app.TabGroup.SelectedTab);
            data = app.updateData(i);

            save([pathname,filename],'data','-mat');


        end

        % Callback function: OpenDatasetButton, OpenDatasetMenu
        function OpenDatasetMenuSelected(app, event)
            if strcmp(event.EventName,'startupOpenDataset')
                filename = event.Value;
                [pathname,~,~] = fileparts(filename{1});
                app.savePath=pathname;
                pathname='';
            else

                name = sprintf('Dataset%d', app.nTab + 1);
                file = [app.savePath,name,'.ndl'];

                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [filename, pathname, filterindex] = uigetfile( ...
                    { '*.ndl','NanoDataLyzer dataset (*.ndl)'; ...
                    '*.mat','Binary MAT-file (*.mat)'; ...
                    '*.*',  'All Files (*.*)'}, ...
                    'Open a file',file,'MultiSelect','on');

                delete(f); %delete the dummy figure
                if filterindex==0
                    return;
                end
                app.savePath = pathname;
            end
            
            if app.nTab==0
                bool=1;
            else
                bool=0;
            end

            
            tabOriginal = app.TabGroup.SelectedTab;
            i = find(app.TabGroup.Children == tabOriginal);

            if ~isa(filename,'cell')
                filename = {filename};
            end
            evnt=struct('Source',event.Source,'EventName','OpenDataset','Value','');
            for k = 1:numel(filename)
                evnt.Value = [pathname,filename{k}];
                app.addTabButtonDown(evnt);
            end


            WD=cd;
            cd(app.savePath)
            closed=0;
            for K=(i+1-bool):numel(app.TabGroup.Children)-1
                REPEAT=true;
                tempCode='';
                while REPEAT
                    
                    REPEAT=false;
                    k=K-closed;
                    app.TabGroup.SelectedTab = app.TabGroup.Children(k);
                    app.loadData(k);
                    if isempty(tempCode)
                        tempCode=app.CodeandCommentsTextArea.Value;
                    end
                    app.CodeandCommentsTextArea.Value=tempCode;
                    closeRequest=false;
                    pauseRequest=false;
                    repeatRequest=false;
                    for q=1:numel(app.CodeandCommentsTextArea.Value)
                        str=app.CodeandCommentsTextArea.Value{q};
                        iCode=strfind(str,'\run');
                        if ~isempty(iCode)
                            str=str(iCode(1)+4:end);
                            iSpace=strfind(str,' ');
                            if ~isempty(iSpace)
                                num=str2double(str(1:iSpace(1)));
                                if ~isnan(num)
                                    num=round(num);
                                else
                                    num=0;
                                end
                                str=str(iSpace(1)+1:end);
                                if num==1
                                    app.CodeandCommentsTextArea.Value{q}=app.CodeandCommentsTextArea.Value{q}(1:iCode(1)-1);
                                elseif num>0
                                    app.CodeandCommentsTextArea.Value{q}=sprintf('%s%i %s',...
                                        app.CodeandCommentsTextArea.Value{q}(1:iCode(1)+3),num-1,str);
                                end

                            end

                            try
                                evalin('base','clear');
                                evalin('base','[];');
                                evalin('base',str)

                                dataEval=app.getVariables;

                                if ~isempty(dataEval.data)
                                    var={};
                                    for i=1:numel(app.UITable.Data.Properties.VariableNames)

                                        var{i}=dataEval.data(:,i);
                                        % if numel(var{i})>1000
                                        %     step=ceil(numel(var{i})/1000)-1;
                                        %     var{i}=var{i}(1:step:end);
                                        % end

                                    end
                                    t=table(var{:});
                                    app.UITable.Data = renamevars(t,t.Properties.VariableNames,app.UITable.Data.Properties.VariableNames);
                                    app.UITable.CellEditCallback(app,event);
                                end

                                if ~isempty(dataEval.R)
                                    R=abs(dataEval.R);
                                    app.REditField.Value=R;
                                    if R>=1e9
                                        num=R/1e9;
                                        str='GOhm';
                                    elseif R>=1e6
                                        num=R/1e6;
                                        str='MOhm';
                                    elseif R>=1e3
                                        num=R/1e3;
                                        str='kOhm';
                                    else
                                        num=R;
                                        str='Ohm';
                                    end
                                    app.NameEditField.Value=sprintf('%s_%.2f%s',app.NameEditField.Value,num,str);
                                end

                                if ~isempty(dataEval.processingMode)
                                    app.ProcessingModeDropDown.Value=dataEval.processingMode;
                                    app.ProcessingModeDropDownValueChanged;
                                end

                                if ~isempty(dataEval.Xname)
                                    app.NameEditField_2.Value=dataEval.Xname;
                                end

                                if ~isempty(dataEval.Yname)
                                    app.XEditField.Value=dataEval.Yname;
                                end

                                if ~isempty(dataEval.Xunits)
                                    app.UnitsEditField.Value=dataEval.Xunits;
                                end

                                if ~isempty(dataEval.Yunits)
                                    app.YEditField.Value=dataEval.Yunits;
                                end

                                app.updateLabels;

                                drawstimulus=false;

                                if ~isempty(dataEval.dataStimulus)
                                    app.dataForce=dataEval.dataStimulus;
                                    app.StimulusFilenameLabel.Text='from code';
                                    if size(app.dataForce,2)>2
                                        app.DatafromButtonGroup.Enable='on';
                                    else
                                        app.DatafromButtonGroup.Enable='off';
                                    end
                                    drawstimulus=true;
                                end

                                if ~isempty(dataEval.channelOfStimulus)
                                    h = findall(app.DatafromButtonGroup,'Text',dataEval.channelOfStimulus);
                                    h.Value=true;
                                    drawstimulus=true;
                                end

                                if ~isempty(dataEval.trigger)
                                    app.TriggerCheckBox.Value=dataEval.trigger;
                                    drawstimulus=true;
                                end

                                if ~isempty(dataEval.labelStimulus)
                                    app.StimulusunitsEditField.Value=dataEval.labelStimulus;
                                end

                                if ~isempty(dataEval.rescale)
                                    app.RescaleEditField.Value=dataEval.rescale;
                                    drawstimulus=true;
                                end

                                if ~isempty(dataEval.operationStimulus)
                                    ind=strcmpi(dataEval.operationStimulus,app.OperationDropDown.Items);
                                    app.OperationDropDown.Value=app.OperationDropDown.ItemsData(ind);
                                    drawstimulus=true;
                                end

                                if ~isempty(dataEval.additionalOperations)
                                    app.AdditionaloperationsPanel.Value=dataEval.additionalOperations;
                                    drawstimulus=true;
                                end

                                if drawstimulus
                                    app.PlotStimulusButtonPushed(event);
                                end

                            catch ME
                                uialert(app.NanoDataLyzerUIFigure,ME.message,'Error evaluating code','Icon','warning')
                            end

                        end
                        iSave=strfind(str,'\save');
                        if ~isempty(iSave)
                            Name = app.NameEditField.Value;
                            if isempty(Name)
                                Name = app.TabGroup.SelectedTab.Title;
                            end
                            file = [app.savePath,Name,'.ndl'];

                            i = find(app.TabGroup.Children == app.TabGroup.SelectedTab);
                            data = app.updateData(i);

                            save(file,'data','-mat');
                        end
                        iClose=strfind(str,'\close');
                        if ~isempty(iClose)
                            closeRequest=true;
                        else
                            iClose=length(str)+1;
                        end
                        iRepeat=strfind(str,'\repeat');
                        if ~isempty(iRepeat)
                            repeatRequest=true;
                        end
                        iPause=strfind(str,'\pause');
                        if ~isempty(iPause)
                            pauseRequest=true;
                            msg = str((iPause+6):iClose-1);
                        end
                    end
                    app.updateData(k);
                    if pauseRequest
                        if isempty(msg)
                            msg='Continue';
                        end
                        pausedlg = helpdlg(msg, 'Pause Dialog');
                        waitfor(pausedlg);
                    end
                    if repeatRequest
                        answer = questdlg('Repeat code execution?', ...
                        	'Repeat Dialog', ...
                        	'Repeat','Continue','Continue');
                        switch answer
                            case 'Repeat'
                                REPEAT=true;
                                continue;
                            case 'Continue'
                                %tempCode='';
                        end
                    end
                    if closeRequest
                        app.CloseDatasetMenuSelected(event);
                        closed=closed+1;
                    end
                end
            end

            cd(WD)
            app.MemoryWarningLoop=0;

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            %app.NanoDataLyzerUIFigure.CloseRequestFcn(event);
            close(app.NanoDataLyzerUIFigure)
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            i = find(app.TabGroup.Children == app.TabGroup.SelectedTab);
            app.DATA{i} = app.addData;
            app.FFTButton.Value = 0;
            app.FFTButtonValueChanged(event);
            app.loadData(i);
        end

        % Menu selected function: ExportcurrentdatasetMenu
        function ExportDataMenuSelected(app, event)
            bool = app.boolProcessing;
            app.boolProcessing = true;
            app.DataProcessingButtonPushed(event);
            app.boolProcessing = bool;
        end

        % Menu selected function: PasteTableMenu
        function PasteTableMenuSelected(app, event)
            try
                type=app.NanoDataLyzerUIFigure.CurrentObject.Type;
            catch ME
                type='uifigure';
            end
            
            switch type
                case {'uinumericeditfield','uieditfield','uispinner','uitextarea'}
                    data=clipboard('paste');
                    try
                        if isnumeric(app.NanoDataLyzerUIFigure.CurrentObject.Value)
                            data=str2double(data);
                        end
                        app.NanoDataLyzerUIFigure.CurrentObject.Value=data;
                    catch ME
                        data=clipboard('pastespecial');
                        try

                            f=fields(data);
                            data=data.(f{1});

                            app.NanoDataLyzerUIFigure.CurrentObject.Value=data;
                        end
                    end
                otherwise
                    app.pasteFnc(struct('Source',app.UITable,'EventName','KeyPress'))
            end
            
        end

        % Menu selected function: CopyTableMenu
        function CopyTableMenuSelected(app, event)
            try
                type=app.NanoDataLyzerUIFigure.CurrentObject.Type;
            catch ME
                type='uifigure';
            end
            
            switch type
                case {'uinumericeditfield','uieditfield','uispinner','uitextarea'}
                    clipboard('copy',app.NanoDataLyzerUIFigure.CurrentObject.Value)
                otherwise
                    app.copyFnc(app.UITable.Data.Variables);
            end
        end

        % Menu selected function: CopyPlotDataMenu
        function CopyPlotDataMenuSelected(app, event)
            app.copyFnc([app.plotTable.XData',app.plotTable.YData']);
        end

        % Menu selected function: ImportDataMenu
        function ImportDataMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uigetfile( ...
                {'*.txt;*.csv;*.dat;*.asc;*.m', 'All ASCII Files (*.txt, *.csv, *.dat, *.asc, *.m)';
                '*.csv', 'CSV from Tektronix oscilloscope (*.csv)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Data File',fullfile(app.savePath,'SensorData.csv'),'MultiSelect','on');

            delete(f); %delete the dummy figure
            if filterindex==0
                return;
            end

            if ~isa(filename,'cell')
                files = {filename};
            else
                files = filename;
            end

            app.savePath = pathname;

            for k=1:numel(files)
                filename=files{k};
                app.StimulusFilenameLabel.Text=filename;
                [~, app.NameEditField.Value, ~]= fileparts(filename);

                s = dir(fullfile(pathname,filename));
                if ~app.checkMemory(s.bytes)
                    app.MemoryWarningLoop=0;
                    return;
                end
                app.MemoryWarningLoop=0;

                if filterindex==2
                    data=importdata(fullfile(pathname,filename),',',18);

                    if size(data.data,2)==11
                        data = data.data(:,[4,5,10,11]);
                    elseif size(data.data,2)==5
                        data = data.data(:,[4,5]);
                    elseif size(data.data,2)==2
                        data = data.data;
                    end
                else
                    data=uiimport(fullfile(pathname,filename));
                    if isempty(data)
                        return;
                    end
                    if isfield(data,'data')
                        data=data.data;
                    elseif numel(fieldnames(data))==1
                        field=fieldnames(data);
                        data=data.(field{1});
                    else
                        uialert(app.NanoDataLyzerUIFigure,'Import the data with ''data'' name', 'Import Data Error','Icon','error');
                        return;
                    end
                end
                X = [];
                Y = [];
                Force = [];
                if size(data,2) >= 4
                    answer = questdlg(...
                        sprintf('More than one data channel detected.\nConsider the extra data as stimulus sensor signal?'), ...
                        'Import Data', ...
                        'Use additional data','Discard additional data','Use additional data');

                    switch answer
                        case 'Use additional data'
                            discard = false;
                        case 'Discard additional data'
                            discard = true;
                        case ''
                            warndlg('Operation cancelled by user.', 'Import Data');
                            return;
                    end

                    answer = questdlg(...
                        sprintf('Select the data channel that contains the signal.'), ...
                        'Import Data', ...
                        'CH 1','CH 2','Last','CH 1');

                    switch answer
                        case 'CH 1'
                            X = data(:,1);
                            Y = data(:,2);
                        case 'CH 2'
                            X = data(:,3);
                            Y = data(:,4);
                        case 'Last'
                            X = data(:,end-1);
                            Y = data(:,end);
                        case ''
                            warndlg('Operation cancelled by user.', 'Import Data');
                            return;
                    end
                    if ~discard
                        answer = questdlg(...
                            sprintf('Select the data channel that contains the stimulus signal.'), ...
                        	'Import Data', ...
                        	'CH 1','CH 2','Last','CH 1');

                        switch answer
                            case 'CH 1'
                                Force = data(:,1:2);
                            case 'CH 2'
                                Force = data(:,3:4);
                            case 'Last'
                                Force = data(:,end-1:end);
                            case ''
                                warndlg('Operation cancelled by user.', 'Import Data');
                                return;
                        end
                    end
                else
                    try
                        X = data(:,1);
                        Y = data(:,2);
                    catch ME
                        errordlg('Error importing data. Unconsistent input data.', 'Import Data');
                        return
                    end
                end
                try
                    app.UITable.Data = table(X,Y);
                    if ~isempty(Force)
                        app.CH1Button.Value = true;
                        app.TriggerCheckBox.Value = false;
                        app.DatafromButtonGroup.Enable = 'off';
                        app.StimulusFilenameLabel.Text = filename;
                        app.dataForce = Force;

                        app.RescaleEditField.Enable = 'on';
                        app.FunctionbeforeoperationusexasvariableLabel.Enable = 'on';
                        app.Button.Enable = 'on';
                        app.StimulusFilenameLabel.Enable = 'on';
                        app.StimulusunitsEditField.Enable = 'on';
                        app.StimulusunitsEditFieldLabel.Enable = 'on';
                        app.PlotStimulusButton.Enable = 'on';
                    end
                catch ME
                    errordlg('Error importing data. Unable to update table.', 'Import Data');
                    return
                end
                app.UITable.CellEditCallback(app,event);
                if k<numel(files)
                    app.addTabButtonDown(event)
                end
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.tableSelected = event.Indices;
        end

        % Menu selected function: DeleteRowMenu
        function DeleteRowMenuSelected(app, event)
            i = app.tableSelected;
            if ~isempty(i)
                iX = unique(i(:,1),'stable');
                app.UITable.Data(iX,:) = [];
                app.tableSelected = [];

                app.UITable.CellEditCallback(app,event);
            end
        end

        % Menu selected function: AddRowMenu
        function AddRowMenuSelected(app, event)
            app.UITable.Data = [app.UITable.Data;{0,0}];
            app.checkTableChanged;
        end

        % Menu selected function: InsertRowMenu
        function InsertRowMenuSelected(app, event)
            if ~isempty(app.tableSelected)
                i = max(app.tableSelected(:,1));
                app.UITable.Data = [app.UITable.Data(1:i,:);{0,0};app.UITable.Data(i+1:end,:)];
            else
                app.AddRowMenuSelected(event);
            end
            app.checkTableChanged;
        end

        % Menu selected function: ExportalldatasetsMenu
        function ExportalldatasetsMenuSelected(app, event)
            tabOriginal = app.TabGroup.SelectedTab;
            i = find(app.TabGroup.Children == tabOriginal);
            app.updateData(i);


            for k=1:numel(app.TabGroup.Children)-1
                app.TabGroup.SelectedTab = app.TabGroup.Children(k);
                app.loadData(k);
                bool = app.boolProcessing;
                app.boolProcessing = true;
                app.DataProcessingButtonPushed(event);
                app.boolProcessing = bool;
            end


            app.TabGroup.SelectedTab = tabOriginal;
            app.loadData(i);


        end

        % Menu selected function: ExportalltoOriginMenu
        function ExportalltoOriginMenuSelected(app, event)
            tabOriginal = app.TabGroup.SelectedTab;
            i = find(app.TabGroup.Children == tabOriginal);
            app.updateData(i);

            file = [app.savePath,'Measurements','.opju'];
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uiputfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uiputfile( ...
                {'*.opj;*.opju','Origin Projects (*.opj,*.opju)';
                '*.*',  'All Files (*.*)'}, ...
                'Save as',file);

            delete(f); %delete the dummy figure
            if filterindex==0
                return;
            end

            app.savePath = pathname;


            choice = questdlg(sprintf('%s\n\n%s%s\n',...
                'Any currently open Origin Projects will be saved and closed in order to continue.',...
                'If an open Origin project does not have a filepath specified already the project will be saved ',...
                'to a new file in your Origin User Files directory, entitled ''UNTITLED.opj'''),...
                'Warning!',...
                'OK','Cancel','OK');

            if strcmpi(choice,'Cancel') % If user pressed Cancel, return
                return
            end

            app.OriginExport={[pathname,filename],1};

            f = waitbar(0,'1','Name','Exporting...',...
                'WindowStyle','modal','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');

            setappdata(f,'canceling',0);

            for k=1:numel(app.TabGroup.Children)-1
                % Check for clicked Cancel button
                if getappdata(f,'canceling')
                    break
                end

                % Update waitbar and message
                waitbar(k/(numel(app.TabGroup.Children)-1),f,sprintf('%.2f%%',100*k/(numel(app.TabGroup.Children)-1)))

                app.TabGroup.SelectedTab = app.TabGroup.Children(k);
                app.loadData(k);
                bool = app.boolProcessing;
                app.boolProcessing = true;
                app.OriginExport{2}=numel(app.TabGroup.Children)-1-k;
                app.DataProcessingButtonPushed(event);
                app.boolProcessing = bool;
            end

            delete(f);
            app.TabGroup.SelectedTab = tabOriginal;
            app.loadData(i);

            app.OriginExport='';
        end

        % Value changing function: HighSpinner
        function HighSpinnerValueChanging(app, event)
            changingValue = event.Value;

            ax = axis(app.UIAxes2);


            if app.LowSpinner.Value>changingValue
                app.areaHthrshld.XData = [app.LowSpinner.Value*[1,1],ax(2)*[1,1];[0,0],changingValue*[1,1]]';
                app.areaHthrshld.YData = ax([4,3,3,4;4,3,3,4]');
            else
                app.areaHthrshld.XData = [app.LowSpinner.Value*[1,1],changingValue*[1,1]];
                app.areaHthrshld.YData = ax([4,3,3,4]);
            end

            updateIFFTplot(app,[app.LowSpinner.Value,changingValue],true)

        end

        % Value changed function: BiascorrectionSpinner
        function BiascorrectionSpinnerValueChanged(app, event)
            
            app.fadeMoreTab;
            
            app.ReplaceDataButton.Enable = 'on';
            updateIFFTplot(app)
        end

        % Value changing function: BiascorrectionSpinner
        function BiascorrectionSpinnerValueChanging(app, event)
            
            app.fadeMoreTab;
            
            changingValue = event.Value;
            app.BiascorrectionSpinner.Value = changingValue;
            if (changingValue == 0)&&(~app.SmoothCheckBox.Value)&&(~app.DenoiseCheckBox.Value)&&(~app.BandStopCheckBox.Value)
                app.ReplaceDataButton.Enable = 'off';
            else
                app.ReplaceDataButton.Enable = 'on';
            end
            updateIFFTplot(app)
        end

        % Value changed function: OperationDropDown
        function OperationDropDownValueChanged(app, event)
            value = app.OperationDropDown.Value;
            if value==3

                if ~app.boolProcessing
                    pos = app.UITable.Position;
                    pos(2) = app.RemoveunsortedXpointsButton.Position(2)+1.5*app.RemoveunsortedXpointsButton.Position(4);
                    pos(4) = app.UITable.Position(2) + pos(4) - pos(2);
                    app.UITable.Position = pos;
                    app.RemoveunsortedXpointsButton.Visible = 'on';
                    app.LoadparamsButton.Visible = 'on';
                    app.SaveparamsButton.Visible = 'on';
                    app.Button_3.Visible = 'on';
                    app.DenoisingPanel.Visible = 'on';
                    app.BiascorrectionSpinner.Value = 0;
                    app.IntegrationPanel.Visible = 'on';

                else

                    app.boolProcessing = false;
                end

                app.AdditionaloperationsPanel.Visible = 'off';

                app.TabGroup.Visible = 'off';
                app.FileMenu.Enable = 'off';
                app.EditMenu.Enable = 'off';
                app.IterateforalldatasetsMenu.Enable = 'off';
                app.IntegrationPanel.Enable = 'off';
                app.DataProcessingButton.Enable = 'off';
                app.UITable.Enable = 'off';
                app.SaveTableDataButton.Enable = 'off';
                app.FFTButton.Enable = 'off';
                app.ReplaceDataButton.Visible = 'on';
                app.ReplaceDataButton.Text = 'Cancel';
                app.ReplaceDataButton.Enable = 'on';
                app.RemoveunsortedXpointsButton.Enable = 'off';
                app.ProcessingModeDropDown.Enable = 'off';
                app.ProcessingModeDropDownLabel.Enable = 'off';
                app.FFTButton.Value = true;

                FFTButtonValueChanged(app,event);

            end
        end

        % Menu selected function: ApplydenoisingMenu
        function ApplydenoisingMenuSelected(app, event)
            denoiseData={app.BiascorrectionSpinner.Value;
                app.SmoothCheckBox.Value;
                app.NSpinner.Value;
                app.DenoiseCheckBox.Value;
                app.ThresholdSpinner_2.Value;
                app.BandStopCheckBox.Value;
                app.LowSpinner.Value;
                app.HighSpinner.Value};

            tabOriginal = app.TabGroup.SelectedTab;
            i = find(app.TabGroup.Children == tabOriginal);
            app.updateData(i);


            for k=1:numel(app.TabGroup.Children)-1
                app.TabGroup.SelectedTab = app.TabGroup.Children(k);
                app.loadData(k);
                app.boolProcessing = false;
                app.DataProcessingButtonPushed(event);
                app.FFTButton.Value=true;
                app.FFTButtonValueChanged(event)
                [app.BiascorrectionSpinner.Value,...
                    app.SmoothCheckBox.Value,...
                    app.NSpinner.Value,...
                    app.DenoiseCheckBox.Value,...
                    app.ThresholdSpinner_2.Value,...
                    app.BandStopCheckBox.Value,...
                    app.LowSpinner.Value,...
                    app.HighSpinner.Value]=denoiseData{:};
                updateIFFTplot(app,[app.LowSpinner.Value,app.HighSpinner.Value],true)
                app.ReplaceDataButtonPushed(event);
                app.updateData(k);
            end


            app.TabGroup.SelectedTab = tabOriginal;
            app.loadData(i);

        end

        % Menu selected function: ApplyintegrationMenu
        function ApplyintegrationMenuSelected(app, event)
            if app.Button_2.Value
                timepercycle=1/app.TimepercyclesEditField.Value;
                app.Button_2.Value=false;
                app.Button_2ValueChanged(event);
            else
                timepercycle=app.TimepercyclesEditField.Value;
            end
            integrationData={timepercycle;
                app.NcyclesSpinner.Value;
                app.IntegrationregionSlider.Value};

            tabOriginal = app.TabGroup.SelectedTab;
            i = find(app.TabGroup.Children == tabOriginal);
            app.updateData(i);

            app.SaveIntegration={};


            for k=1:numel(app.TabGroup.Children)-1
                app.TabGroup.SelectedTab = app.TabGroup.Children(k);
                app.loadData(k);
                app.boolProcessing = false;
                app.DataProcessingButtonPushed(event);

                [app.TimepercyclesEditField.Value,...
                    app.NcyclesSpinner.Value,...
                    app.IntegrationregionSlider.Value]=integrationData{:};
                app.CalculatepowerButtonPushed(event)
            end


            app.TabGroup.SelectedTab = tabOriginal;
            app.loadData(i);
        end

        % Value changed function: SmoothCheckBox
        function SmoothCheckBoxValueChanged(app, event)
            
            app.fadeMoreTab;
            
            if app.SmoothCheckBox.Value
                app.MethodDropDown.Enable="on";
                % app.NSpinnerLabel.Enable="on";
                app.ReplaceDataButton.Enable = 'on';
                updateIFFTplot(app);
            else
                app.MethodDropDown.Enable="off";
                % app.NSpinnerLabel.Enable="off";
                if (app.BiascorrectionSpinner.Value == 0)&&(~app.DenoiseCheckBox.Value)&&(~app.BandStopCheckBox.Value)
                    app.ReplaceDataButton.Enable = 'off';
                end
                updateIFFTplot(app);
            end

        end

        % Value changed function: NSpinner
        function NSpinnerValueChanged(app, event)
            switch app.MethodDropDown.Value
                case 'Savitzky-Golay'
                    app.NSpinner.Value=round((app.NSpinner.Value-1)/2)*2+1;
                    app.PolynomialorderSpinner.Limits=[0,app.NSpinner.Value-1];
            end
            updateIFFTplot(app);
        end

        % Menu selected function: SavealldatasetsMenu
        function SavealldatasetsMenuSelected(app, event)
            tabOriginal = app.TabGroup.SelectedTab;
            i = find(app.TabGroup.Children == tabOriginal);
            app.updateData(i);


            for k=1:numel(app.TabGroup.Children)-1
                app.TabGroup.SelectedTab = app.TabGroup.Children(k);
                app.loadData(k);
                bool = app.boolProcessing;
                app.boolProcessing = true;
                app.SaveDatasetMenuSelected(event);
                app.boolProcessing = bool;
            end


            app.TabGroup.SelectedTab = tabOriginal;
            app.loadData(i);


        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            app.fadeMoreTab;
            
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title,'  +')
                app.addTabButtonDown(event);
            else
                app.DatasetTabButtonDown(event)

                tabOriginal = event.OldValue;
                i = find(app.TabGroup.Children == tabOriginal);
                tabFinal = event.NewValue;
                j = find(app.TabGroup.Children == tabFinal);
                app.updateData(i);
                app.FFTButton.Value = 0;
                app.FFTButtonValueChanged(event);
                app.loadData(j);

            end
        end

        % Menu selected function: CreatedatasetbatchMenu
        function CreatedatasetbatchMenuSelected(app, event)
            BatchCreator;
        end

        % Menu selected function: SplitsignalintodatasetsMenu
        function SplitsignalintodatasetsMenuSelected(app, event)
            DataSplitter;
        end

        % Menu selected function: UndoMenu
        function UndoMenuSelected(app, event)
            if ~isempty(app.undoData)
                [app.undoData,app.redoData]=app.UnReDo(app.undoData,app.redoData);
            end

            if isempty(app.undoData)
                app.UndoMenu.Enable='off';
            else
                app.UndoMenu.Enable='on';
            end
            if isempty(app.redoData)
                app.RedoMenu.Enable='off';
            else
                app.RedoMenu.Enable='on';
            end
        end

        % Menu selected function: RedoMenu
        function RedoMenuSelected(app, event)
            if ~isempty(app.redoData)
                [app.redoData,app.undoData]=app.UnReDo(app.redoData,app.undoData);
            end

            if isempty(app.undoData)
                app.UndoMenu.Enable='off';
            else
                app.UndoMenu.Enable='on';
            end
            if isempty(app.redoData)
                app.RedoMenu.Enable='off';
            else
                app.RedoMenu.Enable='on';
            end
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if value
                app.AdditionaloperationsPanel.Visible="on";
                app.Button.Text='-';
                focus(app.OperationDropDown)
            else
                app.AdditionaloperationsPanel.Visible="off";
                app.Button.Text='+';
            end
        end

        % Menu selected function: SplittedpeaksanalysisMenu
        function SplittedpeaksanalysisMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uigetdir doesn't minimize our GUI
            dirName = uigetdir(app.savePath,'Select folder where single datasets are located');
            delete(f); %delete the dummy figure

            if dirName==0
                return;
            end

            name = app.NameEditField.Value;
            if isempty(name)
                name = 'data';
            end
            name = [name,'PeakAnalysis'];
            file = [dirName,name,'.txt'];
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uiputfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uiputfile( ...
                {'*.txt;*.csv;*.dat;*.asc;*.m', 'All ASCII Files (*.txt, *.csv, *.dat, *.asc, *.m)';
                '*.*',  'All Files (*.*)'}, ...
                'Save peak analysis as',file);

            delete(f); %delete the dummy figure
            if filterindex==0
                return;
            end
            try
                getPeakValues(dirName,[pathname,filesep,filename]);
                uialert(app.NanoDataLyzerUIFigure,'Succesfully saved!','Calculation completed','Icon','success')
            catch ME
                uialert(app.NanoDataLyzerUIFigure,ME.message,'Error','Icon','error')

            end
        end

        % Menu selected function: ApplyaverageandstdMenu
        function ApplyaverageandstdMenuSelected(app, event)
            name = app.NameEditField.Value;
            if isempty(name)
                name = 'data';
            end
            file = [app.savePath,name,'.txt'];
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uigetfile( ...
                {'*.txt;*.csv;*.dat;*.asc;*.m', 'All ASCII Files (*.txt, *.csv, *.dat, *.asc, *.m)';
                '*.*',  'All Files (*.*)'}, ...
                'Select a file',file);

            delete(f); %delete the dummy figure
            if filterindex==0
                return;
            end

            fileIn=[pathname,filesep,filename];

            name = app.NameEditField.Value;
            if isempty(name)
                name = 'data';
            end
            name = [name,'_MeanSigma'];
            file = [pathname,name,'.txt'];
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uiputfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uiputfile( ...
                {'*.txt;*.csv;*.dat;*.asc;*.m', 'All ASCII Files (*.txt, *.csv, *.dat, *.asc, *.m)';
                '*.*',  'All Files (*.*)'}, ...
                'Save as',file);

            delete(f); %delete the dummy figure
            if filterindex==0
                return;
            end

            fileOut=[pathname,filesep,filename];
            
            try
            calculateMeanError(fileIn,fileOut)
            uialert(app.NanoDataLyzerUIFigure,'Succesfully saved!','Calculation completed','Icon','success')
            catch ME
                uialert(app.NanoDataLyzerUIFigure,ME.message,'Error','Icon','error')
                        
            end

        end

        % Button pushed function: SaveparamsButton
        function SaveparamsButtonPushed(app, event)
            if app.Button_2.Value
                app.Button_2.Value=false;
                app.Button_2ValueChanged(event);
            end
            data=struct('BiasCorrection',app.BiascorrectionSpinner.Value, ...
                'Smooth',struct('value',app.SmoothCheckBox.Value,'mode',app.MethodDropDown.Value,'data',struct('N',app.NSpinner.Value,'PolynomialOrder',app.PolynomialorderSpinner.Value)), ...
                'FFT',struct('value',app.FFTButton.Value, ...
                'Denoise',struct('value',app.DenoiseCheckBox.Value,'threshold',app.ThresholdSpinner_2.Value), ...
                'BandStop',struct('value',app.BandStopCheckBox.Value,'low',app.LowSpinner.Value,'high',app.HighSpinner.Value)), ...
                'Integration',struct('timePerCycle',app.TimepercyclesEditField.Value,'nCycles',app.NcyclesSpinner.Value,'integrationRegion',app.IntegrationregionSlider.Value));

            str=jsonencode(data,"PrettyPrint", true);

            name = 'parameters';
            file = [app.paramsPath,name,'.json'];
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uiputfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uiputfile( ...
                {'*.json', 'JSON Files (*.json)';
                '*.json;*.txt;*.dat;*.asc;*.m', 'All ASCII Files (*.json,*.txt, *.dat, *.asc, *.m)';
                '*.*',  'All Files (*.*)'}, ...
                'Select a file',file);

            delete(f); %delete the dummy figure
            if filterindex==0
                return;
            end
            app.paramsPath=pathname;
            fileOut=[pathname,filesep,filename];
            fid=fopen(fileOut,"w");
            fprintf(fid,str);
            fclose(fid);

        end

        % Button pushed function: LoadparamsButton
        function LoadparamsButtonPushed(app, event)
            name = 'parameters';
            file = [app.paramsPath,name,'.json'];
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn',''); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [filename, pathname, filterindex] = uigetfile( ...
                {'*.json', 'JSON Files (*.json)';
                '*.json;*.txt;*.dat;*.asc;*.m', 'All ASCII Files (*.json,*.txt, *.dat, *.asc, *.m)';
                '*.*',  'All Files (*.*)'}, ...
                'Select a file',file);

            delete(f); %delete the dummy figure
            if filterindex==0
                return;
            end
            fileIn = [pathname,filesep,filename];
            str = fileread(fileIn); 
            data = jsondecode(str); 
            
            app.BiascorrectionSpinner.Value=data.BiasCorrection;
            app.SmoothCheckBox.Value=data.Smooth.value;
            % switch data.Smooth.mode
            %     case 'Adjacent average'
            %         app.NSpinner.Value=data.Smooth.data.N;
            % end
            if strcmp(data.Smooth.mode,'Adjacent average')
                data.Smooth.mode='Adjacent averaging';
            end
            app.MethodDropDown.Value=data.Smooth.mode;
            app.NSpinner.Value=data.Smooth.data.N;
            if isfield(data.Smooth.data,'PolynomialOrder')
                app.PolynomialorderSpinner.Value=data.Smooth.data.PolynomialOrder;
            end

            app.FFTButton.Value=data.FFT.value;
            if data.FFT.value
                app.FFTButtonValueChanged(event);
            end
            app.DenoiseCheckBox.Value=data.FFT.Denoise.value;
            app.ThresholdSpinner_2.Value=data.FFT.Denoise.threshold;
            app.BandStopCheckBox.Value=data.FFT.BandStop.value;
            app.LowSpinner.Value=data.FFT.BandStop.low;
            app.HighSpinner.Value=data.FFT.BandStop.high;

            if app.Button_2.Value
                app.Button_2.Value=false;
                app.Button_2ValueChanged(event);
            end
            app.TimepercyclesEditField.Value=data.Integration.timePerCycle;
            app.NcyclesSpinner.Value=data.Integration.nCycles;
            app.IntegrationregionSlider.Value=data.Integration.integrationRegion;

            app.updateIFFTplot;

        end

        % Value changed function: ShowstimulusplotCheckBox
        function ShowstimulusplotCheckBoxValueChanged(app, event)
            value = app.ShowstimulusplotCheckBox.Value;
            if value
                yyaxis(app.UIAxes,'right');
                app.plotForce.Visible = 'on';
                app.UIAxes.YAxis(2).Visible='on';
                yyaxis(app.UIAxes,'left');
            else
                yyaxis(app.UIAxes,'right');
                app.plotForce.Visible = 'off';
                app.UIAxes.YAxis(2).Visible='off';
                yyaxis(app.UIAxes,'left');
            end
        end

        % Value changed function: ShowsignalplotCheckBox
        function ShowsignalplotCheckBoxValueChanged(app, event)
            value = app.ShowsignalplotCheckBox.Value;
            if value
                app.plotTable.Visible = 'on';
            else
                app.plotTable.Visible = 'off';
            end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            value = app.Button_2.Value;
            oldValue=app.TimepercyclesEditField.Value;

            dT=max(app.plotTable.XData)-min(app.plotTable.XData);
            dt=max(diff(app.plotTable.XData));
            if app.Button_2.Value
                app.TimepercyclesEditField.Limits = [1/dT,1/dt];
            else
                app.TimepercyclesEditField.Limits = [dt,dT];
            end

            if value
                app.TimepercyclesEditFieldLabel.Text={'Frequency'; '(Hz)'};
                app.TimepercyclesEditField.Value=1/oldValue;
                app.TimepercyclesEditField.Tooltip='Frequency of the periodic signal.';
            else
                app.TimepercyclesEditFieldLabel.Text={'Time per'; 'cycle (s)'};
                app.TimepercyclesEditField.Value=1/oldValue;
                app.TimepercyclesEditField.Tooltip='Integration time per peak.';
            end

        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            value = app.Button_3.Value;
            if value
                app.TotalNumberofpointsEditField.Value=numel(app.plotTable.XData);
                app.AveragevalueofthedistributionEditField.Value=mean(app.plotTable.YData(:));
                app.StandarddeviationofthedistributionEditField.Value=std(app.plotTable.YData(:));
                app.MorePanel.Visible="on";
                app.Button_3.Text='-';
                focus(app.TotalNumberofpointsEditField)
            else
                app.MorePanel.Visible="off";
                app.Button_3.Text='+';
            end
        end

        % Button down function: DenoisingPanel
        function DenoisingPanelButtonDown(app, event)
            app.fadeMoreTab;
        end

        % Menu selected function: EditMenu
        function EditMenuSelected(app, event)
            app.fadeMoreTab;
        end

        % Menu selected function: ToolsMenu
        function ToolsMenuSelected(app, event)
            app.fadeMoreTab;
        end

        % Value changed function: TotalNumberofpointsEditField
        function TotalNumberofpointsEditFieldValueChanged(app, event)
            value = app.TotalNumberofpointsEditField.Value;
            if app.checkMemory((value-numel(app.plotTable.XData))*2*8)
                x=app.plotTable.XData(:);
                y=app.plotTable.YData(:);
                newX=linspace(min(x),max(x),value);
                newY = interp1(x,y,newX);

                app.redoData={};
                app.undoData{end+1}=struct('type','plotTable','XData',app.plotTable.XData,'YData',app.plotTable.YData,'dataset',app.TabGroup.SelectedTab.Title);
                app.undoSizeControl;

                app.plotTable.XData=newX;
                app.plotTable.YData=newY;
            else
                app.TotalNumberofpointsEditField.Value=numel(app.plotTable.XData);
            end
            app.MemoryWarningLoop=0;

        end

        % Clicked callback: MethodDropDown
        function MethodDropDownClicked(app, event)
            app.fadeMoreTab;
            
            item = event.InteractionInformation.Item;
            %app.SmoothOptions(item);
            app.SmoothoptionsPanel.Visible='on';
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.SmoothoptionsPanel.Visible='off';
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            app.SmoothOptions(value);
        end

        % Value changed function: PolynomialorderSpinner
        function PolynomialorderSpinnerValueChanged(app, event)
            updateIFFTplot(app);
        end

        % Close request function: NanoDataLyzerUIFigure
        function NanoDataLyzerUIFigureCloseRequest(app, event)
            
            ans=uiconfirm(app.NanoDataLyzerUIFigure,'Are you sure you want to exit the program?','Exit','Options',{'Exit','Stay'});
            if ~strcmp(ans,'Stay')
                delete(app)
            end
        end

        % Menu selected function: VersioninfoMenu
        function VersioninfoMenuSelected(app, event)
            notes="";
            try
                fid=fopen("release_notes.txt","r");
                tline = fgets(fid);
                while ischar(tline)
                    notes=sprintf('%s%s',notes,tline);
                    tline = fgets(fid);
                end
                fclose(fid);
            catch
            end
            if isdeployed
                commandStrings = string(System.Environment.GetCommandLineArgs);
                name = char(commandStrings(1));
                % name = strrep(name,'\','\\'); %wmic needs double escape sequence
                % command = ['wmic datafile where Name="' name '"']; %This will output to console
                % [status, cmdout] = system(command);
                % output = strsplit(cmdout,newline);
                % names=split(output{1});
                % output{1}=[' ',output{1},' '];
                % output{2}=[' ',output{2},' '];
                % for k=1:numel(names)-1
                %     Info.(names{k})=output{2}(strfind(output{1},[' ',names{k},' ']):strfind(output{1},[' ',names{k+1},' '])-1);
                % end
                % 

                [status, cmdout] = system(sprintf('powershell -Command "Get-Item ''%s'' | ForEach-Object {$_.VersionInfo.FileName, $_.VersionInfo.ProductVersion}"', name));

                if status == 0
                    output = strsplit(cmdout,newline);
                    [~,Info.FileName,~]=fileparts(output{1});

                    fileInfo.Name=Info.FileName;
                    Info.Version=output{2};
                    ind=strfind(Info.Version,'.');
                    if numel(ind)<2
                        ind=[1,1]*(numel(Info.Version)+1);
                    end
                    fileInfo.Version=Info.Version(1:ind(2)-1);
                else
                    fileInfo.FileName='Error';
                    fileInfo.Version='retrieving metadata';
                end
            else
                app_name = mfilename();
                mlappMetaDataReader = mlapp.internal.MLAPPMetadataReader(app_name);
                fileInfo = mlappMetaDataReader.readMLAPPMetadata();
            end
            info=sprintf('Current version:\n%s %s\n\n\n\n%s',fileInfo.Name,fileInfo.Version,notes);
            uialert(app.NanoDataLyzerUIFigure,info,'Version information','Icon','none')
        end

        % Menu selected function: ProjectsiteMenu
        function ProjectsiteMenuSelected(app, event)
            info=sprintf('Thanks for using NanoDataLyzer !\n Please, consider citing our project if this software is helpful for your research.\n DOI: <a href="https://doi.org/10.5281/zenodo.13225384">10.5281/zenodo.13225384</a>\n\nYou can also read tutorials and guides, and join the community at GitHub !!\n\n<a href="https://github.com/XabierGC/NanoDataLyzer">https://github.com/XabierGC/NanoDataLyzer</a>');
            uialert(app.NanoDataLyzerUIFigure,info,'Project information','Icon','none','Interpreter','html')
        end

        % Menu selected function: AboutusMenu
        function AboutusMenuSelected(app, event)
            info=sprintf('We are the Nanotechnology on Surfaces and Plasma research group.\nWe are part of the Institute of Materials Science of Seville (CSIC – Univ. Seville) in Spain, dedicated to exploring the exciting world of nanotechnology. Our team specializes in developing cutting-edge thin films and nanostructured surfaces for advanced applications in photonics, energy harvesting, sensors, and responsive devices. We are passionate about innovation, combining science and technology to create solutions that make a real impact.\nGet to know us and our projects better by visiting our website:\n <a href="https://sincaf.icms.us-csic.es/" target="_blank">https://sincaf.icms.us-csic.es/</a>.');
            uialert(app.NanoDataLyzerUIFigure,info,'NanoOnSurf research group information','Icon','none','Interpreter','html')
        end

        % Button pushed function: ApplyfunctionButton
        function ApplyfunctionButtonPushed(app, event)
           
            X=app.plotTable.XData(:);
            Y=app.plotTable.YData(:);
            assignin('base','x',Y)
            assignin('base','t',X)
            try
                y=evalin('base',app.FunctionEditField.Value);
                if numel(y)==1
                    Y=Y*y;
                else
                    Y=y;
                end
            catch ME
                uialert(app.NanoDataLyzerUIFigure,sprintf('Error evaluating function:\n%s',ME.message), 'Error','Icon','error');
            end

            app.redoData={};
            app.undoData{end+1}=struct('type','plotTable','XData',app.plotTable.XData,'YData',app.plotTable.YData,'dataset',app.TabGroup.SelectedTab.Title);
            app.undoSizeControl;

            app.plotTable.XData=X;
            app.plotTable.YData=Y;
            app.updateAxis;
            app.MemoryWarningLoop=0;

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NanoDataLyzerUIFigure = uifigure('Visible', 'off');
            app.NanoDataLyzerUIFigure.Name = 'NanoDataLyzer';
            app.NanoDataLyzerUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.NanoDataLyzerUIFigure.CloseRequestFcn = createCallbackFcn(app, @NanoDataLyzerUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.NanoDataLyzerUIFigure);
            app.FileMenu.Text = '   File   ';
            app.NewDatasetMenu = uimenu(app.FileMenu);
            app.NewDatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @addTabButtonDown, true);
            app.NewDatasetMenu.Tooltip = {'Create a blank dataset.'};
            app.NewDatasetMenu.Accelerator = 'n';
            app.NewDatasetMenu.Text = 'New Dataset';
            app.CloseDatasetMenu = uimenu(app.FileMenu);
            app.CloseDatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseDatasetMenuSelected, true);
            app.CloseDatasetMenu.Tooltip = {'Close current dataset.'};
            app.CloseDatasetMenu.Accelerator = 'w';
            app.CloseDatasetMenu.Text = 'Close Dataset';
            app.OpenDatasetMenu = uimenu(app.FileMenu);
            app.OpenDatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDatasetMenuSelected, true);
            app.OpenDatasetMenu.Tooltip = {'Open a saved dataset.'};
            app.OpenDatasetMenu.Accelerator = 'o';
            app.OpenDatasetMenu.Text = 'Open Dataset';
            app.SaveDatasetMenu = uimenu(app.FileMenu);
            app.SaveDatasetMenu.Tooltip = {'Save dataset as binary file.'};
            app.SaveDatasetMenu.Text = 'Save Dataset';
            app.SavecurrentdatasetMenu = uimenu(app.SaveDatasetMenu);
            app.SavecurrentdatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDatasetMenuSelected, true);
            app.SavecurrentdatasetMenu.Accelerator = 's';
            app.SavecurrentdatasetMenu.Text = 'Save current dataset';
            app.SavealldatasetsMenu = uimenu(app.SaveDatasetMenu);
            app.SavealldatasetsMenu.MenuSelectedFcn = createCallbackFcn(app, @SavealldatasetsMenuSelected, true);
            app.SavealldatasetsMenu.Text = 'Save all datasets';
            app.Blank = uimenu(app.FileMenu);
            app.Blank.Enable = 'off';
            app.Blank.Text = '_____________';
            app.ImportDataMenu = uimenu(app.FileMenu);
            app.ImportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportDataMenuSelected, true);
            app.ImportDataMenu.Tooltip = {'Import data from external files.'};
            app.ImportDataMenu.Accelerator = 'i';
            app.ImportDataMenu.Text = 'Import Data';
            app.ExportDataMenu = uimenu(app.FileMenu);
            app.ExportDataMenu.Tooltip = {'Exporting options.'};
            app.ExportDataMenu.Text = 'Export Data';
            app.ExportcurrentdatasetMenu = uimenu(app.ExportDataMenu);
            app.ExportcurrentdatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDataMenuSelected, true);
            app.ExportcurrentdatasetMenu.Accelerator = 'e';
            app.ExportcurrentdatasetMenu.Text = 'Export current dataset';
            app.ExportalldatasetsMenu = uimenu(app.ExportDataMenu);
            app.ExportalldatasetsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportalldatasetsMenuSelected, true);
            app.ExportalldatasetsMenu.Text = 'Export all datasets';
            app.ExportalltoOriginMenu = uimenu(app.ExportDataMenu);
            app.ExportalltoOriginMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportalltoOriginMenuSelected, true);
            app.ExportalltoOriginMenu.Tooltip = {'Export all datasets to a OriginPro project'; '(requires OriginPro  installed).'};
            app.ExportalltoOriginMenu.Text = 'Export all to Origin';
            app.Blank_2 = uimenu(app.FileMenu);
            app.Blank_2.Enable = 'off';
            app.Blank_2.Text = '_____________';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Tooltip = {'Close the application.'};
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.NanoDataLyzerUIFigure);
            app.EditMenu.MenuSelectedFcn = createCallbackFcn(app, @EditMenuSelected, true);
            app.EditMenu.Text = '   Edit   ';
            app.UndoMenu = uimenu(app.EditMenu);
            app.UndoMenu.MenuSelectedFcn = createCallbackFcn(app, @UndoMenuSelected, true);
            app.UndoMenu.Enable = 'off';
            app.UndoMenu.Accelerator = 'z';
            app.UndoMenu.Text = 'Undo';
            app.RedoMenu = uimenu(app.EditMenu);
            app.RedoMenu.MenuSelectedFcn = createCallbackFcn(app, @RedoMenuSelected, true);
            app.RedoMenu.Enable = 'off';
            app.RedoMenu.Accelerator = 'y';
            app.RedoMenu.Text = 'Redo';
            app.Menu = uimenu(app.EditMenu);
            app.Menu.Enable = 'off';
            app.Menu.Text = '_____________';
            app.ClearMenu = uimenu(app.EditMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Tooltip = {'Clear data to a blank dataset.'};
            app.ClearMenu.Accelerator = ' ';
            app.ClearMenu.Text = 'Clear';
            app.DeleteRowMenu = uimenu(app.EditMenu);
            app.DeleteRowMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteRowMenuSelected, true);
            app.DeleteRowMenu.Tooltip = {'Delete selected row in table.'};
            app.DeleteRowMenu.Accelerator = '-';
            app.DeleteRowMenu.Text = 'Delete Row';
            app.AddRowMenu = uimenu(app.EditMenu);
            app.AddRowMenu.MenuSelectedFcn = createCallbackFcn(app, @AddRowMenuSelected, true);
            app.AddRowMenu.Tooltip = {'Add a new row to the end of the table.'};
            app.AddRowMenu.Accelerator = '+';
            app.AddRowMenu.Text = 'Add Row';
            app.InsertRowMenu = uimenu(app.EditMenu);
            app.InsertRowMenu.MenuSelectedFcn = createCallbackFcn(app, @InsertRowMenuSelected, true);
            app.InsertRowMenu.Tooltip = {'Add a new row to the table after the selected cell. '};
            app.InsertRowMenu.Text = 'Insert Row';
            app.Blank_3 = uimenu(app.EditMenu);
            app.Blank_3.Enable = 'off';
            app.Blank_3.Text = '_____________';
            app.CopyTableMenu = uimenu(app.EditMenu);
            app.CopyTableMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyTableMenuSelected, true);
            app.CopyTableMenu.Tooltip = {'Copy original data to the clipboard.'};
            app.CopyTableMenu.Accelerator = 'c';
            app.CopyTableMenu.Text = 'Copy Table';
            app.PasteTableMenu = uimenu(app.EditMenu);
            app.PasteTableMenu.MenuSelectedFcn = createCallbackFcn(app, @PasteTableMenuSelected, true);
            app.PasteTableMenu.Tooltip = {'Paste data from the clipboard to the table.'};
            app.PasteTableMenu.Accelerator = 'v';
            app.PasteTableMenu.Text = 'Paste Table';
            app.CopyPlotDataMenu = uimenu(app.EditMenu);
            app.CopyPlotDataMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyPlotDataMenuSelected, true);
            app.CopyPlotDataMenu.Tooltip = {'Copy processed data to the clipboard.'};
            app.CopyPlotDataMenu.Text = 'Copy Plot Data';
            app.Blank_4 = uimenu(app.EditMenu);
            app.Blank_4.Enable = 'off';
            app.Blank_4.Text = '_____________';
            app.ResetPlottoTableDataMenu = uimenu(app.EditMenu);
            app.ResetPlottoTableDataMenu.MenuSelectedFcn = createCallbackFcn(app, @UITableCellEdit, true);
            app.ResetPlottoTableDataMenu.Text = 'Reset Plot to Table Data';
            app.ToolsMenu = uimenu(app.NanoDataLyzerUIFigure);
            app.ToolsMenu.MenuSelectedFcn = createCallbackFcn(app, @ToolsMenuSelected, true);
            app.ToolsMenu.Text = 'Tools';
            app.IterateforalldatasetsMenu = uimenu(app.ToolsMenu);
            app.IterateforalldatasetsMenu.Text = 'Iterate for all datasets';
            app.ApplydenoisingMenu = uimenu(app.IterateforalldatasetsMenu);
            app.ApplydenoisingMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplydenoisingMenuSelected, true);
            app.ApplydenoisingMenu.Tooltip = {'Confirm changes in the signal and apply the same correction to all the datasets.'};
            app.ApplydenoisingMenu.Text = 'Apply denoising';
            app.ApplyintegrationMenu = uimenu(app.IterateforalldatasetsMenu);
            app.ApplyintegrationMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyintegrationMenuSelected, true);
            app.ApplyintegrationMenu.Tooltip = {'Calculate mean power and energy per cycle.iteratively for all the datasets.'};
            app.ApplyintegrationMenu.Text = 'Apply integration';
            app.CreatedatasetbatchMenu = uimenu(app.ToolsMenu);
            app.CreatedatasetbatchMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatedatasetbatchMenuSelected, true);
            app.CreatedatasetbatchMenu.Tooltip = {'Open a tool for creating arrays of datasets.'};
            app.CreatedatasetbatchMenu.Text = 'Create dataset batch';
            app.SplitsignalintodatasetsMenu = uimenu(app.ToolsMenu);
            app.SplitsignalintodatasetsMenu.MenuSelectedFcn = createCallbackFcn(app, @SplitsignalintodatasetsMenuSelected, true);
            app.SplitsignalintodatasetsMenu.Tooltip = {'Open a tool to split data.'};
            app.SplitsignalintodatasetsMenu.Text = 'Split signal into datasets';
            app.SplittedpeaksanalysisMenu = uimenu(app.ToolsMenu);
            app.SplittedpeaksanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @SplittedpeaksanalysisMenuSelected, true);
            app.SplittedpeaksanalysisMenu.Text = 'Splitted peaks analysis';
            app.ApplyaverageandstdMenu = uimenu(app.ToolsMenu);
            app.ApplyaverageandstdMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyaverageandstdMenuSelected, true);
            app.ApplyaverageandstdMenu.Text = 'Apply average and std';
            app.AboutMenu = uimenu(app.NanoDataLyzerUIFigure);
            app.AboutMenu.Text = '   About   ';
            app.VersioninfoMenu = uimenu(app.AboutMenu);
            app.VersioninfoMenu.MenuSelectedFcn = createCallbackFcn(app, @VersioninfoMenuSelected, true);
            app.VersioninfoMenu.Text = 'Version info';
            app.ProjectsiteMenu = uimenu(app.AboutMenu);
            app.ProjectsiteMenu.MenuSelectedFcn = createCallbackFcn(app, @ProjectsiteMenuSelected, true);
            app.ProjectsiteMenu.Text = 'Project site';
            app.AboutusMenu = uimenu(app.AboutMenu);
            app.AboutusMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutusMenuSelected, true);
            app.AboutusMenu.Text = 'About us';
            app.UIAxes = uiaxes(app.NanoDataLyzerUIFigure);
            xlabel(app.UIAxes, 't (s)')
            ylabel(app.UIAxes, 'V (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.NanoDataLyzerUIFigure);
            title(app.UIAxes2, 'Fast Fourier Transform')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'V (V)')
            app.ProcessingModeDropDownLabel = uilabel(app.NanoDataLyzerUIFigure);
            app.ProcessingModeDropDownLabel.Text = 'Processing Mode';
            app.NameEditFieldLabel = uilabel(app.NanoDataLyzerUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.TabGroup = uitabgroup(app.NanoDataLyzerUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Dataset1Tab = uitab(app.TabGroup);
            app.Dataset1Tab.Title = 'Dataset1';
            app.Dataset1Tab.ButtonDownFcn = createCallbackFcn(app, @DatasetTabButtonDown, true);
            app.addTab = uitab(app.TabGroup);
            app.addTab.Tooltip = {'New Dataset'};
            app.addTab.Title = '  +';
            app.addTab.ButtonDownFcn = createCallbackFcn(app, @addTabButtonDown, true);
            app.OpenDatasetButton = uibutton(app.NanoDataLyzerUIFigure, 'push');
            app.OpenDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDatasetMenuSelected, true);
            app.OpenDatasetButton.Visible = 'off';
            app.OpenDatasetButton.Text = 'Open Dataset';
            app.CreateNewDatasetButton = uibutton(app.NanoDataLyzerUIFigure, 'push');
            app.CreateNewDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @addTabButtonDown, true);
            app.CreateNewDatasetButton.Visible = 'off';
            app.CreateNewDatasetButton.Text = 'Create New Dataset';
            app.ProcessingModeDropDown = uidropdown(app.NanoDataLyzerUIFigure);
            app.ProcessingModeDropDown.Items = {'V - t signal', 'I - t signal', 'V - t with R', 'I - t with R'};
            app.ProcessingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcessingModeDropDownValueChanged, true);
            app.ProcessingModeDropDown.Tooltip = {'processingMode: Select the type of signal.'};
            app.ProcessingModeDropDown.Value = 'V - t with R';
            app.NameEditField = uieditfield(app.NanoDataLyzerUIFigure, 'text');
            app.NameEditField.Tooltip = {'Name your dataset.'};
            app.REditField = uieditfield(app.NanoDataLyzerUIFigure, 'numeric');
            app.REditField.Limits = [0 Inf];
            app.REditField.ValueDisplayFormat = '%1.2e';
            app.REditField.Tooltip = {'R: Impedance of the external circuit.'};
            app.REditField.Value = 10000000;
            app.MetadataPanel = uipanel(app.NanoDataLyzerUIFigure);
            app.MetadataPanel.Title = 'Metadata';
            app.NameEditField_2 = uieditfield(app.MetadataPanel, 'text');
            app.NameEditField_2.ValueChangedFcn = createCallbackFcn(app, @NameEditField_2ValueChanged, true);
            app.NameEditField_2.Tooltip = {'Xname: Name of X variable.'};
            app.NameEditField_2.Value = 't';
            app.XEditField = uieditfield(app.MetadataPanel, 'text');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Tooltip = {'Yname: Name of Y variable.'};
            app.XEditField.Value = 'V';
            app.UnitsEditField = uieditfield(app.MetadataPanel, 'text');
            app.UnitsEditField.ValueChangedFcn = createCallbackFcn(app, @UnitsEditFieldValueChanged, true);
            app.UnitsEditField.Tooltip = {'Xunits: Units of X variable.'};
            app.UnitsEditField.Value = 's';
            app.YEditField = uieditfield(app.MetadataPanel, 'text');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Tooltip = {'Yunits: Units of Y variable.'};
            app.YEditField.Value = 'V';
            app.CommentsEditField = uieditfield(app.MetadataPanel, 'text');
            app.CommentsEditField.Tooltip = {'Comments on the X variable.'};
            app.YcommentsEditField = uieditfield(app.MetadataPanel, 'text');
            app.YcommentsEditField.Tooltip = {'Comments on the Y variable.'};
            app.NameLabel = uilabel(app.MetadataPanel);
            app.NameLabel.Text = 'Name';
            app.UnitsEditFieldLabel = uilabel(app.MetadataPanel);
            app.UnitsEditFieldLabel.Text = 'Units';
            app.CommentsLabel = uilabel(app.MetadataPanel);
            app.CommentsLabel.Text = 'Comments';
            app.XLabel = uilabel(app.MetadataPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.MetadataPanel);
            app.YLabel.Text = 'Y';
            app.CodeandCommentsTextArea = uitextarea(app.NanoDataLyzerUIFigure);
            app.CodeandCommentsTextArea.Tooltip = {'Comments and additional code to be'; 'executed while opening the dataset.'};
            app.CodeandCommentsTextArea.Value = {'Write something as a comment. MATLAB functions can be called when the dataset is being opened.'; '\run %this line after ''\run'' will be evaluated'; '\run1 %this line will be evaluated and then removed'};
            app.StimulusDataPanel = uipanel(app.NanoDataLyzerUIFigure);
            app.StimulusDataPanel.Title = 'Stimulus Data';
            app.LoadfromfileButton = uibutton(app.StimulusDataPanel, 'push');
            app.LoadfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromfileButtonPushed, true);
            app.LoadfromfileButton.Tooltip = {'dataStimulus: Import the data of the stimulus.'};
            app.LoadfromfileButton.Text = 'Load from file';
            app.StimulusFilenameLabel = uilabel(app.StimulusDataPanel);
            app.StimulusFilenameLabel.Enable = 'off';
            app.StimulusFilenameLabel.Text = 'none';
            app.StimulusunitsEditFieldLabel = uilabel(app.StimulusDataPanel);
            app.StimulusunitsEditFieldLabel.Enable = 'off';
            app.StimulusunitsEditFieldLabel.Text = 'Stimulus (units)';
            app.StimulusunitsEditField = uieditfield(app.StimulusDataPanel, 'text');
            app.StimulusunitsEditField.Enable = 'off';
            app.StimulusunitsEditField.Tooltip = {'labelStimulus: Name and units of the stimulus.'};
            app.StimulusunitsEditField.Value = 'F (N)';
            app.FunctionbeforeoperationusexasvariableLabel = uilabel(app.StimulusDataPanel);
            app.FunctionbeforeoperationusexasvariableLabel.Enable = 'off';
            app.FunctionbeforeoperationusexasvariableLabel.Text = 'Rescale';
            app.DatafromButtonGroup = uibuttongroup(app.StimulusDataPanel);
            app.DatafromButtonGroup.Enable = 'off';
            app.DatafromButtonGroup.Title = 'Data from';
            app.CH1Button = uiradiobutton(app.DatafromButtonGroup);
            app.CH1Button.Tooltip = {'channelOfStimulus: Select the channel of the stimulus.'};
            app.CH1Button.Text = 'CH 1';
            app.CH1Button.Value = true;
            app.CH2Button = uiradiobutton(app.DatafromButtonGroup);
            app.CH2Button.Tooltip = {'channelOfStimulus: Select the channel of the stimulus.'};
            app.CH2Button.Text = 'CH 2';
            app.TriggerCheckBox = uicheckbox(app.DatafromButtonGroup);
            app.TriggerCheckBox.Tooltip = {'trigger: Signal and stimulus synchronization.'};
            app.TriggerCheckBox.Text = 'Trigger';
            app.TriggerCheckBox.Value = true;
            app.RescaleEditField = uieditfield(app.StimulusDataPanel, 'text');
            app.RescaleEditField.Enable = 'off';
            app.RescaleEditField.Tooltip = {'rescale: Set a scaling factor or define a'; 'function (use x as variable name)'};
            app.RescaleEditField.Value = 'x/22.5e-3 + 0';
            app.Button = uibutton(app.StimulusDataPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Enable = 'off';
            app.Button.Tooltip = {'Additional operations.'};
            app.Button.Text = '+';
            app.PlotStimulusButton = uibutton(app.StimulusDataPanel, 'push');
            app.PlotStimulusButton.ButtonPushedFcn = createCallbackFcn(app, @PlotStimulusButtonPushed, true);
            app.PlotStimulusButton.Tooltip = {'Plot the stimulus.'};
            app.PlotStimulusButton.Text = 'Plot Stimulus';
            app.ShowsignalplotCheckBox = uicheckbox(app.StimulusDataPanel);
            app.ShowsignalplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowsignalplotCheckBoxValueChanged, true);
            app.ShowsignalplotCheckBox.Enable = 'off';
            app.ShowsignalplotCheckBox.Text = 'Show signal plot';
            app.ShowsignalplotCheckBox.Value = true;
            app.ShowstimulusplotCheckBox = uicheckbox(app.StimulusDataPanel);
            app.ShowstimulusplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowstimulusplotCheckBoxValueChanged, true);
            app.ShowstimulusplotCheckBox.Enable = 'off';
            app.ShowstimulusplotCheckBox.Text = 'Show stimulus plot';
            app.ShowstimulusplotCheckBox.Value = true;
            app.UITable = uitable(app.NanoDataLyzerUIFigure);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Tooltip = {'data: Original data.'};
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.UITable.KeyReleaseFcn = createCallbackFcn(app, @UITableKeyRelease, true);
            app.RemoveunsortedXpointsButton = uibutton(app.NanoDataLyzerUIFigure, 'push');
            app.RemoveunsortedXpointsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveunsortedXpointsButtonPushed, true);
            app.RemoveunsortedXpointsButton.Visible = 'off';
            app.RemoveunsortedXpointsButton.Tooltip = {'Remove X axis faulty points.'};
            app.RemoveunsortedXpointsButton.Text = {'Remove unsorted'; 'X points'};
            app.Button_3 = uibutton(app.NanoDataLyzerUIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Visible = 'off';
            app.Button_3.Tooltip = {'More'};
            app.Button_3.Text = '+';
            app.LoadparamsButton = uibutton(app.NanoDataLyzerUIFigure, 'push');
            app.LoadparamsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparamsButtonPushed, true);
            app.LoadparamsButton.Visible = 'off';
            app.LoadparamsButton.Tooltip = {'Load saved configuration parameters.'};
            app.LoadparamsButton.Text = {'Load'; 'params'};
            app.CodeandCommentsLabel = uilabel(app.NanoDataLyzerUIFigure);
            app.CodeandCommentsLabel.Text = 'Code and Comments';
            app.RLabel = uilabel(app.NanoDataLyzerUIFigure);
            app.RLabel.Text = 'R (Ω)';
            app.SaveparamsButton = uibutton(app.NanoDataLyzerUIFigure, 'push');
            app.SaveparamsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparamsButtonPushed, true);
            app.SaveparamsButton.Visible = 'off';
            app.SaveparamsButton.Tooltip = {'Save configuration paramenters.'};
            app.SaveparamsButton.Text = {'Save'; 'params'};
            app.DenoisingPanel = uipanel(app.NanoDataLyzerUIFigure);
            app.DenoisingPanel.Title = 'Denoising';
            app.DenoisingPanel.Visible = 'off';
            app.DenoisingPanel.ButtonDownFcn = createCallbackFcn(app, @DenoisingPanelButtonDown, true);
            app.BiascorrectionSpinner = uispinner(app.DenoisingPanel);
            app.BiascorrectionSpinner.ValueChangingFcn = createCallbackFcn(app, @BiascorrectionSpinnerValueChanging, true);
            app.BiascorrectionSpinner.ValueDisplayFormat = '%e';
            app.BiascorrectionSpinner.ValueChangedFcn = createCallbackFcn(app, @BiascorrectionSpinnerValueChanged, true);
            app.BiascorrectionSpinner.Tooltip = {'Correct offset of the signal.'};
            app.SmoothCheckBox = uicheckbox(app.DenoisingPanel);
            app.SmoothCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothCheckBoxValueChanged, true);
            app.SmoothCheckBox.Tooltip = {'Apply smooth correction to the signal.'};
            app.SmoothCheckBox.Text = 'Smooth';
            app.MethodDropDown = uidropdown(app.DenoisingPanel);
            app.MethodDropDown.Items = {'Adjacent averaging', 'Savitzky-Golay', 'Lowess', 'Loess', 'Robust Lowess', 'Robust Loess'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Enable = 'off';
            app.MethodDropDown.Tooltip = {'Smooth method'};
            app.MethodDropDown.ClickedFcn = createCallbackFcn(app, @MethodDropDownClicked, true);
            app.MethodDropDown.Value = 'Adjacent averaging';
            app.FFTButton = uibutton(app.DenoisingPanel, 'state');
            app.FFTButton.ValueChangedFcn = createCallbackFcn(app, @FFTButtonValueChanged, true);
            app.FFTButton.Tooltip = {'Apply Fast Fourier Transform.'};
            app.FFTButton.Text = 'FFT';
            app.DenoiseCheckBox = uicheckbox(app.DenoisingPanel);
            app.DenoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @DenoiseCheckBoxValueChanged, true);
            app.DenoiseCheckBox.Enable = 'off';
            app.DenoiseCheckBox.Tooltip = {'Remove low contributions in frequency-domain.'};
            app.DenoiseCheckBox.Text = 'Denoise';
            app.ThresholdSpinner_2 = uispinner(app.DenoisingPanel);
            app.ThresholdSpinner_2.ValueChangingFcn = createCallbackFcn(app, @ThresholdSpinner_2ValueChanging, true);
            app.ThresholdSpinner_2.ValueDisplayFormat = '%.1e';
            app.ThresholdSpinner_2.Enable = 'off';
            app.ThresholdSpinner_2.Tooltip = {'Select a threshold in Y axis.'; 'Remove contribution of frequencies below it.'};
            app.BandStopCheckBox = uicheckbox(app.DenoisingPanel);
            app.BandStopCheckBox.ValueChangedFcn = createCallbackFcn(app, @BandStopCheckBoxValueChanged, true);
            app.BandStopCheckBox.Enable = 'off';
            app.BandStopCheckBox.Tooltip = {'Remove the contribution of a frequency range.'};
            app.BandStopCheckBox.Text = 'Band-Stop';
            app.LowSpinnerLabel = uilabel(app.DenoisingPanel);
            app.LowSpinnerLabel.Enable = 'off';
            app.LowSpinnerLabel.Text = 'Low';
            app.LowSpinner = uispinner(app.DenoisingPanel);
            app.LowSpinner.ValueChangingFcn = createCallbackFcn(app, @LowSpinnerValueChanging, true);
            app.LowSpinner.Limits = [0 Inf];
            app.LowSpinner.ValueDisplayFormat = '%.1e';
            app.LowSpinner.Enable = 'off';
            app.LowSpinner.Tooltip = {'Low frequency selection.'};
            app.HighSpinner = uispinner(app.DenoisingPanel);
            app.HighSpinner.ValueChangingFcn = createCallbackFcn(app, @HighSpinnerValueChanging, true);
            app.HighSpinner.ValueDisplayFormat = '%.1e';
            app.HighSpinner.Enable = 'off';
            app.HighSpinner.Tooltip = {'High frequency selection.'};
            app.ThresholdSpinner_2Label = uilabel(app.DenoisingPanel);
            app.ThresholdSpinner_2Label.Enable = 'off';
            app.ThresholdSpinner_2Label.Text = 'Threshold';
            app.ReplaceDataButton = uibutton(app.DenoisingPanel, 'push');
            app.ReplaceDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceDataButtonPushed, true);
            app.ReplaceDataButton.Visible = 'off';
            app.ReplaceDataButton.Tooltip = {'Confirm changes in the signal.'};
            app.ReplaceDataButton.Text = 'Replace Data';
            app.HighSpinnerLabel = uilabel(app.DenoisingPanel);
            app.HighSpinnerLabel.Enable = 'off';
            app.HighSpinnerLabel.Text = 'High';
            app.BiascorrectionSpinnerLabel = uilabel(app.DenoisingPanel);
            app.BiascorrectionSpinnerLabel.Text = 'Bias correction';
            app.IntegrationPanel = uipanel(app.NanoDataLyzerUIFigure);
            app.IntegrationPanel.Title = 'Integration';
            app.IntegrationPanel.Visible = 'off';
            app.TimepercyclesEditFieldLabel = uilabel(app.IntegrationPanel);
            app.TimepercyclesEditFieldLabel.Text = {'Time per'; 'cycle (s)'};
            app.TimepercyclesEditField = uieditfield(app.IntegrationPanel, 'numeric');
            app.TimepercyclesEditField.Limits = [0 Inf];
            app.TimepercyclesEditField.ValueChangedFcn = createCallbackFcn(app, @TimepercyclesEditFieldValueChanged, true);
            app.TimepercyclesEditField.Tooltip = {'Integration time per peak.'};
            app.TimepercyclesEditField.Value = 1;
            app.Button_2 = uibutton(app.IntegrationPanel, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Tooltip = {'Change between frequency/time per cycle.'};
            app.Button_2.Text = '🔄';
            app.NcyclesSpinnerLabel = uilabel(app.IntegrationPanel);
            app.NcyclesSpinnerLabel.Text = 'N cycles';
            app.NcyclesSpinner = uispinner(app.IntegrationPanel);
            app.NcyclesSpinner.Limits = [1 Inf];
            app.NcyclesSpinner.RoundFractionalValues = 'on';
            app.NcyclesSpinner.ValueChangedFcn = createCallbackFcn(app, @NcyclesSpinnerValueChanged, true);
            app.NcyclesSpinner.Tooltip = {'Number of periodic cycles to be integrated.'};
            app.NcyclesSpinner.Value = 1;
            app.IntegrationregionLabel = uilabel(app.IntegrationPanel);
            app.IntegrationregionLabel.Text = 'Integration region';
            app.IntegrationregionSlider = uislider(app.IntegrationPanel);
            app.IntegrationregionSlider.Limits = [0 1];
            app.IntegrationregionSlider.MajorTicks = [0 0.5 1];
            app.IntegrationregionSlider.MajorTickLabels = {' ', ' ', ' '};
            app.IntegrationregionSlider.ValueChangedFcn = createCallbackFcn(app, @IntegrationregionSliderValueChanged, true);
            app.IntegrationregionSlider.ValueChangingFcn = createCallbackFcn(app, @IntegrationregionSliderValueChanging, true);
            app.CalculatepowerButton = uibutton(app.IntegrationPanel, 'push');
            app.CalculatepowerButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatepowerButtonPushed, true);
            app.CalculatepowerButton.Tooltip = {'Calculate mean power and energy per cycle.'};
            app.CalculatepowerButton.Text = {'Calculate'; 'power'};
            app.SaveTableDataButton = uibutton(app.NanoDataLyzerUIFigure, 'push');
            app.SaveTableDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableDataButtonPushed, true);
            app.SaveTableDataButton.Tooltip = {'Save original data.'};
            app.SaveTableDataButton.Text = 'Save Table Data';
            app.DataProcessingButton = uibutton(app.NanoDataLyzerUIFigure, 'push');
            app.DataProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @DataProcessingButtonPushed, true);
            app.DataProcessingButton.Tooltip = {'Access to denoising and integration tools.'};
            app.DataProcessingButton.Text = 'Data Processing';
            app.SmoothoptionsPanel = uipanel(app.NanoDataLyzerUIFigure);
            app.SmoothoptionsPanel.Title = 'Smooth options';
            app.SmoothoptionsPanel.Visible = 'off';
            app.CloseButton = uibutton(app.SmoothoptionsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.NSpinnerLabel = uilabel(app.SmoothoptionsPanel);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.SmoothoptionsPanel);
            app.NSpinner.Limits = [2 Inf];
            app.NSpinner.RoundFractionalValues = 'on';
            app.NSpinner.ValueDisplayFormat = '%.0f';
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @NSpinnerValueChanged, true);
            app.NSpinner.Tooltip = {'Number of points to be averaged.'};
            app.NSpinner.Value = 2;
            app.PolynomialorderSpinnerLabel = uilabel(app.SmoothoptionsPanel);
            app.PolynomialorderSpinnerLabel.Text = 'Polynomial order';
            app.PolynomialorderSpinner = uispinner(app.SmoothoptionsPanel);
            app.PolynomialorderSpinner.Limits = [1 Inf];
            app.PolynomialorderSpinner.RoundFractionalValues = 'on';
            app.PolynomialorderSpinner.ValueDisplayFormat = '%.0f';
            app.PolynomialorderSpinner.ValueChangedFcn = createCallbackFcn(app, @PolynomialorderSpinnerValueChanged, true);
            app.PolynomialorderSpinner.Tooltip = {'Number of points to be averaged.'};
            app.PolynomialorderSpinner.Value = 1;
            app.MorePanel = uipanel(app.NanoDataLyzerUIFigure);
            app.MorePanel.Title = 'More';
            app.MorePanel.Visible = 'off';
            app.TotalNumberofpointsEditFieldLabel = uilabel(app.MorePanel);
            app.TotalNumberofpointsEditFieldLabel.Text = 'Total Number of points';
            app.TotalNumberofpointsEditField = uieditfield(app.MorePanel, 'numeric');
            app.TotalNumberofpointsEditField.Limits = [1 Inf];
            app.TotalNumberofpointsEditField.RoundFractionalValues = 'on';
            app.TotalNumberofpointsEditField.ValueDisplayFormat = '%.0f';
            app.TotalNumberofpointsEditField.ValueChangedFcn = createCallbackFcn(app, @TotalNumberofpointsEditFieldValueChanged, true);
            app.TotalNumberofpointsEditField.Value = 1;
            app.AveragevalueofthedistributionEditFieldLabel = uilabel(app.MorePanel);
            app.AveragevalueofthedistributionEditFieldLabel.Text = 'Average value of the distribution';
            app.AveragevalueofthedistributionEditField = uieditfield(app.MorePanel, 'numeric');
            app.AveragevalueofthedistributionEditField.ValueDisplayFormat = '%e';
            app.AveragevalueofthedistributionEditField.Editable = 'off';
            app.StandarddeviationofthedistributionEditFieldLabel = uilabel(app.MorePanel);
            app.StandarddeviationofthedistributionEditFieldLabel.Text = 'Standard deviation of the distribution';
            app.StandarddeviationofthedistributionEditField = uieditfield(app.MorePanel, 'numeric');
            app.StandarddeviationofthedistributionEditField.ValueDisplayFormat = '%e';
            app.StandarddeviationofthedistributionEditField.Editable = 'off';
            app.FunctionLabel_2 = uilabel(app.MorePanel);
            app.FunctionLabel_2.Text = 'Function';
            app.FunctionEditField = uieditfield(app.MorePanel, 'text');
            app.FunctionEditField.Tooltip = {'Define a function to be applied'; 'to the plotted data (use x as variable name).'};
            app.FunctionEditField.Value = '1 * x - 0';
            app.ApplyfunctionButton = uibutton(app.MorePanel, 'push');
            app.ApplyfunctionButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyfunctionButtonPushed, true);
            app.ApplyfunctionButton.Tooltip = {'Apply mathematical function'; 'to the plotted data.'};
            app.ApplyfunctionButton.Text = 'Apply function';
            app.AdditionaloperationsPanel = uipanel(app.NanoDataLyzerUIFigure);
            app.AdditionaloperationsPanel.Title = 'Additional operations';
            app.AdditionaloperationsPanel.Visible = 'off';
            app.OperationDropDownLabel = uilabel(app.AdditionaloperationsPanel);
            app.OperationDropDownLabel.Text = 'Operation';
            app.OperationDropDown = uidropdown(app.AdditionaloperationsPanel);
            app.OperationDropDown.Items = {'none', 'Derivation', 'Integration', 'Denoising'};
            app.OperationDropDown.ItemsData = [0 1 2 3];
            app.OperationDropDown.ValueChangedFcn = createCallbackFcn(app, @OperationDropDownValueChanged, true);
            app.OperationDropDown.Tooltip = {'operationStimulus: Apply an operation to the stimulus data..'};
            app.OperationDropDown.Value = 0;
            app.FunctionLabel = uilabel(app.AdditionaloperationsPanel);
            app.FunctionLabel.Text = 'Function';
            app.FunctionafteroperationusexasvariableEditField = uieditfield(app.AdditionaloperationsPanel, 'text');
            app.FunctionafteroperationusexasvariableEditField.Tooltip = {'additionalFunction: Define a'; 'function to be applied'; 'to the result of the operation (use x as variable name).'};
            app.FunctionafteroperationusexasvariableEditField.Value = '1 * x - 0';
            % Show the figure after all components are created
            app.NanoDataLyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Nanodatalyzer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NanoDataLyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NanoDataLyzerUIFigure)
        end
    end
end